classdef retrospective < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RetrospectiveUIFigure       matlab.ui.Figure
        RetrospectivecanbecreditedbycitingthispaperLabel  matlab.ui.control.Label
        Hyperlink                   matlab.ui.control.Hyperlink
        TabGroup0                   matlab.ui.container.TabGroup
        DashboardTab                matlab.ui.container.Tab
        Lamp3                       matlab.ui.control.Lamp
        BusyImage3                  matlab.ui.control.Image
        CinePanel                   matlab.ui.container.Panel
        DynamicsEditField           matlab.ui.control.NumericEditField
        dynamicsLabel               matlab.ui.control.Label
        RecoTypeDropDown            matlab.ui.control.DropDown
        movieDropDownLabel          matlab.ui.control.Label
        FramesEditField             matlab.ui.control.NumericEditField
        CINEframesLabel             matlab.ui.control.Label
        SortKspaceButton            matlab.ui.control.Button
        Lamp7                       matlab.ui.control.Lamp
        BusyImage7                  matlab.ui.control.Image
        ExportMRDButton             matlab.ui.control.Button
        Lamp6                       matlab.ui.control.Lamp
        BusyImage6                  matlab.ui.control.Image
        Lamp5                       matlab.ui.control.Lamp
        BusyImage5                  matlab.ui.control.Image
        Lamp2                       matlab.ui.control.Lamp
        BusyImage2                  matlab.ui.control.Image
        Lamp1                       matlab.ui.control.Lamp
        BusyImage1                  matlab.ui.control.Image
        Lamp4                       matlab.ui.control.Lamp
        BusyImage4                  matlab.ui.control.Image
        ExportGIFButton             matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        ExportDCMButton             matlab.ui.control.Button
        CSrecoparametersPanel       matlab.ui.container.Panel
        LRxyzEditField              matlab.ui.control.NumericEditField
        LRxyzLabel                  matlab.ui.control.Label
        TVdynEditField              matlab.ui.control.NumericEditField
        TVdynEditFieldLabel         matlab.ui.control.Label
        CLEARLabel                  matlab.ui.control.Label
        CLEARCheckBox               matlab.ui.control.CheckBox
        ESPIRiTLabel                matlab.ui.control.Label
        ESPIRiTCheckBox             matlab.ui.control.CheckBox
        WVxyzEditField              matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel         matlab.ui.control.Label
        ProgressGauge               matlab.ui.control.LinearGauge
        TVxyzEditField              matlab.ui.control.NumericEditField
        TVxyzEditFieldLabel         matlab.ui.control.Label
        TVcineEditField             matlab.ui.control.NumericEditField
        TVcineEditFieldLabel        matlab.ui.control.Label
        ReconstructButton           matlab.ui.control.Button
        FilterButton                matlab.ui.control.Button
        DetectedratesBPMPanel       matlab.ui.container.Panel
        ResetFilterValuesButton     matlab.ui.control.Button
        widthLabel                  matlab.ui.control.Label
        centerLabel                 matlab.ui.control.Label
        HeartWidthEditField         matlab.ui.control.NumericEditField
        RespirationWidthEditField   matlab.ui.control.NumericEditField
        RespirationWindowEditField  matlab.ui.control.NumericEditField
        windowLabel                 matlab.ui.control.Label
        HeartEditField              matlab.ui.control.NumericEditField
        heartbpmLabel_3             matlab.ui.control.Label
        RespirationEditField        matlab.ui.control.NumericEditField
        respbpmLabel_3              matlab.ui.control.Label
        LoadMRDButton               matlab.ui.control.Button
        TabGroup1                   matlab.ui.container.TabGroup
        NavigatorTab                matlab.ui.container.Tab
        loglinButton                matlab.ui.control.Button
        CoilLabel                   matlab.ui.control.Label
        CoilSpinner                 matlab.ui.control.Spinner
        SpectrumFig                 matlab.ui.control.UIAxes
        RawNavFig                   matlab.ui.control.UIAxes
        AdvancedsettingsTab         matlab.ui.container.Tab
        DynamicsLabel               matlab.ui.control.Label
        FrameSharingEditField       matlab.ui.control.NumericEditField
        sharingLabel                matlab.ui.control.Label
        NavigatorFlipSwitch         matlab.ui.control.ToggleSwitch
        FlipSwitchLabel             matlab.ui.control.Label
        PresetsLabel_2              matlab.ui.control.Label
        SpeciesButton2              matlab.ui.container.ButtonGroup
        RatButton2                  matlab.ui.control.RadioButton
        MouseButton2                matlab.ui.control.RadioButton
        NavigatorNRPointsEditField  matlab.ui.control.NumericEditField
        pointsLabel                 matlab.ui.control.Label
        NavigatorEndEditField       matlab.ui.control.NumericEditField
        primaryLabel                matlab.ui.control.Label
        NavigatorLabel              matlab.ui.control.Label
        freqNxdiscardLabel          matlab.ui.control.Label
        DiscardEditField            matlab.ui.control.NumericEditField
        FilterorderEditField        matlab.ui.control.NumericEditField
        filterorderEditFieldLabel   matlab.ui.control.Label
        MaxheartrateEditField       matlab.ui.control.NumericEditField
        maxheartrateEditFieldLabel  matlab.ui.control.Label
        MinheartrateEditField       matlab.ui.control.NumericEditField
        minheartrateEditFieldLabel  matlab.ui.control.Label
        MaxresprateEditField        matlab.ui.control.NumericEditField
        maxresprateEditFieldLabel   matlab.ui.control.Label
        MinresprateEditField        matlab.ui.control.NumericEditField
        minresprateEditFieldLabel   matlab.ui.control.Label
        PhysiofilteringLabel        matlab.ui.control.Label
        TabGroup2                   matlab.ui.container.TabGroup
        NavigatoranalysisTab        matlab.ui.container.Tab
        RangeEditField              matlab.ui.control.NumericEditField
        rangeEditFieldLabel         matlab.ui.control.Label
        StartEditField              matlab.ui.control.NumericEditField
        startEditFieldLabel         matlab.ui.control.Label
        NavFastSlowSwitch           matlab.ui.control.RockerSwitch
        NavStopButton               matlab.ui.control.Button
        NavStartButton              matlab.ui.control.Button
        ExcludeLabel                matlab.ui.control.Label
        ExcludeEndSlider            matlab.ui.control.Slider
        ExcludeStartSlider          matlab.ui.control.Slider
        ExcludeCheckBox             matlab.ui.control.CheckBox
        IncludeLabel                matlab.ui.control.Label
        IncludeEndSlider            matlab.ui.control.Slider
        IncludeStartSlider          matlab.ui.control.Slider
        IncludeCheckBox             matlab.ui.control.CheckBox
        RangeSlider                 matlab.ui.control.Slider
        StartSlider                 matlab.ui.control.Slider
        DisplayrangeLabel           matlab.ui.control.Label
        RespirationRateTimeFig      matlab.ui.control.UIAxes
        HeartRateTimeFig            matlab.ui.control.UIAxes
        RespirationNavFig           matlab.ui.control.UIAxes
        CardiacNavFig               matlab.ui.control.UIAxes
        TabGroup3                   matlab.ui.container.TabGroup
        AcquisitionparametersTab    matlab.ui.container.Tab
        TimeDynViewField            matlab.ui.control.EditField
        timedynamicLabel            matlab.ui.control.Label
        ScanTimeViewField           matlab.ui.control.EditField
        scantimeEditFieldLabel      matlab.ui.control.Label
        NcViewField                 matlab.ui.control.NumericEditField
        coilsNcLabel                matlab.ui.control.Label
        NzViewField                 matlab.ui.control.NumericEditField
        slicesNzLabel               matlab.ui.control.Label
        TrajectoryViewField         matlab.ui.control.EditField
        trajectoryEditFieldLabel    matlab.ui.control.Label
        TRViewField                 matlab.ui.control.NumericEditField
        TRmsLabel                   matlab.ui.control.Label
        FillingViewField            matlab.ui.control.NumericEditField
        fillingLabel                matlab.ui.control.Label
        AveragesViewField           matlab.ui.control.NumericEditField
        averagesEditFieldLabel      matlab.ui.control.Label
        NrKlinesViewField           matlab.ui.control.NumericEditField
        klinesLabel                 matlab.ui.control.Label
        RepetitionsViewField        matlab.ui.control.NumericEditField
        repetitionsLabel            matlab.ui.control.Label
        KlinesRepetitionViewField   matlab.ui.control.NumericEditField
        trajectorysizeLabel         matlab.ui.control.Label
        NxViewField                 matlab.ui.control.NumericEditField
        freqNxLabel                 matlab.ui.control.Label
        NyViewField                 matlab.ui.control.NumericEditField
        phaseNyLabel                matlab.ui.control.Label
        DatafileLabel               matlab.ui.control.Label
        DataFile                    matlab.ui.control.TextArea
        TrajectoryFig               matlab.ui.control.UIAxes
        PresetsTab                  matlab.ui.container.Tab
        ReconstructionpresetsLabel  matlab.ui.control.Label
        RecoPresetsButtonGroup      matlab.ui.container.ButtonGroup
        RealTimeFunctionButton      matlab.ui.control.RadioButton
        RespiratoryFunctionButton   matlab.ui.control.RadioButton
        DiastolicFunctionButton     matlab.ui.control.RadioButton
        SystolicFunctionButton      matlab.ui.control.RadioButton
        ScoutButton                 matlab.ui.control.RadioButton
        SpeciesLabel                matlab.ui.control.Label
        SpeciesButton               matlab.ui.container.ButtonGroup
        RatButton                   matlab.ui.control.RadioButton
        MouseButton                 matlab.ui.control.RadioButton
        TabGroup4                   matlab.ui.container.TabGroup
        MovieTab                    matlab.ui.container.Tab
        MovieDynEditField           matlab.ui.control.NumericEditField
        dynamicLabel                matlab.ui.control.Label
        LevelScaleSlider            matlab.ui.control.Slider
        LLabel                      matlab.ui.control.Label
        WindowScaleSlider           matlab.ui.control.Slider
        WLabel                      matlab.ui.control.Label
        MovieSliceEditField         matlab.ui.control.NumericEditField
        sliceLabel                  matlab.ui.control.Label
        FinalRespirationViewField   matlab.ui.control.NumericEditField
        respbpmLabel_2              matlab.ui.control.Label
        FinalHeartViewField         matlab.ui.control.NumericEditField
        heartbpmLabel_2             matlab.ui.control.Label
        MovieFrameEditField         matlab.ui.control.NumericEditField
        frameLabel                  matlab.ui.control.Label
        autoButton                  matlab.ui.control.Button
        MovieCheckBox3              matlab.ui.control.CheckBox
        MovieCheckBox2              matlab.ui.control.CheckBox
        MovieCheckBox1              matlab.ui.control.CheckBox
        MovieDynDecrButton          matlab.ui.control.Button
        MovieDynLabel               matlab.ui.control.Label
        MovieDynIncrButton          matlab.ui.control.Button
        sensemapSpinner2            matlab.ui.control.Spinner
        sensemapSpinner1            matlab.ui.control.Spinner
        MovieCoilSpinner            matlab.ui.control.Spinner
        ButtonGroup                 matlab.ui.container.ButtonGroup
        sensemapButton              matlab.ui.control.RadioButton
        kspaceButton                matlab.ui.control.RadioButton
        averagesButton              matlab.ui.control.RadioButton
        movieButton                 matlab.ui.control.RadioButton
        MovieSliceDecrButton        matlab.ui.control.Button
        MovieSliceLabel             matlab.ui.control.Label
        MovieSliceIncrButton        matlab.ui.control.Button
        MovieFrameDecrButton        matlab.ui.control.Button
        MovieFrameLabel             matlab.ui.control.Label
        MovieFrameIncrButton        matlab.ui.control.Button
        MovieRotateLabel            matlab.ui.control.Label
        ccwButton                   matlab.ui.control.Button
        cwButton                    matlab.ui.control.Button
        IntensityLabel              matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        MovieFig                    matlab.ui.control.UIAxes
        LcurveTab                   matlab.ui.container.Tab
        LcurveLRxyzCheckBox         matlab.ui.control.CheckBox
        LRxyzLabel2                 matlab.ui.control.Label
        RegularizationLabel         matlab.ui.control.Label
        TVdynLabel                  matlab.ui.control.Label
        TVxyzLabel                  matlab.ui.control.Label
        TVcineLabel                 matlab.ui.control.Label
        WVxyzLabel                  matlab.ui.control.Label
        LcurveTVdynCheckBox         matlab.ui.control.CheckBox
        LcurveTVxyzCheckBox         matlab.ui.control.CheckBox
        LcurveTVcineCheckBox        matlab.ui.control.CheckBox
        LcurveWVxyzCheckBox         matlab.ui.control.CheckBox
        LcurveStopButton            matlab.ui.control.Button
        LambdaLabel                 matlab.ui.control.Label
        LambdaIterationsEditField   matlab.ui.control.NumericEditField
        iterLabel                   matlab.ui.control.Label
        LambdaStepsEditField        matlab.ui.control.NumericEditField
        stepsEditFieldLabel         matlab.ui.control.Label
        Lambda2EditField            matlab.ui.control.NumericEditField
        upperEditFieldLabel         matlab.ui.control.Label
        Lambda1EditField            matlab.ui.control.NumericEditField
        lowerEditFieldLabel         matlab.ui.control.Label
        LcurveStartButton           matlab.ui.control.Button
        LcurveModelErrorFig         matlab.ui.control.UIAxes
        LcurveFig                   matlab.ui.control.UIAxes
        RadialTab                   matlab.ui.container.Tab
        statusLabel                 matlab.ui.control.Label
        StatusImage                 matlab.ui.control.Image
        BackgroundBox               matlab.ui.control.TextArea
        MRSImage                    matlab.ui.control.Image
        MessageBox                  matlab.ui.control.TextArea
        TulipImage                  matlab.ui.control.Image
        GustavStrijkersAmsterdamUMCVersion731Label  matlab.ui.control.Label
        RetrospectiveLabel          matlab.ui.control.Label
    end


    % RETROSPECTIVE TRIGGERING RECONSTRUCTION
    %
    % Gustav Strijkers
    % Amsterdam UMC, Academic Medical Center (AMC)
    % Amsterdam, the Netherlands
    % g.j.strijkers@amsterdamumc.nl
    %
    %
    %
    % Version: July, 2021
    % Matlab 2021a
    %
    %
    % Version 1.0
    % - initial commit
    %
    % Version 2.0 changes:
    % - app designer GUI
    % 
    % Version 2.1 changes:
    % - Cleaned-up the code
    % - GIF export file name now includes the number of frames
    % - Automatically start sorting k-space when the number of frames is changed
    % - Added buttons to rotate movie
    % - Added buttons to shift movie in the frame/time dimension
    % - Animated gif export image intensity is scaled by the intensity scaling slider
    % - Trajectory figure added
    % - Full x-scale in plot windows
    % - Added sliders to adjust navigator view range
    % - Parameter no_effective_views is now obsolete
    %
    % Version 3.0 changes:
    % - Added a matlab Conjugadient Gradient method for CS-reconstruction without Bart toolbox
    %
    % Version 3.1 changes:
    % - Added the possibility to reconstruction a respiratory CINE
    % - Corrected a bug in the calculation of the total acquisition time
    % - Added the possibility to reconstruct only part of the data
    % - Added detected respiration and heart rate markers and values in navigator power spectrum
    % - Bug fixed number_of_cardiac frames
    % - Improved check on correct loading of MRD file
    % - Cleaned up the code
    %
    % Version 3.2 changes:
    % - Added edit fields for cardiac and respiratory filter values
    % - Fixed an issue with the image size of the movie in the app
    % - Added a reconstruction progress bar
    %
    % Version 3.3 changes:
    % - Bug fixes
    %
    % Version 3.4 changes:
    % - Major clean up of the code to accomodate future versions
    % - Added app message box
    % - Bug fixes
    % - Simplification of how to switch the buttons and lights on and off
    %
    % Version 3.5 changes:
    % - Bug fix for movie frame circular rotation DICOM export
    % - Fix a crash when respiratory or heart filter width > center value
    %
    % Version 3.6 changes:
    % - Added Total Variation in space regularization - Only works with Bart toolbox
    % - Now reading DICOM information from scanner reconstruction, including correct orientations
    % - VNSA weighting added
    % - Added additional search for DICOM information, compatible with PET/MRI systems
    %
    % Version 4.0 changes:
    % - Bug fixes
    % - Reconstructions of 3D+time data implemented
    % - Cleaned-up Matlab conjugate-gradient reconstruction code
    % - Added busy icons
    %
    % Version 5.0 changes:
    % - Added averages and k-space view
    % - Reconstruction of multi-coil data, tested with 2 receiver coils
    % - PCA analysis of navigator data and multi-coil data
    % - ESPIRiT coil sensitivity maps for multi-coil data
    % - Cosmetic changes
    % - Window/Level intensity scaling
    % - Respiration filtering with main peak + 1 harmonic
    % - Image orientation corrected for phase-encoding-orientation direction
    % 
    % Version 5.1 changes:
    % - Added MRD export button, not functional yet
    % - Added cardiac absolute binning sorting option
    %
    % Version 5.2 changes:
    % - Added average navigator window (not very useful it seems)
    %
    % Version 5.3 changes:
    % - Radial import, first attempts
    % - Cleaned up code to include radial option
    % - Data window includes now an 'include' and 'exclude' data option
    % - Cleaned up code to make it easier to include more navigator types in the future
    %
    % Version 6.0 changes:
    % - Now supports reconstructions of dynamics
    % - VNSA removed (does not seem very useful)
    % - Average navigator removed (idem)
    % - MRD export and reconstruction by scanner reconstructor
    % - Improved check for loading unique dataset in loadMRD
    %
    % Version 6.1 changes:
    % - Implemented 1 frame reconstruction for 2D and 3D recos 
    % - Corrected sequential and centric k-space filling for views2 
    % - SUR file export to /1 directory on MRI/PET systems
    % - Multi-slice 2D acquisitions supported
    % - If on pc, check for system MRD and data directories
    %     
    % Version 6.2 changes:
    % - Correction on 3D navigator code / bug fixes
    % - Discard points parameter added to prevent zipper artifact
    % 
    % Version 6.3 changes:
    % - 2D and 3D Tukey filter of k-space, prevents zipper artifact
    % - Added autointensity button
    % - Navigator movie function added
    % - 3D P2ROUD trajectory import implemented, with pe1_order = 4
    %
    % Version 7.0 changes:
    % - Saves textfile with reconstruction setting parameters
    % - L-curve analysis of single slice 2D cine data
    % - Working on radial reconstruction
    % - Zoom of movie possible
    %
    % Version 7.1 changes:
    % - Advanced navigator panel with presets for mouse and rat
    % - Preset panel with presets for different reconstructions
    %
    % Version 7.2 changes:
    % - Bug fix in initial values navigator sliders
    % - Improved respiration detection for multi-slice acquistions
    %
    % Version 7.3 changes:
    % - Reconstruction of realtime = 1 dynamic per heart beat added
    % - Improved export of dynamic series
    % - Added locally Low-Rank regularization parameter
    %
    % Version 7.3.1 changes:
    % - Don't mention the B word import added
    % -


    % global variables within the app
    properties (Access = private)
        
        % Directory & filename
        appdir                          % application directory
        olddir                          % starting directory
        mrd_import_path                 % mrd import path
        mrd_flag                        % standard mrd import path already defined true or false
        dicom_export_path               % dicom export path
        dicom_flag                      % dicom export path already defned true or false
        gif_export_path                 % gif export path      
        mrd_export_path                 % mrd export path
        bart_detected                   % bart toolbox available: true or false
        tag                             % filename tag for dicom export
        
        % Data file
        datafilename                    % mrd data file
        data                            % raw mrd data
        mrdfooter                       % complete MRD file footer
        rpr                             % rpr file
        rpr_flag                        % rpr file exist true or false
        kspace                          % the final sorted and normalized k-space
        kspace_mrd                      % kspace used for export to MRD file
        averages                        % matrix with the number of averages per k-line
        datatype                        % data type : 2D, 3D, 2Dradial
        valid_data                      % valid k-space data (true or false)
        
        % Acquisition
        dimx                            % nr readout points
        dimy                            % nr phase encoding points
        dimz                            % nr phase encoding in 3rd dimension / slices
        TR                              % repetition time
        acq_dur                         % total acquisition time
        traject_type                    % trajectory type (0,2,3)
        nr_ksteps                       % number of phase encoding steps in 1 repetition
        nr_klines                       % total number of k-lines
        nr_coils                        % number of RF receiver coils
        multicoil                       % multicoil data true or false
        multi2D                         % multislice 2D acquistion true or false
        multislice                      % multislice data (2D or 3D) true or false
        multidynamic                    % multidynamic data true or false
        
        % Navigator & trigger
        nr_nav                          % navigator point
        nr_repetitions                  % nr of k-space repetitions
        nav_discarded                   % number of discarded k-space points between nav and echo
        nav_points_used                 % nr of used navigator point
        nav_amplitude                   % navigator amplitude
        nav_phase                       % navigator phase
        nav_pc                          % navigator principal component
        nav_heart                       % filtered heart navigation signal
        nav_resp                        % filtered respiration navigator signal
        detectedHR                      % detected heart rate
        heartrate                       % final average heart rate
        detectedRR                      % detected respiratory rate
        resprate                        % final respiratory rate
        frequency                       % frequency range of power spectrum of navigator
        powerspectrum                   % power spectrum of the navigator
        heart_bandwidth                 % heart rate filter bandwidth
        resp_bandwidth                  % respiration rate filter bandwidth
        resp_percentage                 % respiration percentage discarded
        resp_window                     % respiration window
        physiofiltersettings            % min/max estimations for heart and respiration rates
        splinefactor                    % navigator spline interpolation factor
        filtered                        % navigator data filtered true or false
        hpks                            % cardiac trigger points
        rpks                            % respiratory trigger points
        hlocs                           % cardiac trigger point locations
        rlocs                           % respiratory trigger point locations
        includewindow                   % window to include data
        excludewindow                   % window to exclude data
      
        % Reconstruction and movie
        nr_frames                       % desired number of movie frames
        nr_dynamics                     % desired number of dynamics
        nr_slices                       % number of reconstructed slices
        sense_map                       % sensitivity map
        sense_map_exists                % sense maps exists, true or false
        movie_exp                       % reconstruced movie for DCM and MRD export
        movie_app                       % movie for viewing in the app
        progress                        % reconstruction progress bar
        valid_reco                      % valid reconstruction true or false
        cbar                            % color bar
        level                           % movie intensity level
        window                          % movie intensity window
        stopmovie                       % flag to stop the movie (true / false)
        stopLcurve                      % flag to stop Lcurve calculation (true/false)
        stopnavmovie                    % flag to stop the navigator movie (true / false)
        navmoviespeed                   % speed of the navigator movie
        slicenr                         % slice shown in the movie
        dynamicnr                       % dynamic shown in the movie
        
        % Plot elements
        dwin1                           % inclusion datawindow in heartrate plot
        dwin2                           % inclusion datawindow in respirationrate plot
        dwin3                           % exclusion datawindow in heartrate plot
        dwin4                           % exclusion datawindow in respirationrate plot
        dplot1                          % dynamics indication in the heartrate plot
        dplot2                          % dynamics indication in the respirationrate plot
        dplot1txt                       % dynamics indication text
        dplot2txt                       % dynamics indication text   
        msplot1                         % multislice indication in the heartrate plot
        msplot2                         % multislice indication in the respirationrate plot
        msplot1txt                      % multislice indication text
        msplot2txt                      % multislice indication text  
        movieplot                       % movie plot
        hrate_window                    % start-end range indicator in time-heartrate figure
        rrate_window                    % start-end range indicator in time-respirationrate figure
        green                           % other shade of green
        brightgreen                     % green
        red                             % other shade of red
        brightred                       % red
        blue                            % blue
        yellow                          % yellow
        orange                          % orange
        
    end
    
    
    % public variables 
    properties (Access = public)
        
        parameters                      % mrd meta data
        
    end
    
    
    methods (Access = private)
        
        
        % -------------------------------------------------
        % Buttons and lights controls
        % -------------------------------------------------
        
        
        % Traffic lights
        function SetLights(app,bitmap)
            
            code = de2bi(bitmap,7);
            
            if code(1) == 0 app.Lamp1.Color = app.brightred; else app.Lamp1.Color = app.brightgreen; end
            if code(2) == 0 app.Lamp2.Color = app.brightred; else app.Lamp2.Color = app.brightgreen; end
            if code(3) == 0 app.Lamp3.Color = app.brightred; else app.Lamp3.Color = app.brightgreen; end
            if code(4) == 0 app.Lamp4.Color = app.brightred; else app.Lamp4.Color = app.brightgreen; end
            if code(5) == 0 app.Lamp5.Color = app.brightred; else app.Lamp5.Color = app.brightgreen; end
            if code(6) == 0 app.Lamp6.Color = app.brightred; else app.Lamp6.Color = app.brightgreen; end
            if code(7) == 0 app.Lamp7.Color = app.brightred; else app.Lamp7.Color = app.brightgreen; end
            
            drawnow;
            
        end
        
        
        % Dashboard buttons
        function SetDashboardButtons(app,bitmap)
            
            code = de2bi(bitmap,8);
            
            if code(1) == 0 app.LoadMRDButton.Enable = 'off'; else app.LoadMRDButton.Enable = 'on'; end
            if code(2) == 0 app.FilterButton.Enable = 'off'; else app.FilterButton.Enable = 'on'; end
            if code(3) == 0 app.SortKspaceButton.Enable = 'off'; else app.SortKspaceButton.Enable = 'on'; end
            if code(4) == 0 app.ReconstructButton.Enable = 'off'; else app.ReconstructButton.Enable = 'on'; end
            if code(5) == 0 app.ExportDCMButton.Enable = 'off'; else app.ExportDCMButton.Enable = 'on'; end
            if code(6) == 0 app.ExportGIFButton.Enable = 'off'; else app.ExportGIFButton.Enable = 'on'; end
            if code(7) == 0 app.ExportMRDButton.Enable = 'off'; else app.ExportMRDButton.Enable = 'on'; end
            if code(8) == 0 app.ExitButton.Enable = 'off'; else app.ExitButton.Enable = 'on'; end
            
            drawnow;
            
        end
        
        
        % Read dashboard button status
        function bitmap = GetDashboardButtons(app)
            
            if app.LoadMRDButton.Enable == 'off' code(1) = 0; else code(1) = 1; end
            if app.FilterButton.Enable == 'off' code(2) = 0; else code(2) = 1; end
            if app.SortKspaceButton.Enable == 'off' code(3) = 0; else code(3) = 1; end
            if app.ReconstructButton.Enable == 'off' code(4) = 0; else code(4) = 1; end
            if app.ExportDCMButton.Enable == 'off' code(5) = 0; else code(5) = 1; end
            if app.ExportGIFButton.Enable == 'off' code(6) = 0; else code(6) = 1; end
            if app.ExportMRDButton.Enable == 'off' code(7) = 0; else code(7) = 1; end
            if app.ExitButton.Enable == 'off' code(8) = 0; else code(8) = 1; end
            
            bitmap = bi2de(code);
            drawnow;
            
        end
                
        
        % Navigator selection buttons
        function NavSelButtons(app,on)
            
            if on
                
                app.NavigatorEndEditField.Enable = 'on';
                app.NavigatorNRPointsEditField.Enable = 'on';
                app.NavigatorFlipSwitch.Enable = 'on';
                app.DiscardEditField.Enable = 'on';
                if strcmp(app.RecoTypeDropDown.Value,'realtime')
                    app.FrameSharingEditField.Enable = 'on';
                else
                    app.FrameSharingEditField.Enable = 'off';
                end
                if app.multicoil
                    app.CoilSpinner.Enable = 'on';
                else
                    app.CoilSpinner.Enable = 'off';
                end
                app.loglinButton.Enable = 'on';
                app.HeartEditField.Enable = 'on';
                app.RespirationEditField.Enable = 'on';
                app.HeartWidthEditField.Enable = 'on';
                app.RespirationWidthEditField.Enable = 'on';
                app.RespirationWindowEditField.Enable = 'on';
                app.ResetFilterValuesButton.Enable = 'on';
                app.MouseButton.Enable = 'on';
                app.RatButton.Enable = 'on';
                app.MouseButton2.Enable = 'on';
                app.RatButton2.Enable = 'on';
                app.FilterorderEditField.Enable = 'on';
                app.MaxheartrateEditField.Enable ='on';
                app.MinheartrateEditField.Enable ='on';
                app.MinresprateEditField.Enable = 'on';
                app.MaxresprateEditField.Enable = 'on';
                
            else
                
                app.NavigatorEndEditField.Enable = 'off';
                app.NavigatorNRPointsEditField.Enable = 'off';
                app.NavigatorFlipSwitch.Enable = 'off';
                app.DiscardEditField.Enable = 'off';
                app.FrameSharingEditField.Enable = 'off';
                app.CoilSpinner.Enable = 'off';
                app.loglinButton.Enable = 'off';
                app.HeartEditField.Enable = 'off';
                app.RespirationEditField.Enable = 'off';
                app.HeartWidthEditField.Enable = 'off';
                app.RespirationWidthEditField.Enable = 'off';
                app.RespirationWindowEditField.Enable = 'off';
                app.ResetFilterValuesButton.Enable = 'off';
                app.MouseButton.Enable = 'off';
                app.RatButton.Enable = 'off';
                app.MouseButton2.Enable = 'off';
                app.RatButton2.Enable = 'off';
                app.FilterorderEditField.Enable = 'off';
                app.MaxheartrateEditField.Enable ='off';
                app.MinheartrateEditField.Enable ='off';
                app.MinresprateEditField.Enable = 'off';
                app.MaxresprateEditField.Enable = 'off';
                
            end
            
            drawnow;
            
        end
        
        
        % Navigator viewing buttons
        function NavViewingButtons(app,on)
            
            if on
                
                app.StartEditField.Enable = 'on';
                app.RangeEditField.Enable = 'on';
                app.StartSlider.Enable = 'on';
                app.RangeSlider.Enable = 'on';
                app.NavStartButton.Enable = 'on';
                app.NavStopButton.Enable = 'on';
                app.NavFastSlowSwitch.Enable = 'on';
                
                if (app.DynamicsEditField.Value == 1) && ~app.multi2D
                    app.IncludeCheckBox.Enable = 'on';
                    app.ExcludeCheckBox.Enable = 'on';
                else
                    app.IncludeCheckBox.Enable = 'off';
                    app.ExcludeCheckBox.Enable = 'off';
                    app.IncludeStartSlider.Enable = 'off';
                    app.IncludeEndSlider.Enable = 'off';
                    app.ExcludeStartSlider.Enable = 'off';
                    app.ExcludeEndSlider.Enable = 'off';
                end
                
                if app.IncludeCheckBox.Value
                    app.IncludeStartSlider.Enable = 'on';
                    app.IncludeEndSlider.Enable = 'on';
                else
                    app.IncludeStartSlider.Enable = 'off';
                    app.IncludeEndSlider.Enable = 'off';
                end
                
                if app.ExcludeCheckBox.Value
                    app.ExcludeStartSlider.Enable = 'on';
                    app.ExcludeEndSlider.Enable = 'on';
                else
                    app.ExcludeStartSlider.Enable = 'off';
                    app.ExcludeEndSlider.Enable = 'off';
                end
                
            else
                
                app.StartEditField.Enable = 'off';
                app.RangeEditField.Enable = 'off';
                app.StartSlider.Enable = 'off';
                app.RangeSlider.Enable = 'off';
                app.NavStartButton.Enable = 'off';
                app.NavStopButton.Enable = 'off';
                app.NavFastSlowSwitch.Enable = 'off';
                app.IncludeCheckBox.Enable = 'off';
                app.IncludeStartSlider.Enable = 'off';
                app.IncludeEndSlider.Enable = 'off';
                app.ExcludeCheckBox.Enable = 'off';
                app.ExcludeStartSlider.Enable = 'off';
                app.ExcludeEndSlider.Enable = 'off';
                
            end
            
            drawnow;
            
        end
        
        
        % Cine reco buttons
        function CineRecoButtons(app,on)
            
            if on
                
                app.ScoutButton.Enable ='on';
                app.SystolicFunctionButton.Enable = 'on';
                app.DiastolicFunctionButton.Enable = 'on';
                app.RespiratoryFunctionButton.Enable = 'on';
                app.RealTimeFunctionButton.Enable = 'on';
                
                app.FramesEditField.Enable = 'on';
                if app.multi2D
                    app.DynamicsEditField.Enable = 'off';
                    app.DynamicsEditField.Value = 1;
                else
                    app.DynamicsEditField.Enable = 'on';
                end
                
                app.RecoTypeDropDown.Enable = 'on';
                app.TVcineEditField.Enable = 'on';
                app.TVcineEditFieldLabel.Enable = 'on';
                
                if app.bart_detected
                    app.WVxyzEditField.Enable = 'on';
                    app.TVxyzEditField.Enable = 'on';
                    app.LRxyzEditField.Enable = 'on';
                    app.TVdynEditField.Enable = 'on';
                    app.Lambda1EditField.Enable = 'on';
                    app.Lambda2EditField.Enable = 'on';
                    app.LambdaStepsEditField.Enable = 'on';
                    app.LambdaIterationsEditField.Enable = 'on';
                    app.LcurveWVxyzCheckBox.Enable = 'on';
                    app.LcurveTVxyzCheckBox.Enable = 'on';
                    app.LcurveLRxyzCheckBox.Enable = 'on';
                    app.LcurveTVcineCheckBox.Enable = 'on';
                    app.LcurveTVdynCheckBox.Enable = 'on';
                else
                    app.WVxyzEditField.Enable = 'off';
                    app.TVxyzEditField.Enable = 'off';
                    app.LRxyzEditField.Enable = 'off';
                    app.TVdynEditField.Enable = 'off';
                    app.Lambda1EditField.Enable = 'off';
                    app.Lambda2EditField.Enable = 'off';
                    app.LambdaStepsEditField.Enable = 'off';
                    app.LambdaIterationsEditField.Enable = 'off';
                    app.LcurveWVxyzCheckBox.Enable = 'off';
                    app.LcurveTVxyzCheckBox.Enable = 'off';
                    app.LcurveLRxyzCheckBox.Enable = 'off';
                    app.LcurveTVcineCheckBox.Enable = 'off';
                    app.LcurveTVdynCheckBox.Enable = 'off';
                end
                
                if app.multicoil && app.bart_detected
                    app.ESPIRiTCheckBox.Enable = 'on';
                    app.CLEARCheckBox.Enable = 'on';
                else
                    app.ESPIRiTCheckBox.Enable = 'off';
                    app.CLEARCheckBox.Enable = 'off';
                    app.ESPIRiTCheckBox.Value = 0;
                    app.CLEARCheckBox.Value = 0;
                end
                
            else
                
                app.ScoutButton.Enable ='off';
                app.SystolicFunctionButton.Enable = 'off';
                app.DiastolicFunctionButton.Enable = 'off';
                app.RespiratoryFunctionButton.Enable = 'off';
                app.RealTimeFunctionButton.Enable = 'off';

                app.FramesEditField.Enable = 'off';
                app.DynamicsEditField.Enable = 'off';
                app.RecoTypeDropDown.Enable = 'off';
                app.WVxyzEditField.Enable = 'off';
                app.TVcineEditField.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.LRxyzEditField.Enable = 'off';
                app.TVdynEditField.Enable = 'off';
                app.ESPIRiTCheckBox.Enable = 'off';
                app.CLEARCheckBox.Enable = 'off';
                app.Lambda1EditField.Enable = 'off';
                app.Lambda2EditField.Enable = 'off';
                app.LambdaStepsEditField.Enable = 'off';
                app.LambdaIterationsEditField.Enable = 'off';
                app.LcurveWVxyzCheckBox.Enable = 'off';
                app.LcurveTVxyzCheckBox.Enable = 'off';
                app.LcurveLRxyzCheckBox.Enable = 'off';
                app.LcurveTVcineCheckBox.Enable = 'off';
                app.LcurveTVdynCheckBox.Enable = 'off';

            end

            drawnow;
            
        end
        
        
        % Movie Buttons
        function MovieButtons(app,on)
            
            if on
                
                if app.stopmovie
                    app.StartButton.Enable = 'on';
                    app.StopButton.Enable = 'off';
                else
                    app.StartButton.Enable = 'off';
                    app.StopButton.Enable = 'on';
                end
                
                app.MovieFrameEditField.Enable = 'on';
                app.MovieCheckBox1.Enable = 'on';
                app.WindowScaleSlider.Enable = 'on';
                app.LevelScaleSlider.Enable = 'on';
                app.autoButton.Enable = 'on';
                app.cwButton.Enable = 'on';
                app.ccwButton.Enable = 'on';
                app.MovieFrameIncrButton.Enable = 'on';
                app.MovieFrameDecrButton.Enable = 'on';
                app.movieButton.Enable = 'on';
                app.averagesButton.Enable = 'on';
                app.kspaceButton.Enable = 'on';
                
                if app.multicoil
                    app.MovieCoilSpinner.Enable = 'on';
                else
                    app.MovieCoilSpinner.Enable = 'off';
                end
                
                if app.sense_map_exists
                    app.sensemapButton.Enable = 'on';
                    s1 = size(app.sense_map,4);
                    s2 = size(app.sense_map,5);
                    app.sensemapSpinner1.Value = 1;
                    if s1>1
                        app.sensemapSpinner1.Limits = [1,s1];
                        app.sensemapSpinner1.Enable = 'on';
                    else
                        app.sensemapSpinner1.Limits = [1,2];
                        app.sensemapSpinner1.Enable = 'off';
                    end
                    app.sensemapSpinner2.Value = 1;
                    if s2>1
                        app.sensemapSpinner2.Limits = [1,s2];
                        app.sensemapSpinner2.Enable = 'on';
                    else
                        app.sensemapSpinner2.Limits = [1,2];
                        app.sensemapSpinner2.Enable = 'off';
                    end
                else
                    app.sensemapButton.Enable = 'off';
                    app.sensemapSpinner1.Enable = 'off';
                    app.sensemapSpinner2.Enable = 'off';
                end
                
                if app.multislice
                    app.MovieSliceEditField.Enable = 'on';
                    app.MovieSliceIncrButton.Enable = 'on';
                    app.MovieSliceDecrButton.Enable = 'on';
                    app.MovieCheckBox2.Enable = 'on';
                end
                
                if app.multidynamic
                    app.MovieDynEditField.Enable = 'on';
                    app.MovieDynIncrButton.Enable = 'on';
                    app.MovieDynDecrButton.Enable = 'on';
                    app.MovieCheckBox3.Enable = 'on';
                end
                
            else
                
                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'off';
                app.MovieFrameEditField.Enable = 'off';
                app.MovieSliceEditField.Enable = 'off';
                app.MovieDynEditField.Enable = 'off';
                app.MovieCheckBox2.Enable = 'off';
                app.MovieCheckBox3.Enable = 'off';
                app.WindowScaleSlider.Enable = 'off';
                app.LevelScaleSlider.Enable = 'off';
                app.autoButton.Enable = 'off';
                app.cwButton.Enable = 'off';
                app.ccwButton.Enable = 'off';
                app.MovieFrameIncrButton.Enable = 'off';
                app.MovieFrameDecrButton.Enable = 'off';
                app.MovieSliceIncrButton.Enable = 'off';
                app.MovieSliceDecrButton.Enable = 'off';
                app.MovieDynIncrButton.Enable = 'off';
                app.MovieDynDecrButton.Enable = 'off';
                app.movieButton.Enable = 'off';
                app.averagesButton.Enable = 'off';
                app.kspaceButton.Enable = 'off';
                app.MovieCoilSpinner.Enable = 'off';
                app.sensemapButton.Enable = 'off';
                app.sensemapSpinner1.Enable = 'off';
                app.sensemapSpinner2.Enable = 'off';
                app.stopmovie = true;
                
            end
            
            drawnow;
            
        end
        
        
        % Switch of all buttons
        function SwitchOffAllButtons(app)
            
            % stop the movies
            app.stopnavmovie = true;
            app.stopmovie = true;
            
            % dashboard buttons
            app.SetDashboardButtons(0);
            
            % navigator selection and filtering
            app.NavSelButtons(false);
            
            % navigator viewing
            app.NavViewingButtons(false);
            
            % cine reconstruction buttons
            app.CineRecoButtons(false);
            
            % movie window
            app.MovieButtons(false);
            
        end
        
         
        % Load icons
        function SetAppLayoutProperties(app)
        
            app.TulipImage.ImageSource = 'AmsterdamUMC.gif';
            app.MRSImage.ImageSource = 'MRS.gif';
            
            app.BusyImage1.ImageSource = 'busy.gif';
            app.BusyImage2.ImageSource = 'busy.gif';
            app.BusyImage3.ImageSource = 'busy.gif';
            app.BusyImage4.ImageSource = 'busy.gif';
            app.BusyImage5.ImageSource = 'busy.gif';
            app.BusyImage6.ImageSource = 'busy.gif';
            app.BusyImage7.ImageSource = 'busy.gif';
            
            app.BusyImage1.Visible = 'off';
            app.BusyImage2.Visible = 'off';
            app.BusyImage3.Visible = 'off';
            app.BusyImage4.Visible = 'off';
            app.BusyImage5.Visible = 'off';
            app.BusyImage6.Visible = 'off';
            app.BusyImage7.Visible = 'off';
            
            app.LoadMRDButton.Icon = 'disk.png';
            app.FilterButton.Icon = 'filter.png';
            app.SortKspaceButton.Icon = 'sort.gif';
            app.ReconstructButton.Icon = 'reconstruct.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExportMRDButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StartButton.Icon = 'play.gif';
            app.NavStartButton.Icon = 'play.gif';
            app.StopButton.Icon = 'stop.gif';
            app.NavStopButton.Icon = 'stop.gif';
            app.cwButton.Icon = 'cw.png';
            app.ccwButton.Icon = 'ccw.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            
            % define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green'; 
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            
            % set some plot properties
            app.CardiacNavFig.Toolbar.Visible = 'off';
            app.RespirationNavFig.Toolbar.Visible = 'off';
            app.RawNavFig.Toolbar.Visible = 'off';
            app.SpectrumFig.Toolbar.Visible = 'off';
            app.HeartRateTimeFig.Toolbar.Visible = 'off';
            app.RespirationRateTimeFig.Toolbar.Visible = 'off';
            app.MovieFig.Toolbar.Visible = 'off';
            app.TrajectoryFig.Toolbar.Visible = 'off';
            app.LcurveFig.Toolbar.Visible = 'off';
            app.LcurveModelErrorFig.Toolbar.Visible = 'off';
            
            % switch off plot interactivity, except for movie plot
            app.CardiacNavFig.Interactions = [];
            app.RespirationNavFig.Interactions = [];
            app.RawNavFig.Interactions = [];
            app.SpectrumFig.Interactions = [];
            app.HeartRateTimeFig.Interactions = [];
            app.RespirationRateTimeFig.Interactions = [];
            app.MovieFig.Interactions = [zoomInteraction regionZoomInteraction];
            app.TrajectoryFig.Interactions = [];
            app.LcurveFig.Interactions = [];
            app.LcurveModelErrorFig.Interactions = [];
            
        end
        
        function SetSpecies(app)
            
          % mouse preset settings
            if app.MouseButton.Value

                app.heart_bandwidth = 100;
                app.HeartWidthEditField.Value = 100;
                app.resp_bandwidth = 20;
                app.RespirationWidthEditField.Value = 40;
                app.resp_percentage = 30;
                app.RespirationWindowEditField.Value = 30;
                app.MinheartrateEditField.Value = 300;
                app.MaxheartrateEditField.Value = 620;
                app.MinresprateEditField.Value = 30;
                app.MaxresprateEditField.Value = 70;
                app.FilterorderEditField.Value = 4;
              
            end

            % rat preset settings
            if app.RatButton.Value

                app.heart_bandwidth = 100;
                app.HeartWidthEditField.Value = 100;
                app.resp_bandwidth = 20;
                app.RespirationWidthEditField.Value = 40;
                app.resp_percentage = 30;
                app.RespirationWindowEditField.Value = 30;
                app.MinheartrateEditField.Value = 200;
                app.MaxheartrateEditField.Value = 450;
                app.MinresprateEditField.Value = 30;
                app.MaxresprateEditField.Value = 70;
                app.FilterorderEditField.Value = 4;
                
            end
        
        end
        
        
        % ------------------------------------------
        % Image acquisition data parameters
        % ------------------------------------------
        
        
        function DataParametersFcn(app)
            
            % Parameters
            app.TR = app.parameters.tr;                                                 % repetition time [ms]
            if isfield(app.parameters,'tr_extra_us')
               app.TR = app.TR + app.parameters.tr_extra_us/1000;
            end
            app.splinefactor = 60;                                                      % data interpolation factor to prevent navigator discretization by TR
            app.nr_repetitions = size(app.data{1},1);                                   % nr k-space repetitions
            app.dimz = size(app.data{1},2);                                             % nr of points in 3rd dimension (2D multi-slice or 3D)
            app.heart_bandwidth = app.HeartWidthEditField.Value;                        % cardiac bandpass filter bandwidth, default = 150
            app.resp_bandwidth = app.RespirationWidthEditField.Value;                   % respiratory bandpass filter bandwidth, default = 20
            app.resp_percentage = app.RespirationWindowEditField.Value;                 % percentage of data discarded during respiration, default = 30%
            app.nr_frames = app.FramesEditField.Value;                                  % default number of cardiac frames
            app.nr_dynamics = app.DynamicsEditField.Value;                              % default number of dynamics
            app.filtered = false;                                                       % reset the filtered flag
            
            % GUI parameters
            app.StartEditField.Value = 1;                                               % reset the nav viewing start value
            app.RangeEditField.Value = 100;                                             % reset the nav viewing range
            app.StartSlider.Value = 1;                                                  % reset the nav viewing start value slider
            app.RangeSlider.Value = 100;                                                % reset the nav viewing range slider
            app.navmoviespeed = 10;                                                     % initial speed of the nav movie
            app.IncludeCheckBox.Value = 0;                                              % reset the include and exclude sliders
            app.ExcludeCheckBox.Value = 0;
           
             
            switch app.datatype
                
                case {'2D','2Dms','3D','3Dp2roud'}
                    
                    if app.DiscardEditField.Value > round((size(app.data{1},4) - app.nr_nav)/2)     % check that number of discard points is not bigger than 1/2 readout
                        app.DiscardEditField.Value = round((size(app.data{1},4) - app.nr_nav)/2);
                    end
                    
                    app.traject_type = app.parameters.pe1_order;                                % k-space trajectroy:  2 = zigzag,  3 = from input array, 4 = 3D proud
                    app.nav_discarded = app.DiscardEditField.Value;                             % additionally discarded k-space samples (during phase encoding / readout dephaser)
                    app.dimx = size(app.data{1},4) - app.nr_nav - app.nav_discarded;            % nr readout points (no_samples)
                    cla(app.TrajectoryFig);                                                     % clear trajectory figure
                    
                    switch app.traject_type
                        
                        case 0
                            
                            app.TrajectoryViewField.Value = 'linear';
                            app.dimy = app.parameters.NO_VIEWS;                                 % nr phase encoding steps (no_views)
                            app.nr_ksteps = app.dimy;                                           % nr k-space points per repetition = nr phase encoding steps
                            app.nr_klines = app.nr_repetitions*app.dimy*app.dimz;               % total number of k-lines that are measured
                            plot(app.TrajectoryFig, linear_trajectory(app.dimy));               % plot the trajectory
                            xlim(app.TrajectoryFig,[1 length(linear_trajectory(app.dimy))-1]);
                            ylim(app.TrajectoryFig,[min(linear_trajectory(app.dimy))-1 max(linear_trajectory(app.dimy))+1]);
                            
                        case 2
                            
                            app.TrajectoryViewField.Value = 'zigzag';
                            app.dimy = app.parameters.NO_VIEWS;                                 % nr phase encoding steps (no_views)
                            app.nr_ksteps = app.dimy;                                           % nr k-space points per repetition = nr phase encoding steps
                            app.nr_klines = app.nr_repetitions*app.dimy*app.dimz;               % total number of k-lines that are measured
                            plot(app.TrajectoryFig, zigzag_trajectory(app.dimy));               % plot the trajectory
                            xlim(app.TrajectoryFig,[1 length(zigzag_trajectory(app.dimy))-1]);
                            ylim(app.TrajectoryFig,[min(zigzag_trajectory(app.dimy))-1 max(zigzag_trajectory(app.dimy))+1]);
                            
                        case 3
                            
                            app.TrajectoryViewField.Value = 'user defined';
                            app.dimy = max(app.parameters.gp_var_mul)-min(app.parameters.gp_var_mul)+1;     % effective nr phase encoding steps (no_effective_views)
                            app.nr_ksteps = app.parameters.NO_VIEWS;                                        % nr k-space trajectory points (no_views) in table
                            app.nr_klines = app.nr_repetitions*app.nr_ksteps*app.dimz;                      % total number of k-lines that are measured
                            plot(app.TrajectoryFig, array_trajectory(app.parameters));                      % plot the trajectory
                            xlim(app.TrajectoryFig,[1 length(array_trajectory(app.parameters))-1]);
                            ylim(app.TrajectoryFig,[min(array_trajectory(app.parameters))-1 max(array_trajectory(app.parameters))+1]);
                            
                        case 4
                            
                            % RESERVED FOR P2ROUD ACQUISITION
                            app.TrajectoryViewField.Value = 'P2ROUD';
                            app.dimy = app.parameters.NO_VIEWS;                                 % nr phase encoding steps (no_views)
                            app.nr_ksteps = app.dimy;                                           % nr k-space points per repetition = nr phase encoding steps
                            app.nr_klines = app.nr_repetitions*app.dimy*app.dimz;               % total number of k-lines that are measured
                            flist = dir(fullfile(app.mrd_import_path,'*.txt'));                 
                            if ~isempty(flist)
                                trajectory = load([flist(1).folder,filesep,flist(1).name]);
                                scatter(app.TrajectoryFig, trajectory(1:2:end),trajectory(2:2:end),'s');            % plot the 3D trajectory
                                xlim(app.TrajectoryFig,[min(trajectory(1:2:end))-1,max(trajectory(1:2:end))+1]);
                                ylim(app.TrajectoryFig,[min(trajectory(2:2:end))-1,max(trajectory(2:2:end))+1]);
                            else
                                text(app.TrajectoryFig,-0.7,1,'MISSING');
                                text(app.TrajectoryFig,-2.4,-0.5,'TRAJECTORY');
                                xlim(app.TrajectoryFig,[-3 9]);
                                ylim(app.TrajectoryFig,[-3 3]);
                            end
                             
                    end
                    
                case '2Dradial'
                    
                    app.TrajectoryViewField.Value = 'radial';
                    app.traject_type = 8;
                    app.DiscardEditField.Value = 0;
                    app.dimx = app.parameters.NO_SAMPLES;
                    app.dimy = app.dimx;
                    app.nr_ksteps = app.parameters.NO_VIEWS;
                    app.nr_klines = app.nr_repetitions*app.nr_ksteps*app.dimz;
                    plot(app.TrajectoryFig, radial_trajectory(app.nr_ksteps));
                    xlim(app.TrajectoryFig,[0 app.dimx+1]);
                    ylim(app.TrajectoryFig,[0 app.dimy+1]);
                    
            end
            
            % reset include / exclude window
            app.includewindow = ones(app.nr_klines,1);
            app.excludewindow = ones(app.nr_klines,1);
            
            % total acquisition time in seconds
            app.acq_dur = app.nr_klines*app.TR/1000;
            
            % display the acquisitionparameters
            app.NxViewField.Value = app.dimx;
            app.NyViewField.Value = app.dimy;
            app.NzViewField.Value = app.dimz;
            app.NcViewField.Value = app.nr_coils;
            app.KlinesRepetitionViewField.Value = app.nr_ksteps;
            app.RepetitionsViewField.Value = app.nr_repetitions;
            app.NrKlinesViewField.Value = app.nr_klines;
            app.TRViewField.Value = app.TR;
            app.ScanTimeViewField.Value = datestr(seconds(app.acq_dur),'HH:MM:SS.FFF');
            app.TimeDynViewField.Value = datestr(seconds(app.acq_dur/app.DynamicsEditField.Value),'HH:MM:SS.FFF');
            
            % define the ranges of the display range sliders
            app.StartEditField.Limits = [1, app.nr_klines-1];
            app.StartSlider.Limits = [1, app.nr_klines-1];
            app.StartEditField.Value = round(app.nr_klines/2);
            app.StartSlider.Value = round(app.nr_klines/2);
            app.RangeEditField.Value = 600;
            app.RangeSlider.Value = 600;
            
            % data limits
            app.IncludeStartSlider.Limits = [0,app.acq_dur];
            app.IncludeEndSlider.Limits = [0,app.acq_dur];
            app.IncludeStartSlider.Value = 0;
            app.IncludeEndSlider.Value = app.acq_dur;
            app.ExcludeStartSlider.Limits = [0,app.acq_dur];
            app.ExcludeEndSlider.Limits = [0,app.acq_dur];
            app.ExcludeStartSlider.Value = 0;
            app.ExcludeEndSlider.Value = app.acq_dur;
            app.sensemapSpinner1.Value = 1;
            app.sensemapSpinner2.Value = 1;
            app.ESPIRiTCheckBox.Value = 0;
            app.CLEARCheckBox.Value = 0;
            
            % case of multicoil data
            if app.multicoil        
                
                if app.CoilSpinner.Value > app.nr_coils 
                    app.CoilSpinner.Value.Value = 1; 
                    app.MovieCoilSpinner.Value = 1;
                end
                app.CoilSpinner.Limits = [1, app.nr_coils]; 
                app.MovieCoilSpinner.Limits = [1, app.nr_coils];
                
            else
                
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1, 2];
                app.MovieCoilSpinner.Value = 1;
                app.MovieCoilSpinner.Limits = [1, 2];
                
            end
            
        end
        
        
        % Export the reconstruction settings
        function ExportRecoParametersFcn(app,exportdir)
            
            pars = strcat(...
                "\nRECONSTRUCTION PARAMETERS \n", ...
                "------------------------- \n\n", ...
                "MRD file = ", app.DataFile.Value, "\n\n", ...
                "\nNAVIGATOR \n", ...
                "primary = ", num2str(app.NavigatorEndEditField.Value), "\n", ...
                "#points = ", num2str(app.NavigatorNRPointsEditField.Value), "\n", ...
                "switch = ",app.NavigatorFlipSwitch.Value , "\n", ...
                "\nFILTERS \n", ...
                "heart = ", num2str(app.HeartEditField.Value), " bpm\n", ...
                "heart width = ", num2str(app.HeartWidthEditField.Value), " bpm\n", ...
                "respiration = ", num2str(app.RespirationEditField.Value), " bpm\n", ...
                "respiration width = ", num2str(app.RespirationWidthEditField.Value), " bpm\n", ...
                "respiration window = ", num2str(app.RespirationWindowEditField.Value), " %%\n", ...
                "\nCINE \n", ...
                "type = ",app.RecoTypeDropDown.Value, "\n", ...
                "#frames = ",num2str(app.FramesEditField.Value), "\n", ...
                "#dynamics = ",num2str(app.DynamicsEditField.Value), "\n", ...
                "\nRECO \n", ...
                "WVxyz = ",num2str(app.WVxyzEditField.Value), "\n", ...
                "TVxyz = ",num2str(app.TVxyzEditField.Value), "\n", ...
                "LRxyz = ",num2str(app.LRxyzEditField.Value), "\n", ...
                "TVcine = ",num2str(app.TVcineEditField.Value), "\n", ...
                "TVdyn = ",num2str(app.TVdynEditField.Value), "\n", ...
                "ESPIRiT = ",num2str(app.ESPIRiTCheckBox.Value), "\n", ...
                "CLEAR = ",num2str(app.CLEARCheckBox.Value), "\n", ...
                "\nINCLUDE & EXCLUDE WINDOWS\n", ...
                "include = ",num2str(app.IncludeCheckBox.Value), "\n", ...
                "include start = ",num2str(app.IncludeStartSlider.Value), " s\n", ...
                "include end = ",num2str(app.IncludeEndSlider.Value), " s\n", ...
                "exclude = ",num2str(app.ExcludeCheckBox.Value), "\n", ...
                "exclude start = ",num2str(app.ExcludeStartSlider.Value), " s\n", ...
                "exclude end = ",num2str(app.ExcludeEndSlider.Value), " s\n", ...
                "\nRATES \n", ...
                "respiration rate = ",num2str(app.FinalRespirationViewField.Value), " bpm\n", ...
                "heart rate = ",num2str(app.FinalHeartViewField.Value), " bpm\n" ...
                );
            
            fid = fopen(strcat(exportdir,filesep,'recoparmeters_',app.tag,'.txt'),'wt');
            fprintf(fid,pars);
            fclose(fid);
            
        end


        % ------------------------------------------
        % Other calculations
        % ------------------------------------------
        
        
        % Calculates the power spectrum of the raw navigator signal within the boundaries of the data-window
        function CalculateNavigatorSpectrumFcn(app)
            
            % determine the power spectrum of the navigator
            app.physiofiltersettings = [app.MinheartrateEditField.Value app.MaxheartrateEditField.Value app.MinresprateEditField.Value app.MaxresprateEditField.Value, app.FilterorderEditField.Value];
            [app.detectedHR,app.detectedRR,app.powerspectrum,app.frequency] = determine_power_spectrum_PCA(app.nav_amplitude,app.nr_coils,app.TR,app.parameters.NO_VIEWS,app.includewindow,app.excludewindow,app.physiofiltersettings);
            
            % update the detected heartrate and respirationrate fields
            app.HeartEditField.Value = app.detectedHR;
            app.RespirationEditField.Value = app.detectedRR;
            
            if app.HeartWidthEditField.Value > app.HeartEditField.Value
                app.HeartWidthEditField.Value = app.HeartEditField.Value;
            end
            
            if app.RespirationWidthEditField.Value > app.RespirationEditField.Value
                app.RespirationWidthEditField.Value = app.RespirationEditField.Value;
            end
            
        end
        
        
        % L-curve analysis
        function LcurveanalysisFcn(app,lambda,x,y,par)
                
                % calculate the curvature and find the maximum
                xl = log10(x);
                yl = log10(y);
                
                curv(1) = 0;
                curv(length(xl))=0;
                for i=2:length(xl)-1
                    
                    a = sqrt( (xl(i+1)-xl(i))^2   + (yl(i+1)-yl(i))^2   );
                    b = sqrt( (xl(i-1)-xl(i))^2   + (yl(i-1)-yl(i))^2   );
                    c = sqrt( (xl(i-1)-xl(i+1))^2 + (yl(i-1)-yl(i+1))^2 );
                    s = ( a + b + c ) / 2;
                    A = sqrt(s*(s-a)*(s-b)*(s-c));
                    curv(i) = 4*A/(a*b*c);
                    
                end
                [~,idx] = max(curv);

                % set lambda to the value corresponding to maximum curvature
                switch par

                    case 1
                        app.WVxyzEditField.Value = lambda(idx);

                    case 2
                        app.TVxyzEditField.Value = lambda(idx);

                    case 3
                        app.LRxyzEditField.Value = lambda(idx);

                    case 4
                        app.TVcineEditField.Value = lambda(idx);

                    case 5
                        app.TVdynEditField.Value = lambda(idx);

                end
                
                % indicate the value in the L-curve and model-error curve
                hold(app.LcurveFig,"on");
                loglog(app.LcurveFig,10^xl(idx),10^yl(idx),'o','MarkerEdgeColor',app.orange,'MarkerFaceColor',app.brightred,'LineWidth',1.5);
                hold(app.LcurveFig,"off");
                
                hold(app.LcurveModelErrorFig,"on");
                loglog(app.LcurveModelErrorFig,[lambda(1) lambda(end)],[10^xl(idx) 10^xl(idx)],'MarkerEdgeColor',[0 .5 .5],'MarkerFaceColor',app.blue,'LineWidth',1.5);
                hold(app.LcurveModelErrorFig,"off");
                
                pause(3);
            
        end


        % ------------------------------------------
        % Plotting figures and images
        % ------------------------------------------
               
        
        % Plot the raw navigator signals
        function PlotRawNavigatorFcn(app)
                
            % display x-range
            startpoint = app.StartEditField.Value;
            endpoint = app.StartEditField.Value+app.RangeEditField.Value-1;
            
            % extract part of the navigator that will be shown
            trace1 = app.nav_amplitude{app.CoilSpinner.Value}(startpoint:endpoint);
            
            % display y-range
            maxplt = max(trace1);
            minplt = min(trace1);
                      
            % plot the navigator
            plot(app.RawNavFig, trace1,'Color',app.blue);
            xlim(app.RawNavFig, [0 length(trace1)+1]);
            ylim(app.RawNavFig, [1.1*minplt 1.1*maxplt+1]);
            drawnow;
            
        end
        
        
        % Plots the navigator with cardiac filtering
        function PlotCardiacNavigatorFcn(app)
            
            % clear the image
            cla(app.CardiacNavFig);
            hold(app.CardiacNavFig,'on');
        
            if app.filtered
                
                % display x-range
                startpoint = app.StartEditField.Value;
                endpoint = app.StartEditField.Value+app.RangeEditField.Value-1;
                            
                % extract part of the navigator that will be shown
                trace1 = app.nav_pc(startpoint:endpoint);
                trace2 = app.nav_heart(startpoint:endpoint);
                
                % display y-range
                maxplt = 1.1*max(trace1);
                minplt = 1.1*min(trace1);
                
                % scale the filtered signal for viewing purposes
                trace2 = 0.25*max(trace1)*trace2;
                                
                % show the detected trigger points (only when movie is not running)
                if app.stopnavmovie
                    triggerpoints = app.hlocs((app.hlocs>=startpoint) & (app.hlocs<=endpoint));
                    if length(triggerpoints) < 40
                        for i = 1:length(triggerpoints)
                            win=plot(app.CardiacNavFig,[triggerpoints(i)-startpoint+1,triggerpoints(i)-startpoint+1],[minplt,maxplt],'black','LineWidth',1);
                            win.Color(4) = 0.2; % transparancy
                        end
                    end
                end
                            
                % plot the raw navigator
                plot(app.CardiacNavFig, trace1,'Color',app.blue);
                
                % plot the navigator filtered for cardiac motion
                plot(app.CardiacNavFig, trace2,'LineWidth',1,'Color',app.red);
                
                % set plot axes
                xlim(app.CardiacNavFig, [0 length(trace1)+1]);
                ylim(app.CardiacNavFig, [minplt maxplt]);
                
            end
            
            hold(app.CardiacNavFig,'off');
            drawnow limitrate;
            
        end
        
        
        % Plot the navigator with respiratory filtering
        function PlotRespirationNavigatorFcn(app)
            
            % clear the image
            cla(app.RespirationNavFig);
            hold(app.RespirationNavFig,'on');
            
            if app.filtered
                                
                % display x-range
                startpoint = app.StartEditField.Value;
                endpoint = app.StartEditField.Value+app.RangeEditField.Value-1;
                
                % extract part of the navigator that will be shown
                trace1 = app.nav_pc(startpoint:endpoint);
                trace2 = app.nav_resp(startpoint:endpoint);
                
                % scale the filtered signal for viewing purposes
                trace2 = 0.25*max(trace1)*trace2;
                                
                % plot respiratory window masks in transparent red
                maxplt = 1.1*max(trace1);
                minplt = 1.1*min(trace1);
                if app.stopnavmovie steps = 1; else steps = 4; end
                for i=startpoint:steps:endpoint
                    if app.resp_window(i)==1
                        win=line(app.RespirationNavFig,[i-app.StartEditField.Value+1;i-app.StartEditField.Value+1],[app.resp_window(i)*minplt;app.resp_window(i)*maxplt],'Color',app.red,'LineWidth',1);
                        win.Color(4) = 0.1*steps; % transparancy
                    end
                end
                
                % show the detected trigger points (only when movie is not running)
                if app.stopnavmovie
                    triggerpoints = app.rlocs((app.rlocs>=startpoint) & (app.rlocs<=endpoint));
                    if length(triggerpoints) < 10
                        for i = 1:length(triggerpoints)
                            win=plot(app.RespirationNavFig,[triggerpoints(i)-startpoint+1,triggerpoints(i)-startpoint+1],[minplt,maxplt],'black','LineWidth',1);
                            win.Color(4) = 0.2; % transparancy
                        end
                    end
                end
                
                % plot raw navigator
                plot(app.RespirationNavFig, trace1,'Color',app.blue);
                
                % plot navigator signal filtered for respiratory motion
                plot(app.RespirationNavFig, trace2,'LineWidth',1,'Color',app.red);
                
                % set plot axes
                xlim(app.RespirationNavFig, [0 length(trace1)]);
                ylim(app.RespirationNavFig, [minplt maxplt]);
                
            end
            
            hold(app.RespirationNavFig,'off');
            drawnow limitrate;
            
        end
        
        
        % Plot the time-heartrate and time-respirationrate plots
        function PlotRates(app,hr,hrf,resp,respf)
        
            % clear the images
            cla(app.HeartRateTimeFig);
            cla(app.RespirationRateTimeFig);
            
            if app.filtered
                
                % y-scale
                maxheart = round(2*app.heartrate);
                maxresp = round(2*app.resprate);
                maxheart(maxheart<100) = 100;
                maxresp(maxresp<50) = 50;
                
                % show the time-heartrate plot
                hold(app.HeartRateTimeFig,'on');
                plot(app.HeartRateTimeFig, app.TR*app.hlocs(1:end-1)'/1000, hr,'Color',app.blue);                 % heart rate
                plot(app.HeartRateTimeFig, app.TR*app.hlocs(1:end-1)'/1000, hrf,'LineWidth',2,'Color',app.red);   % trend line
                axis(app.HeartRateTimeFig,[0 app.TR*app.hlocs(end)/1000 0 maxheart]);
                hold(app.HeartRateTimeFig,'off');
                drawnow;
                                
                % show the time-respirationrate plot
                hold(app.RespirationRateTimeFig,'on');
                plot(app.RespirationRateTimeFig, app.TR*app.rlocs(1:end-1)'/1000,resp,'Color',app.blue);                % respiration rate
                plot(app.RespirationRateTimeFig, app.TR*app.rlocs(1:end-1)'/1000,respf,'LineWidth',2,'Color',app.red);  % trend line
                axis(app.RespirationRateTimeFig,[0 app.TR*app.rlocs(end)/1000 0 maxresp]);
                hold(app.RespirationRateTimeFig,'off');
                drawnow;
                
                % plot the dynamic or multi-slice windows
                app.PlotDynamicsWindows;
                app.PlotMultiSliceWindows;
                
            end
            
        end
                
        
        % Plot the separation into dynamics in the time-heart-rate and time-respiration-rate plots
        function PlotDynamicsWindows(app)
            
            % delete previous windows
            delete(app.dplot1);
            delete(app.dplot2);
            delete(app.dplot1txt);
            delete(app.dplot2txt);
            
            if app.DynamicsEditField.Value > 1
                
                % y-scale
                maxheart = round(2*app.heartrate);
                maxresp = round(2*app.resprate);
                maxheart(maxheart<100) = 100;
                maxresp(maxresp<50) = 50;
                
                hold(app.HeartRateTimeFig,'on');
                
                % show shaded areas in the time-heartrate plot
                heartrange = app.TR*app.hlocs(end-1)/1000;
                step = heartrange / app.DynamicsEditField.Value;
                pgon = polyshape();
                for i = 1:2:app.DynamicsEditField.Value
                    xstart = (i-1)*step;
                    xend = i*step;
                    pgon = union(pgon,polyshape([xstart xstart xend xend],[5 maxheart-5 maxheart-5 5]));
                end
                app.dplot1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');
                
                % add dynamic frame numbers
                if app.DynamicsEditField.Value < 15
                    idx = 1:app.DynamicsEditField.Value;
                    txtposx = (idx - 0.5 - app.DynamicsEditField.Value / 25)*step;
                    txtposy = 0.95*maxheart*ones(app.DynamicsEditField.Value,1)';
                    txtstr = strcat('d',num2str(idx(:)));
                    app.dplot1txt = text(app.HeartRateTimeFig,txtposx,txtposy,txtstr,'FontSize',10,'Color',app.blue);
                end
               
                hold(app.HeartRateTimeFig,'off');
                
                hold(app.RespirationRateTimeFig,'on');
                
                % show in the time-respirationrate plot
                heartrange = app.TR*app.rlocs(end-1)/1000;
                step = heartrange / app.DynamicsEditField.Value;
                pgon = polyshape();
                for i = 1:2:app.DynamicsEditField.Value
                    xstart = (i-1)*step;
                    xend = i*step;
                    pgon = union(pgon,polyshape([xstart xstart xend xend],[0.5 maxresp-0.5 maxresp-0.5 0.5]));
                end
                app.dplot2 = plot(app.RespirationRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');
                
                % add dynamic frame numbers
                if app.DynamicsEditField.Value < 15
                    idx = 1:app.DynamicsEditField.Value;
                    txtposx = (idx - 0.5 - app.DynamicsEditField.Value / 25)*step;
                    txtposy = 0.95*maxresp*ones(app.DynamicsEditField.Value,1)';
                    txtstr = strcat('d',num2str(idx(:)));
                    app.dplot2txt = text(app.RespirationRateTimeFig,txtposx,txtposy,txtstr,'FontSize',10,'Color',app.blue);
                end
                
                hold(app.RespirationRateTimeFig,'off');
                                
            end
            
        end
        
        
        % Plot the separation into slices for the time-heartrate and time-respiratiorate plots
        function PlotMultiSliceWindows(app)
        
            % delete previous windows
            delete(app.msplot1);
            delete(app.msplot2);
            delete(app.msplot1txt);
            delete(app.msplot2txt);
            
            if strcmp(app.datatype,'2Dms')
                
                % y-scale
                maxheart = round(2*app.heartrate);
                maxresp = round(2*app.resprate);
                maxheart(maxheart<100) = 100;
                maxresp(maxresp<50) = 50;
                
                hold(app.HeartRateTimeFig,'on');
                
                % show shaded areas in the time-heartrate plot
                heartrange = app.TR*app.hlocs(end-1)/1000;
                step = heartrange / app.NzViewField.Value;
                pgon = polyshape();
                for i = 1:2:app.NzViewField.Value
                    xstart = (i-1)*step;
                    xend = i*step;
                    pgon = union(pgon,polyshape([xstart xstart xend xend],[5 maxheart-5 maxheart-5 5]));
                end
                app.msplot1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');
                
                % add dynamic frame numbers
                if app.NzViewField.Value < 15
                    idx = 1:app.NzViewField.Value;
                    txtposx = (idx - 0.5 - app.NzViewField.Value / 50)*step;
                    txtposy = 0.95*maxheart*ones(app.NzViewField.Value,1)';
                    txtstr = strcat('s',num2str(idx(:)));
                    app.msplot1txt = text(app.HeartRateTimeFig,txtposx,txtposy,txtstr,'FontSize',10,'Color',app.blue);
                end
               
                hold(app.HeartRateTimeFig,'off');
                
                hold(app.RespirationRateTimeFig,'on');
                
                % show in the time-respirationrate plot
                heartrange = app.TR*app.rlocs(end-1)/1000;
                step = heartrange / app.NzViewField.Value;
                pgon = polyshape();
                for i = 1:2:app.NzViewField.Value
                    xstart = (i-1)*step;
                    xend = i*step;
                    pgon = union(pgon,polyshape([xstart xstart xend xend],[0.5 maxresp-0.5 maxresp-0.5 0.5]));
                end
                app.msplot2 = plot(app.RespirationRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');
                
                % add dynamic frame numbers
                if app.NzViewField.Value < 15
                    idx = 1:app.NzViewField.Value;
                    txtposx = (idx - 0.5 - app.NzViewField.Value / 50)*step;
                    txtposy = 0.95*maxresp*ones(app.NzViewField.Value,1)';
                    txtstr = strcat('s',num2str(idx(:)));
                    app.msplot2txt = text(app.RespirationRateTimeFig,txtposx,txtposy,txtstr,'FontSize',10,'Color',app.blue);
                end
                
                hold(app.RespirationRateTimeFig,'off');
                
            end
            
        end


        % Plot windows in time-heartrate and time-respirationrate plots which indicate the range of data shown in the navigator plots
        function PlotRateLimits(app)
        
            % delete previous windows
            delete(app.hrate_window);
            delete(app.rrate_window);
            
            % start and ending of the window
            xstart = app.StartEditField.Value*app.TR/1000;
            xend = xstart + app.RangeEditField.Value*app.TR/1000;
            
            % y-scale
            maxheart = round(2*app.heartrate);
            maxresp = round(2*app.resprate);
            maxheart(maxheart<100) = 100;
            maxresp(maxresp<50) = 50;
            
            % time-heartrate plot navigator inspection window
            hold(app.HeartRateTimeFig,'on');
            pgon = polyshape([xstart xstart xend xend],[5 maxheart-5 maxheart-5 5]);
            app.hrate_window = plot(app.HeartRateTimeFig, pgon,'FaceColor','black','FaceAlpha',0.25,'LineStyle','none');
            hold(app.HeartRateTimeFig,'off');
            
            % time-respirationrate plot navigator inspection window
            hold(app.RespirationRateTimeFig,'on');
            pgon = polyshape([xstart xstart xend xend],[0.5 maxresp-0.5 maxresp-0.5 0.5]);
            app.rrate_window = plot(app.RespirationRateTimeFig, pgon,'FaceColor','black','FaceAlpha',0.25,'LineStyle','none');
            hold(app.RespirationRateTimeFig,'off');
            
        end
        
        
        % Plot the data window in time-heartrate and time-respirationrate plots
        function PlotDataWindowFcn(app)
            
            % delete previous windows
            delete(app.dwin1);
            delete(app.dwin2);
            delete(app.dwin3);
            delete(app.dwin4);
            
            % y-scale
            maxheart = round(2*app.heartrate);
            maxresp = round(2*app.resprate);
            maxheart(maxheart<100) = 100;
            maxresp(maxresp<50) = 50;
           
            if app.IncludeCheckBox.Value == true
                
                % time-heartrate plot window
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([app.IncludeStartSlider.Value app.IncludeStartSlider.Value app.IncludeEndSlider.Value app.IncludeEndSlider.Value],[5 maxheart-5 maxheart-5 5]);
                app.dwin1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.brightgreen,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.HeartRateTimeFig,[0 app.TR*app.hlocs(end)/1000 0 maxheart]);
                hold(app.HeartRateTimeFig,'off');
                drawnow;
                
                % time-respirationrate plot window
                hold(app.RespirationRateTimeFig,'on');
                pgon = polyshape([app.IncludeStartSlider.Value app.IncludeStartSlider.Value app.IncludeEndSlider.Value app.IncludeEndSlider.Value],[0.5 maxresp-0.5 maxresp-0.5 0.5]);
                app.dwin2 = plot(app.RespirationRateTimeFig, pgon,'FaceColor',app.brightgreen,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.RespirationRateTimeFig,[0 app.TR*app.rlocs(end)/1000 0 maxresp]);
                hold(app.RespirationRateTimeFig,'off');
                drawnow;
                
            end
            
            if app.ExcludeCheckBox.Value == true
                
                % time-heartrate plot window
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([app.ExcludeStartSlider.Value app.ExcludeStartSlider.Value app.ExcludeEndSlider.Value app.ExcludeEndSlider.Value],[5 maxheart-5 maxheart-5 5]);
                app.dwin3 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.red,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.HeartRateTimeFig,[0 app.TR*app.hlocs(end)/1000 0 maxheart]);
                hold(app.HeartRateTimeFig,'off');
                drawnow;
                
                % time-respirationrate plot window
                hold(app.RespirationRateTimeFig,'on');
                pgon = polyshape([app.ExcludeStartSlider.Value app.ExcludeStartSlider.Value app.ExcludeEndSlider.Value app.ExcludeEndSlider.Value],[0.5 maxresp-0.5 maxresp-0.5 0.5]);
                app.dwin4 = plot(app.RespirationRateTimeFig, pgon,'FaceColor',app.red,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.RespirationRateTimeFig,[0 app.TR*app.rlocs(end)/1000 0 maxresp]);
                hold(app.RespirationRateTimeFig,'off');
                drawnow;
                
            end
            
            % set the acquisition parameters
            app.NrKlinesViewField.Value = nnz(app.includewindow.*app.excludewindow);
            app.RepetitionsViewField.Value = round(app.NrKlinesViewField.Value/(app.KlinesRepetitionViewField.Value*app.dimz));
            app.ScanTimeViewField.Value = datestr(seconds(app.NrKlinesViewField.Value * app.TR / 1000),'HH:MM:SS.FFF');
            if app.nr_dynamics>1
                app.TimeDynViewField.Value = datestr(seconds(app.acq_dur/app.DynamicsEditField.Value),'HH:MM:SS.FFF');
            else
                app.TimeDynViewField.Value = app.ScanTimeViewField.Value;
            end
            
        end
        
        
        % Plot the power spectrum of the raw navigator signal
        function PlotNavigatorSpectrumFcn(app)
            
            % limits
            startfreq = find(app.frequency>10,1);
            endfreq = find(app.frequency>800,1);

            if strcmp(app.loglinButton.Text,'lin')
                intensity = app.powerspectrum;
            else
                intensity = log(app.powerspectrum);
            end
            
            ymax = 1.3*max(intensity(startfreq:endfreq));
            ymin = -0.05*ymax;

            cla(app.SpectrumFig);
            hold(app.SpectrumFig,'on');
            xlim(app.SpectrumFig, [10 800]);
            ylim(app.SpectrumFig, [ymin, ymax+1]);
            plot(app.SpectrumFig,app.frequency,intensity,'Color',app.blue);
            
            app.heart_bandwidth = app.HeartWidthEditField.Value;
            app.resp_bandwidth = app.RespirationWidthEditField.Value;
            
            % plot the butter filter for cardiac motion filtering
            flt = filter_shape(app.TR,app.detectedHR,app.heart_bandwidth,app.FilterorderEditField.Value); % heart filter shape
            area(app.SpectrumFig,flt(:,1),0.95*ymax*flt(:,2),'FaceColor',app.brightgreen,'FaceAlpha',0.1,'EdgeColor',app.brightgreen,'LineStyle', 'none');
            plot(app.SpectrumFig,[app.detectedHR,app.detectedHR],[0,ymax],'Color',app.brightgreen,'LineWidth',1,'LineStyle','--');
            text(app.SpectrumFig,app.detectedHR+10,0.9*ymax,[num2str(app.detectedHR),' bpm']);
            
            % plot the butter filter for the repiration motion filtering
            try
                flt = filter_shape(app.TR,app.detectedRR,app.resp_bandwidth,app.FilterorderEditField.Value); % resp filter shape
                area(app.SpectrumFig,flt(:,1),0.95*ymax*flt(:,2),'FaceColor',app.brightgreen,'FaceAlpha',0.1,'EdgeColor',app.brightgreen,'LineStyle', 'none');
                plot(app.SpectrumFig,[app.detectedRR,app.detectedRR],[0,ymax],'Color',app.brightgreen,'LineWidth',1,'LineStyle','--');
                text(app.SpectrumFig,app.detectedRR+10,0.9*ymax,[num2str(app.detectedRR),' bpm']);
            catch
            end
            
            hold(app.SpectrumFig,'off');
            drawnow;
            
        end
        
        
        % Autoset level and window
        function AutoIntensity(app,images)
            
                scale = double(max(abs(images(:))));
                
                app.LevelScaleSlider.Value = 0;
                app.WindowScaleSlider.Value = 0;
                
                app.LevelScaleSlider.Limits = [0 scale];
                app.WindowScaleSlider.Limits = [0 scale];
                               
                app.level = 2*double(mean(abs(images((abs(images)>0.05*scale) & (abs(images)<0.95*scale)))));
                app.level(app.level>scale) = scale;
                if isnan(app.level) app.level = scale/2; end
                
                app.window = 1.8*app.level;
                app.window(app.window>scale) = scale;
                
                app.LevelScaleSlider.Value = app.level;
                app.WindowScaleSlider.Value = app.window;
            
        end 


        % Plot the current movie frame
        function PlotCurrentMovieFrameFcn(app)
           
            % MRI movie
            im = squeeze(app.movie_app(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
            [yl,xl] = size(im); % needs to be here, in case app.MovieButton changes during this routine
            delete(app.cbar);
            if app.movieButton.Value == 1
                app.movieplot = imshow(im,[app.level-app.window/2 app.level+app.window/2],'Parent',app.MovieFig);
            end
            
            % averages
            if app.averagesButton.Value == 1
                maxscale = round(max(app.averages(:)));
                c = [0 0 0 ; summer(maxscale-1)];
                im = squeeze(app.averages(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                [yl,xl] = size(im);
                app.movieplot = imshow(im,[0,maxscale],'ColorMap',c,'Parent',app.MovieFig);
                app.cbar = colorbar(app.MovieFig,'location','eastoutside','TickLabelsMode','auto','FontSize',12);
            end
            
            % raw k-space
            if app.kspaceButton.Value == 1
                im = squeeze(abs(app.kspace{app.MovieCoilSpinner.Value}(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value)));
                [yl,xl] = size(im);
                app.movieplot = imshow(im,[app.level-app.window/2 app.level+app.window/2],'Parent',app.MovieFig);
            end
            
            % sense map
            if (app.sensemapButton.Value == 1) && (app.sense_map_exists)
                im = squeeze(app.sense_map(:,:,app.MovieSliceEditField.Value,app.sensemapSpinner1.Value,app.sensemapSpinner2.Value));
                [yl,xl] = size(im);
                app.movieplot = imshow(im,[app.level-app.window/2 app.level+app.window/2],'Parent',app.MovieFig);
            end
            
            xlim(app.MovieFig, [0 xl+1]);
            ylim(app.MovieFig, [0 yl+1]);
            
            drawnow;
            
        end


        % Fast update the current movie frame
        function UpdateMovieFrameFcn(app)
           
            % MRI movie
            if app.movieButton.Value == 1
                im = squeeze(app.movie_app(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                set(app.movieplot, 'CData', im);
            end
            
            % averages
            if app.averagesButton.Value == 1
                im = squeeze(app.averages(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                set(app.movieplot, 'CData', im);
            end
            
            % raw k-space
            if app.kspaceButton.Value == 1
                im = squeeze(abs(app.kspace{app.MovieCoilSpinner.Value}(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value)));
                set(app.movieplot, 'CData', im);
            end
            
            % sense map
            if (app.sensemapButton.Value == 1) && (app.sense_map_exists)
                im = squeeze(abs(app.sense_map(:,:,app.MovieSliceEditField.Value,app.sensemapSpinner1.Value,app.sensemapSpinner2.Value)));
                set(app.movieplot, 'CData', im);
            end
            
            drawnow;
            
        end


        function PlotLcurveFig(app,lambda,x,y)
            
            % plot the L-curve and model-error curve
            if length(x)>1
       
                try
                    app.LcurveFig.XLim = [min(x) max(x)];
                    app.LcurveFig.YLim = [min(y) max(y)];
                    app.LcurveModelErrorFig.XLim = [app.Lambda1EditField.Value app.Lambda2EditField.Value];
                    app.LcurveModelErrorFig.YLim = [min(x) max(x)];
                catch
                end
                
                loglog(app.LcurveFig,x,y,'o','MarkerEdgeColor',[0 .5 .5],'MarkerFaceColor',app.blue,'LineWidth',1.5);
                loglog(app.LcurveModelErrorFig,lambda(1:length(x)),x,'o','MarkerEdgeColor',[0 .5 .5],'MarkerFaceColor',app.blue,'LineWidth',1.5);
                
            end
            
        end


    end % methods (Access = private)


    % ----------------------------------------------
    % Public functions, available outside the app
    % ----------------------------------------------


    methods (Access = public)
        
        
        % Text messages in the app
        function TextMessage(app, message)
            
            app.MessageBox.Value = [message ; app.MessageBox.Value];
            drawnow;
            
        end % TextMessage
        
        
        % Status icon
        function SetStatus(app,status)
            
            switch status
                
                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.statusLabel.Text = 'OK';
                    app.statusLabel.FontColor = app.green;
                    
                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.statusLabel.Text = 'Warning';
                    app.statusLabel.FontColor = app.orange;
                    
                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.statusLabel.Text = 'ERROR';
                    app.statusLabel.FontColor = app.brightred;
                    
            end
            
            drawnow;
            
        end


    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            
            % disable unnecessary warnings
            warning off;
            clc;
        
            % Start the parallel pool if it is not running already
            p = gcp('nocreate');
            if isempty(p)
                parpool(4);
            end
            
            % Empty file import and export paths
            app.mrd_import_path = '';
            app.mrd_flag = true;
            app.dicom_export_path = '';
            app.dicom_flag = true;
            app.gif_export_path = '';
            
            % Add subfolders to search path
            if (~isdeployed)
                app.olddir = pwd;
                info = dir(which('retrospective.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
            end
            
            % Load all icons & colors
            app.SetAppLayoutProperties;
            
            % Add the bart path to the environment variable if available
            try
                folder_name = fileread('bartpath.txt');
                setenv('TOOLBOX_PATH', folder_name);
            catch
            end

            try
                app.bart_detected = true;
                bart('version');
            catch
                app.bart_detected = false;
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end
            
            if app.bart_detected
                app.TextMessage('BART toolbox detected ...');
            end
            
            % set buttons
            app.LoadMRDButton.Enable = 'on';
            drawnow;
            
        end

        % Callback function: DataFile, LoadMRDButton
        function LoadMRDfileButtonPushed(app, event)
            
            % indicate busy
            app.SetLights(0);
            app.Lamp1.Color = app.yellow;
            app.BusyImage1.Visible = 'on';
            app.SwitchOffAllButtons;
            app.NavigatorFlipSwitch.Value = 'Up';
            drawnow;

            % check standard smis directory first if platform is pc, only 1x per session
            if ispc
                try
                    folder_name = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folder_name = [folder_name,filesep];
                    app.TextMessage('MRD directory found in Windows registry ...');
                    app.TextMessage(strcat('MRD directory =',{' '},folder_name));
                catch
                    app.TextMessage('Trying standard MRD directory ...');
                    folder_name = 'c:\smis\dev\MRD\';
                end
                if exist(folder_name, 'dir') && app.mrd_flag
                    app.mrd_import_path = folder_name;
                    app.mrd_flag = false;
                end
            end

            % check mrdpath.txt file next, only 1x per session
            if isfile('mrdpath.txt') && app.mrd_flag
                try
                    folder_name = fileread('mrdpath.txt');
                    if exist(folder_name, 'dir')
                        app.mrd_import_path = folder_name;
                        app.mrd_flag = false;
                    end
                catch
                    app.mrd_import_path = '';
                    app.mrd_flag = false;
                end
            end

            % select a DATA file
            app.RetrospectiveUIFigure.Visible = 'off';
            [mrdfile,app.mrd_import_path] = uigetfile(strcat(app.mrd_import_path,filesep,{'*.mrd;*.orig;*.job0'}));
            app.RetrospectiveUIFigure.Visible = 'on';

            app.valid_data = false;
            app.datatype = 'unknown';
            app.sense_map_exists = false;
            app.parameters.pixelshift1 = 0;
            app.parameters.pixelshift2 = 0;
          
            if ischar(mrdfile)

                % File selected
                app.datafilename = [app.mrd_import_path,mrdfile];

                if strcmp(mrdfile,"fid.orig") || strcmp(mrdfile,"rawdata.job0")

                    try

                        % Don't mention the B-word
                        app.rpr_flag = false;
                        app.data = [];
                        [app.data,app.parameters] = importb(app.mrd_import_path);
                        app.DataFile.Value = mrdfile;

                        app.nr_coils = app.parameters.no_coils;
                        if app.nr_coils > 1
                            app.multicoil = true;                           
                            app.TextMessage('Multi receiver coil data detected ...');
                        else
                            app.multicoil = false;
                        end

                        if app.parameters.NO_SLICES > 1
                            app.datatype = '2Dms';
                            app.multi2D = true;
                        else
                            app.datatype = '2D';
                            app.multi2D = false;
                        end

                        if app.parameters.NO_VIEWS_2 > 1
                            app.datatype = '3D';
                            app.TextMessage('INFO: 3D data detected ...');
                        end
                        
                        app.valid_data = true;
                        app.nr_nav = app.parameters.no_samples_nav;
                        app.NavigatorEndEditField.Value = app.nr_nav - 1;
                        app.NavigatorNRPointsEditField.Value = 5;

                    catch

                        app.valid_data = false;

                    end

                else

                    mrdstart = extractBefore(mrdfile,'_');
                    mrdfile = [app.mrd_import_path,mrdfile];
                    flist = dir(fullfile(app.mrd_import_path,[mrdstart,'*.MRD']));

                    % check wether the filelist contains already a reconstructed MRD file, remove this one from the list
                    if length(flist) > 1
                        for i = length(flist):-1:1
                            if contains(flist(i).name,'retro')
                                flist(i) = [];
                            end
                        end
                    end

                    if ~isempty(flist)

                        % filename tag
                        app.DataFile.Value = flist(1).name;
                        [~,app.tag,~] = fileparts([flist(1).folder,filesep,flist(1).name]);

                        % check for mulit-receiver-coil data
                        app.multicoil = false;
                        app.nr_coils = length(flist);
                        if app.nr_coils>1
                            app.multicoil = true;
                            app.parameters.coil_scaling = ones(app.nr_coils,1);
                            app.TextMessage('Multi receiver coil data detected ...');
                        end

                        % load the data
                        app.TextMessage('Loading k-space data ...');
                        app.data = {};
                        for i=1:app.nr_coils
                            app.TextMessage(strcat('Loading coil',{' '},num2str(i)));
                            [app.data{i},~,app.parameters] = Get_mrd_3D4(fullfile(flist(i).folder,flist(i).name),'seq','seq');
                        end

                        % read the full mrd-file footer
                        app.mrdfooter = readmrdfooter(fullfile(flist(1).folder,flist(1).name));

                        % read the rpr file
                        try
                            rprfilename = [flist(1).folder,filesep,app.tag,'.rpr'];
                            app.rpr = readrprfile(rprfilename);
                            app.rpr_flag = true;
                        catch
                            app.TextMessage('WARNING: rpr file not found ...');
                            app.SetStatus(1);
                            app.rpr_flag = false;
                        end

                        % check for radial data acquisition
                        if isfield(app.parameters,'radial_on')
                            if app.parameters.radial_on == 1
                                app.nr_nav = round(app.parameters.NO_SAMPLES/2);                % middle of k-space
                                app.NavigatorEndEditField.Value = app.nr_nav;
                                app.NavigatorNRPointsEditField.Value = 1;
                                app.datatype = '2Dradial';
                                app.valid_data = true;
                                if ndims(app.data{i}) == 3
                                    for i=1:app.nr_coils
                                        app.data{i} = permute(app.data{i},[1,4,2,3]);
                                    end
                                end
                            end
                        end

                        % check if the data is a valid file with a motion navigator acquisition
                        if isfield(app.parameters,'slice_nav') && isfield(app.parameters,'no_samples_nav') && (~strcmp(app.datatype,'2Dradial'))
                            if (app.parameters.slice_nav == 1) && (app.parameters.no_samples_nav > 0)
                                app.multi2D = false;
                                if app.parameters.NO_VIEWS_2 > 1
                                    app.datatype = '3D';                                        % 3D data
                                    app.TextMessage('3D k-space detected ...');
                                    for i=1:app.nr_coils
                                        if app.parameters.EXPERIMENT_ARRAY > 1
                                            app.data{i} = permute(app.data{i},[1,3,2,4]);
                                        else
                                            app.data{i} = permute(app.data{i},[4,2,1,3]);
                                        end
                                    end
                                else
                                    app.datatype = '2D';                                        % 2D single-slice data
                                    if ndims(app.data{i}) == 3
                                        for i=1:app.nr_coils
                                            app.data{i} = permute(app.data{i},[1,4,2,3]);
                                        end
                                    end
                                    if isfield(app.parameters,'NO_SLICES')
                                        if app.parameters.NO_SLICES > 1
                                            app.datatype = '2Dms';                              % 2D multi-slice data
                                            app.multi2D = true;
                                            app.TextMessage('2D multi-slice data detected ...');
                                        end
                                    end
                                end
                                app.nr_nav = app.parameters.no_samples_nav;                     % initial number of navigator points
                                app.NavigatorEndEditField.Value = app.nr_nav;
                                app.NavigatorNRPointsEditField.Value = 1;
                                app.valid_data = true;
                            else
                                app.TextMessage('ERROR: Data contains no navigator ...');
                                app.valid_data = false;
                                app.SetStatus(2);
                            end
                        end
                    end
                end

                % check if there are sufficient number of experiments
                if isfield(app.parameters,'EXPERIMENT_ARRAY') && isfield(app.parameters,'NO_VIEWS') && isfield(app.parameters,'NO_VIEWS_2') && isfield(app.parameters,'NO_SLICES')
                    if app.parameters.EXPERIMENT_ARRAY*app.parameters.NO_VIEWS*app.parameters.NO_VIEWS_2*app.parameters.NO_SLICES < 1024
                        app.valid_data = false;
                        app.TextMessage('ERROR: Not enough k-lines for reconstruction ...');
                        app.SetStatus(2);
                    end
                end
   
                % check if number of averages = 1
                if isfield(app.parameters,'NO_AVERAGES')
                    if app.parameters.NO_AVERAGES > 1
                        app.valid_data = false;
                        app.TextMessage('ERROR: Number of averages should be 1 ...');
                        app.SetStatus(2);
                    end
                end

                % 3D multi-slab data is not supported
                if strcmp(app.datatype,'3D')
                    if isfield(app.parameters,'NO_SLICES')
                        if app.parameters.NO_SLICES > 1
                            app.valid_data = false;
                            app.TextMessage('ERROR: Only 3D single-slab data supported ...');
                            app.SetStatus(2);
                        end
                    end
                end

            end

            if app.valid_data

                % initialize
                DataParametersFcn(app);                                         % determine all data parameters
                app.SetSpecies;                                                 % set initial filtering values for mouse/rat
                NavPointEditFieldValueChanged(app, event);                      % extract navigator

                % reset buttons
                app.SetLights(1);
                app.BusyImage1.Visible = 'off';
                app.SetDashboardButtons(131);
                app.ProgressGauge.Value = 0;
                app.stopmovie = true;
                app.NavSelButtons(true);
                app.SetStatus(0);
              
            else

                % not a valid MRD file with navigator
                app.TextMessage('ERROR: Please choose a valid data file ...');
                app.SetStatus(2);
                app.mrd_import_path = '';
                app.LoadMRDButton.Enable = 'on';
                app.ExitButton.Enable = 'on';
                app.Lamp1.Color = app.brightred;
                app.BusyImage1.Visible = 'off';
                app.LcurveStartButton.Enable = 'off';
                app.ProgressGauge.Value = 0;
                app.SetStatus(2);
                
            end
            
        end

        % Value changed function: DiscardEditField, 
        % NavigatorEndEditField, NavigatorFlipSwitch, 
        % NavigatorNRPointsEditField
        function NavPointEditFieldValueChanged(app, event)
            
            % update the used navigator point and the number of navigator points used
            if app.NavigatorNRPointsEditField.Value > app.NavigatorEndEditField.Value
                app.NavigatorNRPointsEditField.Value = app.NavigatorEndEditField.Value;
            end
            app.nr_nav = app.NavigatorEndEditField.Value;
            app.nav_points_used = app.NavigatorNRPointsEditField.Value;
            
            % update the data parameters
            app.DataParametersFcn;
            
            % switch the data-window off
            app.IncludeCheckBox.Value = false;
            app.PlotDataWindowFcn;
            
            % determine the value of the switch to flip the navigator
            switch app.NavigatorFlipSwitch.Value
                
                case 'Up'
                    pos_min = 1;
                    
                case 'Down'
                    pos_min = -1;
                    
            end
                        
            % extract the navigator signals
            app.nav_amplitude = {};
            for i = 1:app.nr_coils
                
                switch app.datatype
                    
                    case '2D'
                        app.nav_amplitude{i} = extractnavigator_PCA(app.data{i},app.nr_nav,app.nav_points_used,pos_min);
                        
                    case {'3D','3Dp2roud'}
                        app.nav_amplitude{i} = extractnavigator3D_PCA(app.data{i},app.nr_nav,app.nav_points_used,pos_min);
                        
                    case '2Dms'
                        app.nav_amplitude{i} = extractnavigator2Dms_PCA(app.data{i},app.nr_nav,app.nav_points_used,pos_min);
                        
                    case '2Dradial'
                        [app.nav_amplitude{i},app.nav_phase{i}] = extractnavigatorRadial_PCA(app.data{i},app.nr_nav,app.nav_points_used,pos_min);
                        
                end
                
            end

            % plot the raw navigator signal
            app.PlotRawNavigatorFcn;
            
            % determine and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;
            
            % plot or clear the other navigator graphs
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            cla(app.HeartRateTimeFig);
            cla(app.RespirationRateTimeFig);
            
            % reset buttons
            app.SetLights(1);
            app.SwitchOffAllButtons;
            app.SetDashboardButtons(131);
            app.NavSelButtons(true);
            app.ProgressGauge.Value = 0;
            
        end

        % Value changed function: CoilSpinner
        function CoilSpinnerValueChanged(app, event)
            
            % plot the raw navigator signal
            app.PlotRawNavigatorFcn;
            
            % show the samples-navigator plots
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            
        end

        % Value changed function: HeartEditField, 
        % HeartWidthEditField, RespirationEditField, 
        % RespirationWidthEditField, RespirationWindowEditField
        function FilterEditFieldValueChanged(app, event)
            
            app.filtered = false;
        
            if app.HeartWidthEditField.Value > app.HeartEditField.Value
                app.HeartWidthEditField.Value = app.HeartEditField.Value;
            end
            
            if app.RespirationWidthEditField.Value > app.RespirationEditField.Value
                app.RespirationWidthEditField.Value = app.RespirationEditField.Value;
            end
            
            app.detectedHR = app.HeartEditField.Value;
            app.heart_bandwidth = app.HeartWidthEditField.Value;
            app.detectedRR = app.RespirationEditField.Value;
            app.resp_bandwidth = app.RespirationWidthEditField.Value;
            app.resp_percentage = app.RespirationWindowEditField.Value;
            
            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;
            
        end

        % Button pushed function: ResetFilterValuesButton
        function ResetFilterValuesButtonPushed(app, event)
            
            % reset filter values
            app.filtered = false;
            app.SetSpecies;
            app.CalculateNavigatorSpectrumFcn;
            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;
            
        end

        % Selection changed function: SpeciesButton
        function SpeciesButtonSelectionChanged(app, event)
            
            app.MouseButton2.Value = app.MouseButton.Value;
            app.RatButton2.Value = app.RatButton.Value;
            app.ResetFilterValuesButtonPushed;
            
        end

        % Selection changed function: SpeciesButton2
        function SpeciesButton2SelectionChanged(app, event)
            
            app.MouseButton.Value = app.MouseButton2.Value;
            app.RatButton.Value = app.RatButton2.Value;
            app.ResetFilterValuesButtonPushed;
            
        end

        % Button down function: SpeciesButton, SpeciesButton2
        function SpeciesButtonButtonDown(app, event)
            
            app.ResetFilterValuesButtonPushed;
            
        end

        % Selection changed function: RecoPresetsButtonGroup
        function RecoPresetsButtonGroupSelectionChanged(app, event)
            
            % Settings for scout acquisition (e.g. 3 orthogonal slices)
            if app.ScoutButton.Value == 1
                
                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 1;
                app.DynamicsEditField.Value = 1;
                app.WVxyzEditField.Value = 0.000;
                app.TVxyzEditField.Value = 0.005;
                app.LRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.000;
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                app.CLEARCheckBox.Value = 0;
                
            end
            
            % Settings for systolic function, typical number of CINE frames = 12
            if app.SystolicFunctionButton.Value == 1
                
                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 12;
                app.DynamicsEditField.Value = 1;
                app.FrameSharingEditField.Value = 1;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                app.LRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.100;
                app.TVdynEditField.Value = 0.000;                                                                                      
                app.ESPIRiTCheckBox.Value = 0;
                app.CLEARCheckBox.Value = 0;
                
            end
            
            % Settings for diastolic function, typical number of CINE frames = 32
            if app.DiastolicFunctionButton.Value == 1
                
                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 32;
                app.DynamicsEditField.Value = 1;
                app.FrameSharingEditField.Value = 1;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                app.LRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.100;
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                app.CLEARCheckBox.Value = 0;
                
            end
            
            % For reconstruction of respiratory motion
            if app.RespiratoryFunctionButton.Value == 1
                
                app.RecoTypeDropDown.Value = 'respiratory';
                app.FramesEditField.Value = 60;
                app.DynamicsEditField.Value = 1;
                app.FrameSharingEditField.Value = 1;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                app.LRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.100;
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                app.CLEARCheckBox.Value = 0;
                
            end
            
            % For 'realtime' reconstruction of X frames / heartbeat
            if app.RealTimeFunctionButton.Value == 1
                
                app.RecoTypeDropDown.Value = 'realtime';
                app.FramesEditField.Value = 2;
                app.DynamicsEditField.Value = 1;
                app.FrameSharingEditField.Value = 3;
                app.WVxyzEditField.Value = 0.000;
                app.TVxyzEditField.Value = 0.000;
                app.LRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.001;
                app.TVdynEditField.Value = 0.200;
                app.ESPIRiTCheckBox.Value = 0;
                app.CLEARCheckBox.Value = 0;
                
            end
            
            % Update the reonstruction parameters
            CinePanelValueChanged(app, event);
            
            % If the data was already filtered, resort the data immediately
            if app.filtered
                SortKspaceButtonPushed(app, event);
            end
            
            
        end

        % Button down function: RecoPresetsButtonGroup
        function RecoPresetsButtonGroupButtonDown(app, event)
            
            RecoPresetsButtonGroupSelectionChanged(app, event);
            
        end

        % Value changed function: FilterorderEditField, 
        % MaxheartrateEditField, MaxresprateEditField, 
        % MinheartrateEditField, MinresprateEditField
        function FilterSettingsChanged(app, event)
            
            if app.MinheartrateEditField.Value < 100 
                app.MinheartrateEditField.Value = 100; 
            end
            
            if app.MinheartrateEditField.Value > 1000 
                app.MinheartrateEditField.Value = 1000; 
            end
            
            if app.MaxheartrateEditField.Value < 100
                app.MaxheartrateEditField.Value = 100;
            end
            
            if app.MaxheartrateEditField.Value > 1000
                app.MaxheartrateEditField.Value = 1000;
            end
            
            if app.MinresprateEditField.Value < 5
                app.MinresprateEditField.Value = 5;
            end
            if app.MinresprateEditField.Value > 400
                app.MinresprateEditField.Value = 400;
            end
            
            if app.MaxresprateEditField.Value < 5
                app.MaxresprateEditField.Value = 5;
            end
           
            if app.MaxresprateEditField.Value > 400
                app.MaxresprateEditField.Value = 400;
            end
            
            if app.MinresprateEditField.Value > app.MaxresprateEditField.Value
                app.MinresprateEditField.Value = app.MaxresprateEditField.Value;
            end
            
            if app.MinheartrateEditField.Value > app.MaxheartrateEditField.Value
                app.MinheartrateEditField.Value = app.MaxheartrateEditField.Value;
            end
            
            app.CalculateNavigatorSpectrumFcn;
            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;
            
        end

        % Button pushed function: loglinButton
        function loglinButtonPushed(app, event)
            
            if strcmp(app.loglinButton.Text,'log')
                app.loglinButton.Text = 'lin';
            else
                app.loglinButton.Text = 'log';
            end
            
            app.PlotNavigatorSpectrumFcn;
            
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            
            % indicate busy
            app.SetLights(1);
            app.Lamp2.Color = app.yellow;
            app.BusyImage2.Visible = 'on';
            app.SwitchOffAllButtons;

            app.TextMessage('Filtering the navigator ...');

            try

                % Let the app know the nav data is filtered
                app.filtered = true;

                % filter for cardiac and respiratory motion
                app.physiofiltersettings = [app.MinheartrateEditField.Value app.MaxheartrateEditField.Value app.MinresprateEditField.Value app.MaxresprateEditField.Value, app.FilterorderEditField.Value];
                [app.nav_heart,app.nav_resp,app.nav_pc] = filter_nav_PCA(app,app.nav_amplitude,app.nr_coils,app.TR,app.detectedHR,app.heart_bandwidth,app.detectedRR,app.resp_bandwidth,app.physiofiltersettings);

                % extract cardiac trigger points
                [app.hpks,app.hlocs] = trigpoints_cardiac(app.nav_heart,app.TR,app.detectedHR,app.splinefactor);
                hr = calc_heartrate(app.hlocs,app.TR);
                hrf = movmedian(hr,32); % smooth trendline

                % determine the mean cardiac rate
                includedata = app.includewindow.*app.excludewindow;                             % data-window which is included
                includedata = round(resample(includedata,4*length(hr),length(includedata)));    % resample the data-window to nr samples heartrate
                includedata = round(resample(includedata,length(hr),length(includedata)));      % in 2 steps, to prevent an overflow error
                app.heartrate = median(nonzeros(includedata.*hr));                              % take the median of the heartrate
                app.FinalHeartViewField.Value = round(app.heartrate);                           % display the heartrate next to the movie

                % extract respiratory trigger points
                [app.rpks,app.rlocs]=trigpoints_resp(app.nav_resp,app.TR,app.detectedRR,app.splinefactor);
                resp = calc_resprate(app.rlocs,app.TR);
                respf = movmedian(resp,32);     % smooth trendline
                app.resp_window = respiratory_window(app.rlocs,mean(respf),app.resp_percentage,app.TR,app.nr_klines);

                % determine the mean respiration rate
                includedata = app.includewindow.*app.excludewindow;                             % data-window which is included
                includedata = round(resample(includedata,4*length(resp),length(includedata)));  % resample the data-window to nr samples respiration rate
                includedata = round(resample(includedata,length(resp),length(includedata)));    % in 2 steps, to prevent an overflow error
                app.resprate = median(nonzeros(includedata.*resp));                             % take the median of the respirationrate
                app.FinalRespirationViewField.Value = round(app.resprate);                      % display respiration rate next to the movie

                % Plot the datawindow in the time-heartrate and time-respirationrate plots and datawindow
                PlotRates(app,hr,hrf,resp,respf)
                app.PlotRateLimits;
                app.PlotDataWindowFcn;

                % show the samples-navigator plots
                app.PlotCardiacNavigatorFcn;
                app.PlotRespirationNavigatorFcn;

                app.SetStatus(0);

            catch

                app.filtered = false;
                app.TextMessage('ERROR: Something went wrong during filtering of the navigator ...')
                app.SetStatus(2);

            end

            % reset buttons
            app.SetLights(3);
            app.BusyImage2.Visible = 'off';
            app.SetDashboardButtons(135);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;
            app.ProgressGauge.Value = 0;
            app.LcurveStartButton.Enable = 'off';

        end

        % Value changed function: RangeEditField, StartEditField
        function DisplayEditFieldValuesChanged(app, event)
            
            value1 = app.StartEditField.Value;
            value2 = app.RangeEditField.Value;
            
            % Range should be at least 20
            value2(value2<20) = 20;
            
            % Range should not exceed 2000, else plot gets really slow and cluttered
            value2(value2>2000) = 2000;
            
            % Start + Range should not exceed the number of available k-lines
            if value1 + value2 > app.nr_klines
                value1 = app.nr_klines - value2;
            end
            
            % Report the values back to the user-interface
            app.StartEditField.Value = value1;
            app.RangeEditField.Value = value2;
            
            % Adjust the sliders also
            app.StartSlider.Value = app.StartEditField.Value;
            app.RangeSlider.Value = app.RangeEditField.Value;
            
            % Plot the raw navigator, cardiac filtered, and respiration filtered
            app.PlotRawNavigatorFcn;
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            app.PlotRateLimits;
            
        end

        % Value changed function: RangeSlider, StartSlider
        function DisplaySlidersValueChanged(app, event)
            
            % Set the field values to match the sliders
            app.StartEditField.Value = round(app.StartSlider.Value);
            app.RangeEditField.Value = round(app.RangeSlider.Value);
            
            % Proceed as if the field values were changed
            app.DisplayEditFieldValuesChanged;
            
        end

        % Button pushed function: NavStartButton
        function NavStartButtonPushed(app, event)
            
            app.stopnavmovie = false;
            
            while ~app.stopnavmovie
                
                value1 = app.StartEditField.Value;
                value2 = app.RangeEditField.Value;
                
                value1 = value1 + app.navmoviespeed;
                
                if value1 + value2 > app.nr_klines
                    value1 = 1;
                end
                
                app.StartEditField.Value = value1;
                app.StartSlider.Value = value1;
                
                app.PlotRawNavigatorFcn;
                app.PlotCardiacNavigatorFcn;
                app.PlotRespirationNavigatorFcn;
                app.PlotRateLimits;
                
            end
            
        end

        % Button pushed function: NavStopButton
        function NavStopButtonPushed(app, event)
            
            app.stopnavmovie = true;
            
            % Plot the raw navigator, cardiac filtered, and respiration filtered
            app.PlotRawNavigatorFcn;
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            app.PlotRateLimits;
            
        end

        % Value changed function: NavFastSlowSwitch
        function NavFastSlowSwitchValueChanged(app, event)
            
            if app.NavFastSlowSwitch.Value == "slow" 
                app.navmoviespeed = 25;
            else
                app.navmoviespeed = 100;
            end
            
        end

        % Value changed function: IncludeCheckBox
        function IncludeCheckBoxValueChanged(app, event)
            
            % Include window check-box on or off
            if app.IncludeCheckBox.Value == 1
                
                app.IncludeSliderValueChanged;
                
            else
                
                app.includewindow = ones(app.nr_klines,1);
                app.PlotDataWindowFcn;
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;
                
                % Reset buttons
                app.SetLights(1);
                app.SwitchOffAllButtons;
                app.SetDashboardButtons(131);
                app.NavSelButtons(true);
                app.NavViewingButtons(true);
                app.stopmovie = true;
                app.SetStatus(0);
                drawnow;
                
            end
            
        end

        % Value changed function: IncludeEndSlider, 
        % IncludeStartSlider
        function IncludeSliderValueChanged(app, event)
            
            % Read the sliders
            value1 = app.IncludeStartSlider.Value;      % start of the include-window in units of seconds
            value2 = app.IncludeEndSlider.Value;        % end of the include-window in units of seconds
             
            % End should be bigger than Start and length of the window should be at least 10 (arbitrary) repetitions
            if value2-value1 < round(10 * app.nr_ksteps * app.TR / 1000)
                value2 = value1 + round(10 * app.nr_ksteps * app.TR / 1000);
            end
            
            % End should be smaller than total acquisition time and length of the window should be at least 10 (arbitrary) repetitions
            if value2 > app.acq_dur
                value2 = app.acq_dur;
                value1 = value2 - (10 * app.nr_ksteps * app.TR / 1000);
            end
                        
            % set the values and sliders
            app.IncludeStartSlider.Value = value1;
            app.IncludeEndSlider.Value = value2;
                        
            % set the value of the include window
            app.includewindow = zeros(app.nr_klines,1);
            value1k = round(1000*value1/app.TR)+1; % start of the window in nr k-lines
            value2k = round(1000*value2/app.TR); % end of the window in nr k-lines
            if value2k > app.nr_klines 
                value2k = app.nr_klines; 
            end
            app.includewindow(value1k:value2k) = 1;
                        
            % Plot the datawindow in the time-heartrate and time-respirationrate plots
            app.PlotDataWindowFcn;
            
            % Calculate and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;
            
            % Reset buttons
            app.SetLights(1);
            app.SwitchOffAllButtons;
            app.SetDashboardButtons(131);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.stopmovie = true;
            drawnow;
            
            % Check whether there is sufficient data left to do a reconstruction
            amountofklines = nnz(app.includewindow.*app.excludewindow);
            if amountofklines < 512
                app.TextMessage('ERROR: Not enough k-lines left for reconstruction, adjust data windows ...');
                app.FilterButton.Enable = 'off';
                app.SetStatus(2);
            else
                app.SetStatus(0);
            end
            
        end

        % Value changed function: ExcludeCheckBox
        function ExcludeCheckBoxValueChanged(app, event)
            
            % Exclude window check-box on or off
            if app.ExcludeCheckBox.Value == 1
                
                app.ExcludeSliderValueChanged;
                
            else
                
                app.excludewindow = ones(app.nr_klines,1);
                app.PlotDataWindowFcn;
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;
                
                % Reset buttons
                app.SetLights(1);
                app.SwitchOffAllButtons;
                app.SetDashboardButtons(131);
                app.NavSelButtons(true);
                app.NavViewingButtons(true);
                app.stopmovie = true;
                app.SetStatus(0);
                drawnow;
                
            end
            
        end

        % Value changed function: ExcludeEndSlider, 
        % ExcludeStartSlider
        function ExcludeSliderValueChanged(app, event)
            
            % Read the sliders
            value1 = app.ExcludeStartSlider.Value;      % start of the exclude-window in units of seconds
            value2 = app.ExcludeEndSlider.Value;        % end of the exclude-window in units of seconds
             
            % End should be bigger than Start and length of the window should be at least 10 (arbitrary) repetitions
            if value2-value1 < round(10 * app.nr_ksteps * app.TR / 1000)
                value2 = value1 + round(10 * app.nr_ksteps * app.TR / 1000);
            end
            
            % End should be smaller than total acquisition time and length of the window should be at least 10 (arbitrary) repetitions
            if value2 > app.acq_dur
                value2 = app.acq_dur;
                value1 = value2 - (10 * app.nr_ksteps * app.TR / 1000);
            end
                        
            % set the values and sliders
            app.ExcludeStartSlider.Value = value1;
            app.ExcludeEndSlider.Value = value2;
                        
            % set the value of the exclude window
            app.excludewindow = ones(app.nr_klines,1);
            value1k = round(1000*value1/app.TR)+1; % start of the window in nr k-lines
            value2k = round(1000*value2/app.TR); % end of the window in nr k-lines
            if value2k > app.nr_klines 
                value2k = app.nr_klines; 
            end
            app.excludewindow(value1k:value2k) = 0;
                        
            % Plot the datawindow in the time-heartrate and time-respirationrate plots
            app.PlotDataWindowFcn;
            
            % Calculate and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;
            
            % Reset buttons
            app.SetLights(1);
            app.SwitchOffAllButtons;
            app.SetDashboardButtons(131);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.stopmovie = true;
            drawnow;    
            
            % Check whether there is sufficient data left to do a reconstruction
            amountofklines = nnz(app.includewindow.*app.excludewindow);
            if amountofklines < 512
                app.TextMessage('ERROR: Not enough k-lines left for reconstruction, adjust data windows ...');
                app.FilterButton.Enable = 'off';
                app.SetStatus(2);
            else
                app.SetStatus(0);
            end
            
        end

        % Value changed function: DynamicsEditField, 
        % FramesEditField, RecoTypeDropDown
        function CinePanelValueChanged(app, event)
            
            % If running, stop the movie
            StopButtonPushed(app, event);
            
            % New number of frames
            app.nr_frames = app.FramesEditField.Value;
            app.nr_dynamics = app.DynamicsEditField.Value;
            
            if app.nr_dynamics > 1
                app.TimeDynViewField.Value = datestr(seconds(app.acq_dur/app.DynamicsEditField.Value),'HH:MM:SS.FFF');
                app.IncludeCheckBox.Value = 0;
                app.IncludeCheckBoxValueChanged;
                app.ExcludeCheckBox.Value = 0;
                app.ExcludeCheckBoxValueChanged;
            else
                app.TimeDynViewField.Value = app.ScanTimeViewField.Value;
            end
            
            % Plot the dynamic windows in the rate plots
            app.PlotDynamicsWindows;
            
            % Reset buttons
            app.SetLights(3);
            app.SwitchOffAllButtons;
            app.SetDashboardButtons(135);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;
            app.ProgressGauge.Value = 0;
            drawnow;
            
        end

        % Button pushed function: SortKspaceButton
        function SortKspaceButtonPushed(app, event)
            
            % Indicate busy
            app.SetLights(3);
            app.Lamp3.Color = app.yellow;
            app.BusyImage3.Visible = 'on';
            app.SwitchOffAllButtons;
            
            app.TextMessage('Sorting ...');
            
            % Extract all the raw k-space imaging data
            raw = {};
            
            switch app.datatype
                
                case {'2D','2Dms','3D','3Dp2roud'}
                    for i = 1:app.nr_coils
                        raw{i} = extractdata(app.data{i},app.nr_nav + app.nav_discarded);
                    end
             
                case '2Dradial'
                    for i = 1:app.nr_coils
                        raw{i} = app.data{i};
                    end
                    
            end
             
            % Sort by cardiac phase binning, cardiac absolute binning, or respiratory phase binning
            switch app.RecoTypeDropDown.Value
     
                case 'cardiac'
                    number_of_cardiac_frames = app.nr_frames;
                    number_of_respiratory_frames = 1;
                    number_of_dynamics = app.nr_dynamics;
                    number_of_shared_frames = 1;
                    app.FrameSharingEditField.Value = 1;
                    [bin_times_card,bin_times_resp] = assign_bin_times(app.hlocs,number_of_cardiac_frames,app.rlocs,number_of_respiratory_frames);
                    [card_bin_ass,resp_bin_ass] = assign_bin_frames(bin_times_card,bin_times_resp,app.resp_window,app.nr_klines,number_of_cardiac_frames,number_of_respiratory_frames);

                case 'cardiac abs'
                    number_of_cardiac_frames = app.nr_frames;
                    number_of_respiratory_frames = 1;
                    number_of_dynamics = app.nr_dynamics;
                    number_of_shared_frames = 1;
                    app.FrameSharingEditField.Value = 1;
                    [bin_times_card,bin_times_resp] = assign_bin_times_abs(app.hlocs,number_of_cardiac_frames,app.rlocs,number_of_respiratory_frames,app.heartrate,app.TR);
                    [card_bin_ass,resp_bin_ass] = assign_bin_frames_abs(bin_times_card,bin_times_resp,app.resp_window,app.nr_klines,number_of_respiratory_frames);

                case 'respiratory'
                    number_of_cardiac_frames = 1;
                    number_of_respiratory_frames = app.nr_frames;
                    number_of_dynamics = app.nr_dynamics;
                    number_of_shared_frames = 1;
                    app.FrameSharingEditField.Value = 1;
                    [bin_times_card,bin_times_resp] = assign_bin_times(app.hlocs,number_of_cardiac_frames,app.rlocs,number_of_respiratory_frames);
                    [card_bin_ass,resp_bin_ass] = assign_bin_frames(bin_times_card,bin_times_resp,app.resp_window,app.nr_klines,number_of_cardiac_frames,number_of_respiratory_frames);

                case 'realtime'
                    number_of_cardiac_frames = app.nr_frames;
                    number_of_respiratory_frames = 1;
                    number_of_dynamics = 1; % will be determined later depending on the number of heartbeats
                    number_of_shared_frames = app.FrameSharingEditField.Value;
                    [bin_times_card,bin_times_resp] = assign_bin_times(app.hlocs,number_of_cardiac_frames,app.rlocs,number_of_respiratory_frames);
                    [card_bin_ass,resp_bin_ass] = assign_bin_frames(bin_times_card,bin_times_resp,app.resp_window,app.nr_klines,number_of_cardiac_frames,number_of_respiratory_frames);
            
            end
            
            % Trajectory (linear, zigzag, user-defined, radial)
            switch app.traject_type
                
                case 0
                    trajectory = linear_trajectory(app.nr_ksteps);
                    
                case 2
                    trajectory = zigzag_trajectory(app.nr_ksteps);
                    
                case 3
                    trajectory = array_trajectory(app.parameters);
                    
                case 4 % reserved for 3D P2ROUD acquisition
                    flist = dir(fullfile(app.mrd_import_path,'*.txt'));
                    if ~isempty(flist)
                        trajectory = load([flist(1).folder,filesep,flist(1).name]);
                        app.datatype = '3Dp2roud';
                        app.TextMessage(strcat('P2ROUD trajectory: ',{' '},flist(1).name));
                    else
                        app.datatype = '3D';
                        trajectory = linear_trajectory(app.nr_ksteps);
                        app.TextMessage('WARNING: P2ROUD trajectory file not found, assuming linear k-space filling ...');
                        app.SetStatus(1);
                    end
                    
                case 8 % reserved for radial
                    trajectory = radial_trajectory(app.nr_ksteps);
                    
            end
            
            % Sort k-space
            app.kspace = {};
            includedata = app.includewindow.*app.excludewindow; % combine green include and red exclude windows
            
            for i = 1:app.nr_coils
                app.TextMessage(strcat('k-space coil',{' '},num2str(i)));
                
                switch app.datatype
                    
                    case {'2D','2Dms'}
                        if strcmp(app.RecoTypeDropDown.Value,'realtime')
                            [app.kspace{i},app.averages,number_of_dynamics] = fill_kspace2D_RT(raw{i},number_of_cardiac_frames,number_of_respiratory_frames,number_of_shared_frames,app.dimz,app.dimy,app.nr_ksteps,app.dimx,bin_times_card,trajectory);
                            app.nr_dynamics = number_of_dynamics;
                            app.DynamicsEditField.Value = number_of_dynamics;
                            app.TimeDynViewField.Value = datestr(seconds(app.acq_dur/app.DynamicsEditField.Value),'HH:MM:SS.FFF');
                        else
                            [app.kspace{i},app.averages] = fill_kspace2D(raw{i},includedata,number_of_cardiac_frames,number_of_respiratory_frames,number_of_dynamics,app.dimz,app.dimy,app.nr_ksteps,app.dimx,card_bin_ass,resp_bin_ass,trajectory);
                        end
                        
                    case '3D'
                        [app.kspace{i},app.averages] = fill_kspace3D(raw{i},includedata,number_of_cardiac_frames,number_of_respiratory_frames,number_of_dynamics,app.dimz,app.dimy,app.nr_ksteps,app.dimx,card_bin_ass,resp_bin_ass,trajectory,app.parameters);
                        
                    case '3Dp2roud'
                        [app.kspace{i},app.averages] = fill_kspace3Dp2roud(raw{i},includedata,number_of_cardiac_frames,number_of_respiratory_frames,number_of_dynamics,app.dimz,app.dimy,app.nr_ksteps,app.dimx,card_bin_ass,resp_bin_ass,trajectory);
                        
                    case '2Dradial'
                        [app.kspace{i},app.averages] = fill_kspace2Dradial(raw{i},includedata,number_of_cardiac_frames,number_of_respiratory_frames,number_of_dynamics,app.dimz,app.dimy,app.nr_ksteps,app.dimx,card_bin_ass,resp_bin_ass,trajectory,app.nav_phase{i});
                        
                end
                
            end
            
            % Reshape to either cardiac or respiratory CINE
            [s1,s2,s3,s4,s5,s6] = size(app.averages);
            s = max([s1,s2]);
            for i = 1:app.nr_coils
                app.kspace{i} = reshape(app.kspace{i},[s,s3,s4,s5,s6]);
                app.kspace{i} = permute(app.kspace{i},[1,3,4,2,5]);
            end
            app.averages = reshape(app.averages,[s,s3,s4,s5,s6]);
            app.averages = permute(app.averages,[1,3,4,2,5]);
                      
            % K-space statistics
            app.FillingViewField.Value = round(100*nnz(app.averages)/numel(app.averages));
            if app.FillingViewField.Value < 10 
                app.TextMessage('WARNING: Low k-space filling, decrease number of frames or dynamics ...');
                app.SetStatus(1);
            end
            
            app.AveragesViewField.Value = mean2(app.averages);
            app.parameters.NO_AVERAGES = round(app.AveragesViewField.Value);
                        
            % Reset buttons
            app.SetLights(7);
            app.BusyImage3.Visible = 'off';
            app.SetDashboardButtons(143);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;
            app.ProgressGauge.Value = 0;
            app.LcurveStartButton.Enable = 'on';
            app.SetStatus(0);
            drawnow;
            
            % Warning in case of large dataset
            if app.FramesEditField.Value*app.DynamicsEditField.Value > 2000
                app.SetStatus(1);
                app.TextMessage('WARNING: large dataset, reconstruction may take a very long time ...');
            end
            
            
        end

        % Value changed function: TVcineEditField, TVxyzEditField, 
        % WVxyzEditField
        function RecoParEditFieldValueChanged(app, event)
            
            % Reset buttons
            app.SetLights(7);
            app.SwitchOffAllButtons;
            app.SetDashboardButtons(143);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;
            app.ProgressGauge.Value = 0;
            drawnow;
            
        end

        % Button pushed function: LcurveStartButton
        function LcurveStartButtonPushed(app, event)
            
            % Indicate busy
            app.TextMessage('L-curve calculation in progress ...');
            app.stopmovie = true;
            app.stopLcurve = false;
            app.LcurveStartButton.Enable = 'off';
            app.LcurveStopButton.Enable = 'on';
            buttoncode = app.GetDashboardButtons;
            app.SwitchOffAllButtons;
            
            if app.bart_detected==true && (strcmp(app.datatype,'2D')==true || strcmp(app.datatype,'2Dms')==true)
                
                % Range of lamda's
                lambda = logspace(log10(app.Lambda1EditField.Value),log10(app.Lambda2EditField.Value),app.LambdaStepsEditField.Value);
                lambda = round(lambda,3);
               
                % Determine which parameters will be optimized
                par = [0 0 0 0 0];
                if app.LcurveWVxyzCheckBox.Value == 1  par(1) = 1; end
                if app.LcurveTVxyzCheckBox.Value == 1  par(2) = 1; end
                if app.LcurveLRxyzCheckBox.Value == 1  par(3) = 1; end
                if app.LcurveTVcineCheckBox.Value == 1 par(4) = 1; end
                if app.LcurveTVdynCheckBox.Value == 1  par(5) = 1; end
                                
                % Intialize progress gauge
                totalnumberofsteps = app.LambdaIterationsEditField.Value * sum(par) * length(lambda);
                app.ProgressGauge.Value = 0;
                cnt = 0;
                               
                % Iterations
                iter = 0;
                while (iter<app.LambdaIterationsEditField.Value) && (app.stopLcurve==false)
                    iter = iter + 1;
               
                    
                    % Wavelet xyz
                    if par(1)==1
                        
                        % Reset graph
                        cla(app.LcurveFig);
                        cla(app.LcurveModelErrorFig);
                        app.LcurveFig.YLabel.String = "|| WVxyz x_{\lambda} ||_1";
                        drawnow;
                        
                        x = [];
                        y = [];
                        
                        i = 0;
                        while (i<length(lambda)) && (app.stopLcurve==false)
                            i = i + 1;
                            
                            % Wavelet
                            app.WVxyzEditField.Value = lambda(i);
                            app.TextMessage(strcat('L-curve, WVxyz, iteration=',num2str(iter),', lambda=',num2str(lambda(i))));
                            
                            % CS reco
                            [Lmovie,~] = cs_reco2D_mc(app,app.kspace,app.nr_coils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.LRxyzEditField.Value,app.TVcineEditField.Value,app.TVdynEditField.Value,app.ESPIRiTCheckBox.Value,app.CLEARCheckBox.Value,0);
                            
                            % L1 norm wavelet
                            WVxyz = bart('cdf97 6',Lmovie);
                            y(i) = norm(WVxyz(:),1);
                            
                            % L2 norm
                            kspaceim = bart('fft -u -i 6',Lmovie);
                            kspaceim = bart(['resize -c 1 ',num2str(size(app.kspace{1},2)),' 2 ',num2str(size(app.kspace{1},3))],kspaceim);
                            x(i) = 0;
                            for j = 1:app.nr_coils
                                mask = app.kspace{j} ~= 0;
                                kspacediff = abs(kspaceim(:,:,:,:,:,j).*mask) - abs(app.kspace{j});
                                x(i) = x(i) + norm(kspacediff(:),2);
                            end
                                                        
                            % Plot the data
                            app.PlotLcurveFig(lambda,x,y);
                            
                            % update counter
                            cnt = cnt + 1;
                            app.ProgressGauge.Value = round(100*cnt/totalnumberofsteps);
                            
                        end
                        
                        % Analyze the L-curve and determine optimal value
                        if ~app.stopLcurve
                            app.LcurveanalysisFcn(lambda,x,y,1);
                        end
                        
                    end
                    
                    
                    % Total Variation spatial domain
                    if par(2)==1
                        
                        % Reset graph
                        cla(app.LcurveFig);
                        cla(app.LcurveModelErrorFig);
                        app.LcurveFig.YLabel.String = "|| TVxyz x_{\lambda} ||_1";
                        drawnow;
                        
                        x = [];
                        y = [];
                        
                        i = 0;
                        while (i<length(lambda)) && (app.stopLcurve==false)
                            i = i + 1;
                            
                            % Total variation
                            app.TVxyzEditField.Value = lambda(i);
                            app.TextMessage(strcat('L-curve, TVxyz, iteration=',num2str(iter),', lambda=',num2str(lambda(i))));
                            
                            % CS reco
                            [Lmovie,~] = cs_reco2D_mc(app,app.kspace,app.nr_coils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.LRxyzEditField.Value,app.TVcineEditField.Value,app.TVdynEditField.Value,app.ESPIRiTCheckBox.Value,app.CLEARCheckBox.Value,0);
                           
                            % L1 norm TV in spatial dimension
                            for dim1=1:size(Lmovie,1)
                                for dim4=1:size(Lmovie,4)
                                    for dim5=1:size(Lmovie,5)
                                        for dim6=1:size(Lmovie,6)
                                            [Gx,Gy] = gradient(squeeze(Lmovie(dim1,:,:,dim4,dim5,dim6)));
                                            TVxyz(dim1,:,:,dim4,dim5,dim6) = sqrt(Gx.^2 + Gy.^2);
                                        end
                                    end
                                end
                            end
                            y(i) = norm(TVxyz(:),1);
                            
                            % L2 norm
                            kspaceim = bart('fft -u -i 6',Lmovie);
                            kspaceim = bart(['resize -c 1 ',num2str(size(app.kspace{1},2)),' 2 ',num2str(size(app.kspace{1},3))],kspaceim);
                            x(i) = 0;
                            for j = 1:app.nr_coils
                                mask = app.kspace{j} ~= 0;
                                kspacediff = abs(kspaceim(:,:,:,:,:,j).*mask) - abs(app.kspace{j});
                                x(i) = x(i) + norm(kspacediff(:),2);
                            end
                            
                            % Plot the data
                            app.PlotLcurveFig(lambda,x,y);
                            
                            % update counter
                            cnt = cnt + 1;
                            app.ProgressGauge.Value = round(100*cnt/totalnumberofsteps);
                            
                        end
                        
                        % Analyze the L-curve and determine optimal value
                        if ~app.stopLcurve
                            app.LcurveanalysisFcn(lambda,x,y,2);
                        end
                    
                    end
                    
                    
                    % Low-Rank in spatial domain
                    if par(3)==1
                        
                        % Reset graph
                        cla(app.LcurveFig);
                        cla(app.LcurveModelErrorFig);
                        app.LcurveFig.YLabel.String = "|| LRxyz x_{\lambda} ||_1";
                        drawnow;
                        
                        x = [];
                        y = [];
                        
                        i = 0;
                        while (i<length(lambda)) && (app.stopLcurve==false)
                            i = i + 1;
                            
                            % Total variation
                            app.LRxyzEditField.Value = lambda(i);
                            app.TextMessage(strcat('L-curve, LRxyz, iteration=',num2str(iter),', lambda=',num2str(lambda(i))));
                            
                            % CS reco
                            [Lmovie,~] = cs_reco2D_mc(app,app.kspace,app.nr_coils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.LRxyzEditField.Value,app.TVcineEditField.Value,app.TVdynEditField.Value,app.ESPIRiTCheckBox.Value,app.CLEARCheckBox.Value,0);
                           
                            % L1 norm TV in spatial dimension
                            for dim1=1:size(Lmovie,1)
                                for dim4=1:size(Lmovie,4)
                                    for dim5=1:size(Lmovie,5)
                                        for dim6=1:size(Lmovie,6)
                                            [Gx,Gy] = gradient(squeeze(Lmovie(dim1,:,:,dim4,dim5,dim6)));
                                            TVxyz(dim1,:,:,dim4,dim5,dim6) = sqrt(Gx.^2 + Gy.^2);
                                        end
                                    end
                                end
                            end
                            y(i) = norm(TVxyz(:),1);
                            
                            % L2 norm
                            kspaceim = bart('fft -u -i 6',Lmovie);
                            kspaceim = bart(['resize -c 1 ',num2str(size(app.kspace{1},2)),' 2 ',num2str(size(app.kspace{1},3))],kspaceim);
                            x(i) = 0;
                            for j = 1:app.nr_coils
                                mask = app.kspace{j} ~= 0;
                                kspacediff = abs(kspaceim(:,:,:,:,:,j).*mask) - abs(app.kspace{j});
                                x(i) = x(i) + norm(kspacediff(:),2);
                            end
                            
                            % Plot the data
                            app.PlotLcurveFig(lambda,x,y);
                            
                            % update counter
                            cnt = cnt + 1;
                            app.ProgressGauge.Value = round(100*cnt/totalnumberofsteps);
                            
                        end
                        
                        % Analyze the L-curve and determine optimal value
                        if ~app.stopLcurve
                            app.LcurveanalysisFcn(lambda,x,y,3);
                        end
                    
                    end
                    
                    
                    % Total Variation CINE
                    if par(4)==1
                        
                        % Reset graph
                        cla(app.LcurveFig);
                        cla(app.LcurveModelErrorFig);
                        app.LcurveFig.YLabel.String = "|| TVcine x_{\lambda} ||_1";
                        drawnow;
                        
                        x = [];
                        y = [];
                        
                        i = 0;
                        while (i<length(lambda)) && (app.stopLcurve==false)
                            i = i + 1;
                            
                            % Total variation
                            app.TVcineEditField.Value = lambda(i);
                            app.TextMessage(strcat('L-curve, TVcine, iteration=',num2str(iter),', lambda=',num2str(lambda(i))));
                            
                            % CS reco
                            [Lmovie,~] = cs_reco2D_mc(app,app.kspace,app.nr_coils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.LRxyzEditField.Value,app.TVcineEditField.Value,app.TVdynEditField.Value,app.ESPIRiTCheckBox.Value,app.CLEARCheckBox.Value,0);
                           
                            % L1 norm TV
                            TVcine = circshift(Lmovie,1) - Lmovie;
                            y(i) = norm(TVcine(:),1);
                            
                            % L2 norm
                            kspaceim = bart('fft -u -i 6',Lmovie);
                            kspaceim = bart(['resize -c 1 ',num2str(size(app.kspace{1},2)),' 2 ',num2str(size(app.kspace{1},3))],kspaceim);
                            x(i) = 0;
                            for j = 1:app.nr_coils
                                mask = app.kspace{j} ~= 0;
                                kspacediff = abs(kspaceim(:,:,:,:,:,j).*mask) - abs(app.kspace{j});
                                x(i) = x(i) + norm(kspacediff(:),2);
                            end
                            
                            % Plot the data
                            app.PlotLcurveFig(lambda,x,y);
                            
                            % update counter
                            cnt = cnt + 1;
                            app.ProgressGauge.Value = round(100*cnt/totalnumberofsteps);
                            
                        end
                        
                        % Analyze the L-curve and determine optimal value
                        if ~app.stopLcurve
                            app.LcurveanalysisFcn(lambda,x,y,4);
                        end
                    
                    end
                    
                    
                    % Total Variation dynamic dimension
                    if par(5)==1
                        
                        % Reset graph
                        cla(app.LcurveFig);
                        cla(app.LcurveModelErrorFig);
                        app.LcurveFig.YLabel.String = "|| TVdyn x_{\lambda} ||_1";
                        drawnow;
                        
                        x = [];
                        y = [];
                        
                        i = 0;
                        while (i<length(lambda)) && (app.stopLcurve==false)
                            i = i + 1;
                            
                            % Total variation
                            app.TVdynEditField.Value = lambda(i);
                            app.TextMessage(strcat('L-curve, TVdyn, iteration=',num2str(iter),', lambda=',num2str(lambda(i))));
                            
                            % CS reco
                            [Lmovie,~] = cs_reco2D_mc(app,app.kspace,app.nr_coils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.LRxyzEditField.Value,app.TVcineEditField.Value,app.TVdynEditField.Value,app.ESPIRiTCheckBox.Value,app.CLEARCheckBox.Value,0);
                            
                            % L1 norm TV in dynamic dimension
                            TVtime = circshift(Lmovie,5) - Lmovie;
                            y(i) = norm(TVtime(:),1);
                            
                            % L2 norm
                            kspaceim = bart('fft -u -i 6',Lmovie);
                            kspaceim = bart(['resize -c 1 ',num2str(size(app.kspace{1},2)),' 2 ',num2str(size(app.kspace{1},3))],kspaceim);
                            x(i) = 0;
                            for j = 1:app.nr_coils
                                mask = app.kspace{j} ~= 0;
                                kspacediff = abs(kspaceim(:,:,:,:,:,j).*mask) - abs(app.kspace{j});
                                x(i) = x(i) + norm(kspacediff(:),2);
                            end
                            
                            % Plot the data
                            app.PlotLcurveFig(lambda,x,y);
                            
                            % update counter
                            cnt = cnt + 1;
                            app.ProgressGauge.Value = round(100*cnt/totalnumberofsteps);
                            
                        end
                        
                        % Analyze the L-curve and determine optimal value
                        if ~app.stopLcurve
                            app.LcurveanalysisFcn(lambda,x,y,5);
                        end
                    
                    end
                    
                    
                end
                
            else
                
                app.TextMessage('ERROR: L-curve calculations require Bart toolbox and Cartesian 2D data...');
                app.SetStatus(2);
                drawnow;
                
            end
           
            % Stopped before completion
            if app.stopLcurve
                app.TextMessage('L-curve calculation stopped by user ...');
            end
            
            % Reset buttons
            app.LcurveStartButton.Enable = 'on';
            app.LcurveStopButton.Enable = 'off';
            app.SetDashboardButtons(buttoncode);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            if app.valid_reco app.MovieButtons(true); end
            
        end

        % Button pushed function: LcurveStopButton
        function LcurveStopButtonPushed(app, event)
            
            app.stopLcurve = true;
            drawnow;
            
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            
            % Indicate busy
            app.SetLights(7);
            app.Lamp4.Color = app.yellow;
            app.BusyImage4.Visible = 'on';
            app.SwitchOffAllButtons;
            app.TabGroup4.SelectedTab = app.MovieTab;
            
            % For testing purpose
            % app.bart_detected = false;
            
            % Some parameters
            app.sense_map_exists = false;
            app.valid_reco = false;
            app.slicenr = 1;
            app.dynamicnr = 1;
            app.MovieSliceEditField.Value = 1;
            app.MovieFrameEditField.Value = 1;
            app.MovieDynEditField.Value = 1;
            app.MovieCheckBox1.Value = 1;
            app.MovieCheckBox2.Value = 0;
            app.MovieCheckBox3.Value = 0;
            app.multislice = false;
            app.multidynamic = false;
            
            % Reconstruction
            switch app.datatype
                
                
                case {'2D','2Dms'}
                    
                    app.valid_reco = true;
                    
                    try
                        
                        if app.bart_detected
                            
                            % perform CS reconstruction with the Bart toolbox, preferred option
                            app.TextMessage('Reconstructing the data with the BART toolbox ...');
                            app.ProgressGauge.Value = 0;
                            app.SetStatus(0);
                            drawnow;
                            [app.movie_exp,app.sense_map] = cs_reco2D_mc(app,app.kspace,app.nr_coils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.LRxyzEditField.Value,app.TVcineEditField.Value,app.TVdynEditField.Value,app.ESPIRiTCheckBox.Value,app.CLEARCheckBox.Value,1,app.parameters);
                            app.ProgressGauge.Value = 100;
                            app.sense_map_exists = true;
                            
                        else
                            
                            % perform CS reconstruction in matlab, slow but works
                            app.TextMessage('WARNING: Reconstructing the data with MATLAB ...');
                            app.TextMessage('Slow reconstruction, please be patient ...');
                            app.ProgressGauge.Value = 0;
                            app.SetStatus(1);
                            drawnow;
                            app.movie_exp = cs_reco2D_mat_mc(app,app.kspace,app.nr_coils,app.averages,app.TVcineEditField.Value,app.parameters);
                            
                        end
                        
                    catch
                        
                        app.valid_reco = false;
                        app.sense_map_exists = false;
                        
                    end
                    
                    
                case {'3D','3Dp2roud'}
                    
                    app.slicenr = round(app.dimz/2);
                    app.MovieSliceEditField.Value = app.slicenr;
                    
                    app.valid_reco = true;
                    
                    try
                        
                        if app.bart_detected
                            
                            % perform CS reconstruction with the Bart toolbox, preferred option
                            app.TextMessage('Reconstructing the 3D data with the BART toolbox ...');
                            app.ProgressGauge.Value = 0;
                            app.SetStatus(0);
                            drawnow;
                            [app.movie_exp,app.sense_map] = cs_reco3D_mc(app,app.kspace,app.nr_coils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.LRxyzEditField.Value,app.TVcineEditField.Value,app.TVdynEditField.Value,app.ESPIRiTCheckBox.Value,app.CLEARCheckBox.Value);
                            app.ProgressGauge.Value = 100;
                            drawnow;
                            app.sense_map_exists = true;
                            
                        else
                            
                            % perform cs reconstruction in matlab, slow but works
                            app.TextMessage('WARNING: Reconstructing the data with MATLAB ...');
                            app.TextMessage('Slow reconstruction, please be patient ...');
                            app.ProgressGauge.Value = 0;
                            app.SetStatus(1);
                            drawnow;
                            app.movie_exp = cs_reco3D_mat_mc(app,app.kspace,app.nr_coils,app.averages,app.TVcineEditField.Value);
                            
                        end
                        
                    catch
                        
                        app.valid_reco = false;
                        app.sense_map_exists = false;
                        
                    end
                    
                    
                case '2Dradial'
                    
                    try
                        
                        if app.bart_detected
                            
                            app.TextMessage('Reconstructing 2D radial data with the BART toolbox ...');
                            app.ProgressGauge.Value = 0;
                            app.SetStatus(0);
                            drawnow;
                            [app.movie_exp,app.sense_map] = cs_reco2D_radial_mc(app,app.kspace,app.dimx,app.dimy,app.nr_ksteps,app.nr_coils,app.WVxyzEditField.Value,app.TVcineEditField.Value,app.TVxyzEditField.Value);
                            app.ProgressGauge.Value = 100;
                            drawnow;
                            app.sense_map_exists = true;
                            app.valid_reco = true;
                            app.slicenr = 1;
                            app.MovieSliceEditField.Value = 1;
                            app.MovieFrameEditField.Value = 1;
                            
                        else
                            
                            app.TextMessage('ERROR: Reconstruction of radial data requires the BART toolbox ...');
                            app.ProgressGauge.Value = 0;
                            app.SetStatus(2);
                            app.valid_reco = false;
                            drawnow;
                            
                        end
                        
                    catch
                        
                        app.valid_reco = false;
                        app.sense_map_exists = false;
                        
                    end
                    
                    
            end
            
            
            % Successful reco or not
            if app.valid_reco
                
                % Normalize image intensity somewhat
                app.movie_exp = norm_images(app.movie_exp);
                app.movie_app = app.movie_exp;
                
                if size(app.movie_app,4) > 1 app.multislice = true; else app.multislice = false; end
                if size(app.movie_app,5) > 1 app.multidynamic = true; else app.multidynamic = false; end
                app.nr_slices = size(app.movie_app,4);
                app.MovieSliceEditField.Value = round(app.nr_slices/2);
           
                % Phase_orientation correction for viewing
                if app.parameters.PHASE_ORIENTATION
                    app.TextMessage('INFO: phase orientation = 1 / horizontal ... ')
                    app.movie_app = permute(rot90(permute(app.movie_app,[2,3,4,1,5]),1),[4,1,2,3,5]);
                    app.movie_app = flip(app.movie_app,3);
                    app.sense_map = rot90(app.sense_map,1);
                    app.sense_map = flip(app.sense_map,2);
                else
                    app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                end
                
                % Reset buttons
                app.SetLights(15);
                app.BusyImage4.Visible = 'off';
                app.SetDashboardButtons(255);
                app.NavSelButtons(true);
                app.NavViewingButtons(true);
                app.CineRecoButtons(true);
                app.MovieButtons(true);
                app.SetStatus(0);
                drawnow;
                
                % Start the movie
                app.movieButton.Value = 1;
                app.ButtonGroupSelectionChanged;
                if app.nr_frames > 1
                    app.StartButtonPushed;
                end
                
            else
                
                % Reset buttons
                app.SetLights(7);
                app.BusyImage4.Visible = 'off';
                app.SwitchOffAllButtons;
                app.SetDashboardButtons(135);
                app.NavSelButtons(true);
                app.NavViewingButtons(true);
                app.CineRecoButtons(true);
                app.stopmovie = true;
                app.ProgressGauge.Value = 0;
                app.TextMessage('ERROR: There was a problem with reconstruction ...');
                app.SetStatus(2);
                drawnow;
                
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % Movie is running
            app.stopmovie = false;
            app.TextMessage('Movie playing ...');
            
            % Buttons
            app.MovieButtons(true);
            app.ExitButton.Enable = 'off';
                       
            app.MovieFig.Position = [154,15,280,280];
                        
            counter1 = app.MovieFrameEditField.Value;
            counter2 = app.slicenr;
            counter3 = app.dynamicnr;
                        
            % Show the movie frame
            app.ButtonGroupSelectionChanged;
            
            % Play the movie continuously until app.stopmovie==true
            while app.stopmovie == false
                
                % time
                tic
                
                % Calculate movie speed
                if strcmp(app.RecoTypeDropDown.Value,'realtime')
                    mintimeperframe = 1/(app.MovieCheckBox1.Value*app.nr_frames + app.MovieCheckBox2.Value*app.nr_slices + app.MovieCheckBox3.Value*app.nr_dynamics);
                else
                    mintimeperframe = 1/(app.MovieCheckBox1.Value*app.nr_frames);
                    if app.MovieCheckBox1.Value == 0
                        mintimeperframe = 1/(app.MovieCheckBox2.Value*app.nr_slices+app.MovieCheckBox3.Value*app.nr_dynamics);
                    end
                end
                 
                % Show the movie frame
                app.UpdateMovieFrameFcn;
                               
                chk = false;
               
                if app.MovieCheckBox1.Value == 1
                    counter1 = counter1 + 1;
                    chk = true;
                    if counter1 > app.nr_frames
                        counter1 = 1;
                        chk = false;
                    end
                    app.MovieFrameEditField.Value = counter1;
                end
             
                if (app.MovieCheckBox2.Value == 1) && (chk == false)
                    counter2 = counter2 + 1;
                    chk = true;
                    if counter2 > app.dimz
                        counter2 = 1;
                        chk = false;
                    end
                    app.MovieSliceEditField.Value = counter2;
                    app.slicenr = counter2;
                end
                
                if (app.MovieCheckBox3.Value == 1) && (chk == false)
                    counter3 = counter3 + 1;
                    chk = true;
                    if counter3 > app.nr_dynamics
                        counter3 = 1;
                        chk = false;
                    end
                    app.MovieDynEditField.Value = counter3;
                    app.dynamicnr = counter3;
                end
                
                % slow movie a bit down with small number of frames
                mvtime = toc;
                
                if mvtime<mintimeperframe
                    pause(mintimeperframe-mvtime);
                end
                
            end
            
            app.PlotCurrentMovieFrameFcn;
            drawnow;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            % Stop the movie
            app.stopmovie = true;
            app.TextMessage('Stopping the movie ...');
            
            % Reset buttons
            app.MovieButtons(true);
            app.ExitButton.Enable = 'on';
            drawnow;
            
        end

        % Value changed function: MovieFrameEditField
        function MovieFrameEditFieldValueChanged(app, event)
            
            value = app.MovieFrameEditField.Value;
            
            % If running, stop the movie
            app.StopButtonPushed;
            
            % Check if input value <= number of cardiac frames
            if value > app.nr_frames
                app.MovieFrameEditField.Value = app.nr_frames;
            end
            
            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
        end

        % Value changed function: MovieSliceEditField
        function MovieSliceEditFieldValueChanged(app, event)
            
            value = app.MovieSliceEditField.Value;
            
            if value > app.nr_slices
                app.MovieSliceEditField.Value = app.nr_slices;
            end
            
            app.slicenr = app.MovieSliceEditField.Value;
            
            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
        end

        % Value changed function: MovieDynEditField
        function MovieDynEditFieldValueChanged(app, event)
            
            value = app.MovieDynEditField.Value;
            
            if value > app.nr_dynamics
                app.MovieDynEditField.Value = app.nr_dynamics;
            end
            
            app.dynamicnr = app.MovieDynEditField.Value;
            
            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
        end

        % Value changed function: MovieCheckBox1, MovieCheckBox2, 
        % MovieCheckBox3
        function MovieCheckBoxValueChanged(app, event)
            
            if (app.MovieCheckBox1.Value == 0) && (app.MovieCheckBox2.Value == 0) && (app.MovieCheckBox3.Value == 0)
                app.MovieCheckBox1.Value = 1;
            end
            
        end

        % Value changed function: WindowScaleSlider
        function WindowScaleSliderValueChanged(app, event)
            
            value = app.WindowScaleSlider.Value;
            app.window = value;
            app.PlotCurrentMovieFrameFcn;
            
        end

        % Value changed function: LevelScaleSlider
        function LevelScaleSliderValueChanged(app, event)
            
            value = app.LevelScaleSlider.Value;
            app.level = value;
            app.PlotCurrentMovieFrameFcn;
            
        end

        % Button pushed function: autoButton
        function AutoButtonPushed(app, event)
            
             % MRI movie
            if app.movieButton.Value == 1
                app.AutoIntensity(app.movie_app);
            end
            
            % averages
            if app.averagesButton.Value == 1
                app.AutoIntensity(app.averages);
            end
            
            % raw k-space
            if app.kspaceButton.Value == 1
                app.AutoIntensity(app.kspace{app.MovieCoilSpinner.Value});
            end
            
            % sense map
            if (app.sensemapButton.Value == 1) && (app.sense_map_exists)
                app.AutoIntensity(app.sense_map);
            end
            
            app.PlotCurrentMovieFrameFcn;
            drawnow;
            
        end

        % Value changed function: MovieCoilSpinner, 
        % sensemapSpinner1, sensemapSpinner2
        function MovieCoilSpinnerValueChanged(app, event)
            
            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
        end

        % Button pushed function: cwButton
        function cwButtonPushed(app, event)
            
            % Rotate movie clock-wise
            app.movie_app = permute(rot90(permute(app.movie_app,[2,3,1,4,5]),-1),[3,1,2,4,5]);
            app.sense_map = rot90(app.sense_map,-1);
                        
            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;
            
        end

        % Button pushed function: ccwButton
        function ccwButtonPushed(app, event)
            
            % rotate movie counter-clock-wise
            app.movie_app = permute(rot90(permute(app.movie_app,[2,3,1,4,5])),[3,1,2,4,5]);
            app.sense_map = rot90(app.sense_map,1);
            
            % show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;
        
        end

        % Button pushed function: MovieFrameIncrButton
        function MovieFrameIncrButtonPushed(app, event)
            
            % Shift movie +1 frame along the time/frame axis
           
            switch app.datatype
                
                case {'2D','3D','2Dradial','3Dp2roud'}
                    
                    app.movie_exp = circshift(app.movie_exp,-1,1);
                    app.movie_app = circshift(app.movie_app,-1,1);
                    for i = 1:app.nr_coils
                        app.kspace{i} = circshift(app.kspace{i},-1,1);
                    end
                    app.averages = circshift(app.averages,-1,1);
                    
                case '2Dms'
                    
                    % Shift for the CS movie slice
                    shifted_movie_slice = circshift(app.movie_exp(:,:,:,app.slicenr),-1,1);
                    app.movie_exp(:,:,:,app.slicenr) = shifted_movie_slice;
                    
                    % Shift for the movie slice
                    shifted_movie_slice = circshift(app.movie_app(:,:,:,app.slicenr),-1,1);
                    app.movie_app(:,:,:,app.slicenr) = shifted_movie_slice;
                    
                    % Shift k-space movie for all coils
                    for i = 1:app.nr_coils
                        shifted_movie_slice = circshift(app.kspace{i}(:,:,:,app.slicenr),-1,1);
                        app.kspace{i}(:,:,:,app.slicenr) = shifted_movie_slice;
                    end
                    
                    % Shift the averages movie
                    shifted_movie_slice = circshift(app.averages(:,:,:,app.slicenr),-1,1);
                    app.averages(:,:,:,app.slicenr) = shifted_movie_slice;
                    
            end
                        
            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;
        
        end

        % Button pushed function: MovieFrameDecrButton
        function MovieFrameDecrButtonPushed(app, event)
            
            % Shift movie -1 frame along the time/frame axis
            
            switch app.datatype
                
                case {'2D','3D','2Dradial','3Dp2roud'}
                    
                    app.movie_exp = circshift(app.movie_exp,1,1);
                    app.movie_app = circshift(app.movie_app,1,1);
                    for i = 1:app.nr_coils
                        app.kspace{i} = circshift(app.kspace{i},1,1);
                    end
                    app.averages = circshift(app.averages,1,1);
                    
                case '2Dms'
                    
                    % Shift for the CS movie slice
                    shifted_movie_slice = circshift(app.movie_exp(:,:,:,app.slicenr),1,1);
                    app.movie_exp(:,:,:,app.slicenr) = shifted_movie_slice;
                    
                    % Shift for the movie slice
                    shifted_movie_slice = circshift(app.movie_app(:,:,:,app.slicenr),1,1);
                    app.movie_app(:,:,:,app.slicenr) = shifted_movie_slice;
                    
                    % Shift k-space movie for all coils
                    for i = 1:app.nr_coils
                        shifted_movie_slice = circshift(app.kspace{i}(:,:,:,app.slicenr),1,1);
                        app.kspace{i}(:,:,:,app.slicenr) = shifted_movie_slice;
                    end
                    
                    % Shift the averages movie
                    shifted_movie_slice = circshift(app.averages(:,:,:,app.slicenr),1,1);
                    app.averages(:,:,:,app.slicenr) = shifted_movie_slice;
                    
            end
            
            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;
            
        end

        % Button pushed function: MovieSliceIncrButton
        function MovieSliceIncrButtonPushed(app, event)
            
            % Increment the slice number by 1
            app.slicenr = app.slicenr + 1;

            if app.kspaceButton.Value == 1 || app.averagesButton.Value == 1
                if app.slicenr > size(app.data{1},2)
                    app.slicenr = 1;
                end
            else
                if app.slicenr > app.nr_slices
                    app.slicenr = 1;
                end
            end
            
            app.MovieSliceEditField.Value = app.slicenr;
            
            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
        end

        % Button pushed function: MovieSliceDecrButton
        function MovieSliceDecrButtonPushed(app, event)
            
            % Decrease the slice number by 1
            app.slicenr = app.slicenr - 1;
            
            if app.kspaceButton.Value == 1 || app.averagesButton.Value == 1
                if app.slicenr < 1
                    app.slicenr = size(app.data{1},2);
                end
            else
                if app.slicenr < 1
                    app.slicenr = app.nr_slices;
                end
            end
            
            app.MovieSliceEditField.Value = app.slicenr;
            
            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
        end

        % Button pushed function: MovieDynIncrButton
        function MovieDynIncrButtonPushed(app, event)
            
            % Increment the dynamic number by 1
            app.dynamicnr = app.dynamicnr + 1;
            if app.dynamicnr > app.nr_dynamics
                app.dynamicnr = 1;
            end
            app.MovieDynEditField.Value = app.dynamicnr;
            
            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
        end

        % Button pushed function: MovieDynDecrButton
        function MovieDynDecrButtonPushed(app, event)
            
            % Decrease the dynamic number by 1
            app.dynamicnr = app.dynamicnr - 1;
            if app.dynamicnr < 1
                app.dynamicnr = app.nr_dynamics;
            end
            app.MovieDynEditField.Value = app.dynamicnr;
            
            % Show the movie frame
            app.PlotCurrentMovieFrameFcn;
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            % Show the movie frame
            
            if app.movieButton.Value == 1 
                app.AutoIntensity(app.movie_exp);
            end
            
            if app.kspaceButton.Value == 1
                app.slicenr = 1;
                app.MovieSliceEditField.Value = app.slicenr;
                app.AutoIntensity(app.kspace{app.MovieCoilSpinner.Value});
            end
            
            if app.averagesButton.Value == 1
                app.slicenr = 1;
                app.MovieSliceEditField.Value = app.slicenr;
            end
            
            if (app.sensemapButton.Value == 1) && (app.sense_map_exists)
                app.AutoIntensity(app.sense_map(:,:,app.MovieSliceEditField.Value,app.sensemapSpinner1.Value,app.sensemapSpinner2.Value));
            end
            
            app.PlotCurrentMovieFrameFcn;
                        
        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)
            
            % If running, stop the movie
            app.StopButtonPushed;
            
            % Indicate busy
            app.Lamp5.Color = app.yellow;
            app.BusyImage5.Visible = 'on';
            app.SwitchOffAllButtons;
            
            % Check whether dicom-path is defined in dcmpath.txt file, only 1x per session
            if isfile('dcmpath.txt') && app.dicom_flag
                folder_name = fileread('dcmpath.txt');
                if exist(folder_name, 'dir')
                    app.dicom_export_path = folder_name;
                    app.dicom_flag = false;
                end
            end
                        
            % Ask for dicom export directory
            app.RetrospectiveUIFigure.Visible = 'off';
            app.dicom_export_path = uigetdir(app.dicom_export_path,'Dicom export path');
            app.RetrospectiveUIFigure.Visible = 'on';
            drawnow;
            
            % Check if directory exists and is writeable - does not always guarantee that the directory can be written
            ready = true;
            if ~ischar(app.dicom_export_path)     % user cancel
                ready = false;
                app.dicom_export_path = '';
            else
                [~, Attrib] = fileattrib(app.dicom_export_path);
                if ~any(Attrib.UserWrite)       % check if directory is writable
                    ready = false;
                end
            end
            
            % Export to dicom
            if ready
                
                app.TextMessage('DICOM export ...');
                
                % assuming that MRD and Data directory share the same base-directory
                % example: base / MRD / 343 / 8455 /
                
                % base / data / 343 / DICOM / 8455 /
                dcm_spath{1} = replace(app.mrd_import_path,'MRD','Data');
                dcmpos = strfind(dcm_spath{1},filesep);
                dcmpos = dcmpos(end-1);
                dcm_spath{1} = insertBefore(dcm_spath{1},dcmpos,[filesep,'DICOM']);
                
                % base / data / 343 / DICOM / 8455 / 1 /
                dcm_spath{2} = [dcm_spath{1},'1',filesep];
                
                % base / data / 343 / DICOM / 8455 / 2 /
                dcm_spath{3} = [dcm_spath{1},'2',filesep];
                
                % don't mention the B word
                dcm_spath{4} = strcat(app.mrd_import_path,filesep,'pdata',filesep,'1',filesep,'dicom');
                dcm_spath{5} = strcat(app.mrd_import_path,filesep,'pdata',filesep,'2',filesep,'dicom');
                
                % Check if one of the directories contains dicom files
                dcm_exist = false;
                app.TextMessage('Searching for DICOM info in the following locations ...');
                for i=1:length(dcm_spath)
                    app.TextMessage(dcm_spath{i});
                    if ~isempty(dir([dcm_spath{i},filesep,'*.dcm']))
                        dcm_path = dcm_spath{i};
                        dcm_exist = true;
                        app.TextMessage('Found DICOM info ...');
                        app.SetStatus(0);
                        break;
                    end
                end
                
                % Correct phase orientation for DCM export
                if app.parameters.PHASE_ORIENTATION
                    dcm_movie = permute(rot90(permute(app.movie_exp,[2,3,4,1,5]),1),[4,1,2,3,5]);
                    dcm_movie = flip(dcm_movie,3);
                else
                    dcm_movie = app.movie_exp;
                end
                
                % Export
                if dcm_exist
                    
                    % Export, reading dicom information from dcm_path
                    try
                        export_dicom_dcm(app,dcm_path,app.dicom_export_path,dcm_movie,app.parameters,app.heartrate,app.acq_dur,app.tag,app.RecoTypeDropDown.Value);
                        app.ExportRecoParametersFcn(app.dicom_export_path);
                    catch
                        app.TextMessage('ERROR: problem writing files ...');
                        app.Lamp5.Color = app.brightred;
                        app.SetStatus(2);
                    end
                    
                else
                    
                    % Export without image orientation information
                    try
                        app.TextMessage('WARNING: DICOM image orientation information not available ...');
                        app.SetStatus(1);
                        export_dicom(app.dicom_export_path,dcm_movie,app.parameters,app.heartrate,app.acq_dur,app.tag,app.RecoTypeDropDown.Value);
                        app.ExportRecoParametersFcn(app.dicom_export_path);
                    catch
                        app.TextMessage('ERROR: problem writing files ...');
                        app.Lamp5.Color = app.brightred;
                        app.SetStatus(2);
                    end
                    
                end
                
                clearvars dcm_movie;
                app.Lamp5.Color = app.brightgreen;
                app.SetStatus(0);
                
            else
                
                app.TextMessage('ERROR: not a valid export directory');
                app.Lamp5.Color = app.brightred;
                app.SetStatus(2);
                
            end
            
            % Reset buttons
            app.BusyImage5.Visible = 'off';
            app.SetDashboardButtons(255);
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;
            app.MovieButtons(true);
            drawnow;
            
        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)
            
            % If running, stop the movie
            app.StopButtonPushed;
            
            % Indicate busy
            app.Lamp6.Color = app.yellow;
            app.BusyImage6.Visible = 'on';
            app.SwitchOffAllButtons;
            
            % Ask for directory
            app.RetrospectiveUIFigure.Visible = 'off';
            app.gif_export_path = uigetdir(app.gif_export_path,'GIF export path');
            app.RetrospectiveUIFigure.Visible = 'on';
            drawnow;
            
            % Check if directory exists and is writeable
            ready = true;
            if ~ischar(app.gif_export_path)   % user cancel
                ready = false;
                app.gif_export_path = '';
            else
                [~, Attrib] = fileattrib(app.gif_export_path);
                if ~any(Attrib.UserWrite)   
                    ready = false;
                end
            end
            
            % Export to animated gif
            if ready
                
                app.TextMessage('Animated GIF export ...');
                
                try
                    export_gif(app.gif_export_path,app.movie_app,app.tag,app.window,app.level,app.RecoTypeDropDown.Value,app.acq_dur);
                    app.ExportRecoParametersFcn(app.gif_export_path);
                catch
                    app.TextMessage('ERROR: problem writing files ...');
                    app.Lamp6.Color = app.brightred;
                    app.SetStatus(2);
                end
                
                app.Lamp6.Color = app.brightgreen;
                app.SetStatus(0);
                
            else
                
                app.TextMessage('ERROR: not a valid export directory');
                app.Lamp6.Color = app.brightred;
                app.SetStatus(2);
                
            end
            
            % Reset buttons
            app.SetDashboardButtons(255);
            app.BusyImage6.Visible = 'off';
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;
            app.MovieButtons(true);
            drawnow;
            
        end

        % Button pushed function: ExportMRDButton
        function ExportMRDButtonPushed(app, event)
            
            % If running, stop the movie
            app.StopButtonPushed;
            
            % Indicate busy
            app.Lamp7.Color = app.yellow;
            app.BusyImage7.Visible = 'on';
            app.SwitchOffAllButtons;
            app.TextMessage('MRD and SUR file export ...');
            
            % Make a k-space from the movie
            app.kspace_mrd = [];
            switch app.datatype
                case {'2D','2Dms','radial'}
                    app.kspace_mrd = backtokspace(1,app.movie_exp);
                case {'3D','3Dp2roud'}
                    app.kspace_mrd = backtokspace(0,app.movie_exp);
            end
                        
            % Parameters for mrd footer
            [par.NoSamples,par.NoViews,par.NoViews2,par.NoSlices,par.NoEchoes,par.NoExperiments] = size(app.kspace_mrd);
            par.NoAverages = round(app.AveragesViewField.Value);
            par.peorder = 0;
            par.slicenav = 0;
            par.radialon = 0;
            par.frameloopon = 1;
            par.tr = round(1000*(60/app.heartrate)/app.nr_frames,2);
            par.te = 0;  % set to zero, because no_echoes dimension is used for frames
            par.batchslices = 0;
            par.View2order = "SEQUENTIAL";
            newmrdfooter = makemrdfooter(app.mrdfooter,par);
            if app.rpr_flag
                newrpr = makerpr(app.rpr,par);
            end
            
            % First try auto export the new MRD and rpr files
            success = true;
            app.mrd_export_path = app.mrd_import_path;

            mrdfilename = ['retro_',num2str(par.NoExperiments),'_',num2str(par.NoEchoes),'_',app.tag,'.MRD'];
            rprfilename = ['retro_',num2str(par.NoExperiments),'_',num2str(par.NoEchoes),'_',app.tag,'.rpr'];

            mrdexportfilename = [app.mrd_export_path,mrdfilename];
            rprexportfilename = [app.mrd_export_path,rprfilename];
            try
                writedata2mrd(mrdexportfilename, app.kspace_mrd, par, newmrdfooter);
                if app.rpr_flag
                    writedata2rpr(rprexportfilename, newrpr);
                end
                app.ExportRecoParametersFcn(app.mrd_export_path);
            catch
                success = false;
            end
            
            % If unsuccessful, try again using user-input directory
            if ~success
                
                % ask for directory
                app.RetrospectiveUIFigure.Visible = 'off';
                app.mrd_export_path = uigetdir(app.mrd_export_path,'MRD export path');
                app.RetrospectiveUIFigure.Visible = 'on';
                drawnow;
                
                % check if directory exists and is writeable
                ready = true;
                if ~ischar(app.mrd_export_path)   % user cancel
                    ready = false;
                    app.mrd_export_path = '';
                else
                    [~, Attrib] = fileattrib(app.mrd_export_path);
                    if ~any(Attrib.UserWrite)
                        ready = false;
                    end
                end
                                
                % export new MRD and rpr files to user-input directory
                if ready
                    success = true;
                    mrdexportfilename = [app.mrd_export_path,filesep,mrdfilename];
                    rprexportfilename = [app.mrd_export_path,filesep,rprfilename];
                    try
                        data2mrd(mrdexportfilename, app.kspace_mrd, par, newmrdfooter);
                        if app.rpr_flag
                            data2rpr(rprexportfilename, newrpr);
                        end
                    catch
                        success = false;
                    end
                end
                
            end
                        
            % Try to autoreconstruct and export of SUR files
            if success && ispc
                
                % Determine SUR directory
                try
                    scanpos = strfind(app.mrd_import_path,filesep);
                    scantag = strcat(app.mrd_import_path(scanpos(end-2)+1:scanpos(end-1)-1),filesep,'Image',filesep,app.mrd_import_path(scanpos(end-1)+1:scanpos(end)-1));
                    sur_path = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','BaseStorageDirectory');
                    sur_path1 = [sur_path,filesep,scantag,filesep,'1',filesep];
                    sur_path2 = [sur_path,filesep,scantag,filesep];
                    app.TextMessage('Data directory found in Windows registry ...');
                catch
                    sur_path = replace(app.mrd_import_path,'MRD','Data');
                    surpos = strfind(sur_path,filesep);
                    surpos = surpos(end-1);
                    sur_path1 = [insertBefore(sur_path,surpos,[filesep,'Image']),'1',filesep];
                    sur_path2 = insertBefore(sur_path,surpos,[filesep,'Image']);
                end
                
                sur_path = sur_path2;
                if (exist(sur_path1, 'dir'))
                    sur_path = sur_path1;
                end
                
                app.TextMessage(strcat('SUR directory =',{' '},sur_path));
                
                % SUR file names
                sursuffix = extractBefore(app.tag,'_');
                
                % Make and save the new SUR files
                try
                    recosurfiles(sur_path,sursuffix,mrdexportfilename,rprexportfilename);
                catch
                    app.TextMessage('WARNING: Automatic reconstruction of SUR files failed ...');
                    app.SetStatus(1);
                end
                
            end
            
            % Reset buttons
            if success 
                app.Lamp7.Color = app.brightgreen;
                app.SetStatus(0);
            else
                app.Lamp7.Color = app.brightred;
                app.TextMessage('ERROR: MRD export failed ...');
                app.SetStatus(2);
            end
            app.SetDashboardButtons(255);
            app.BusyImage7.Visible = 'off';
            app.NavSelButtons(true);
            app.NavViewingButtons(true);
            app.CineRecoButtons(true);
            app.stopmovie = true;
            app.MovieButtons(true);
            drawnow;
        
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('retrospective.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
            end
            
            % Exit
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RetrospectiveUIFigure = uifigure('Visible', 'off');
            app.RetrospectiveLabel = uilabel(app.RetrospectiveUIFigure);
            app.RetrospectiveLabel.Text = 'Retrospective';
            app.GustavStrijkersAmsterdamUMCVersion731Label = uilabel(app.RetrospectiveUIFigure);
            app.GustavStrijkersAmsterdamUMCVersion731Label.Text = 'Gustav Strijkers, Amsterdam UMC, Version 7.3.1';
            app.TulipImage = uiimage(app.RetrospectiveUIFigure);
            app.MessageBox = uitextarea(app.RetrospectiveUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Value = {'Messages ....'};
            app.MRSImage = uiimage(app.RetrospectiveUIFigure);
            app.BackgroundBox = uitextarea(app.RetrospectiveUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.StatusImage = uiimage(app.RetrospectiveUIFigure);
            app.statusLabel = uilabel(app.RetrospectiveUIFigure);
            app.statusLabel.Text = 'status';
            app.TabGroup4 = uitabgroup(app.RetrospectiveUIFigure);
            app.MovieTab = uitab(app.TabGroup4);
            app.MovieTab.Title = 'Movie';
            app.MovieFig = uiaxes(app.MovieTab);
            app.MovieFig.XLim = [0 255];
            app.MovieFig.YLim = [0 255];
            app.MovieFig.XTick = [];
            app.MovieFig.YTick = [];
            app.StartButton = uibutton(app.MovieTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Tooltip = {'Start the movie'};
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.MovieTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Tooltip = {'Stop the movie'};
            app.StopButton.Text = 'Stop';
            app.IntensityLabel = uilabel(app.MovieTab);
            app.IntensityLabel.Tooltip = {'Level and Window'};
            app.IntensityLabel.Text = 'Intensity';
            app.cwButton = uibutton(app.MovieTab, 'push');
            app.cwButton.ButtonPushedFcn = createCallbackFcn(app, @cwButtonPushed, true);
            app.cwButton.Enable = 'off';
            app.cwButton.Tooltip = {'Rotate movie cw'};
            app.cwButton.Text = '';
            app.ccwButton = uibutton(app.MovieTab, 'push');
            app.ccwButton.ButtonPushedFcn = createCallbackFcn(app, @ccwButtonPushed, true);
            app.ccwButton.Enable = 'off';
            app.ccwButton.Tooltip = {'Rotate movie cc'};
            app.ccwButton.Text = '';
            app.MovieRotateLabel = uilabel(app.MovieTab);
            app.MovieRotateLabel.Text = 'rotate';
            app.MovieFrameIncrButton = uibutton(app.MovieTab, 'push');
            app.MovieFrameIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieFrameIncrButtonPushed, true);
            app.MovieFrameIncrButton.Enable = 'off';
            app.MovieFrameIncrButton.Text = '+1';
            app.MovieFrameLabel = uilabel(app.MovieTab);
            app.MovieFrameLabel.Text = 'frame';
            app.MovieFrameDecrButton = uibutton(app.MovieTab, 'push');
            app.MovieFrameDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieFrameDecrButtonPushed, true);
            app.MovieFrameDecrButton.Enable = 'off';
            app.MovieFrameDecrButton.Text = '-1';
            app.MovieSliceIncrButton = uibutton(app.MovieTab, 'push');
            app.MovieSliceIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieSliceIncrButtonPushed, true);
            app.MovieSliceIncrButton.Enable = 'off';
            app.MovieSliceIncrButton.Text = '+1';
            app.MovieSliceLabel = uilabel(app.MovieTab);
            app.MovieSliceLabel.Text = 'slice';
            app.MovieSliceDecrButton = uibutton(app.MovieTab, 'push');
            app.MovieSliceDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieSliceDecrButtonPushed, true);
            app.MovieSliceDecrButton.Enable = 'off';
            app.MovieSliceDecrButton.Text = '-1';
            app.ButtonGroup = uibuttongroup(app.MovieTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.movieButton = uiradiobutton(app.ButtonGroup);
            app.movieButton.Enable = 'off';
            app.movieButton.Tooltip = {'Movie display.'};
            app.movieButton.Text = 'movie';
            app.movieButton.Value = true;
            app.averagesButton = uiradiobutton(app.ButtonGroup);
            app.averagesButton.Enable = 'off';
            app.averagesButton.Tooltip = {'Map of the number of k-space signal averages.'};
            app.averagesButton.Text = 'averages';
            app.kspaceButton = uiradiobutton(app.ButtonGroup);
            app.kspaceButton.Enable = 'off';
            app.kspaceButton.Tooltip = {'Absolute value of k-space.'};
            app.kspaceButton.Text = '|k-space|';
            app.sensemapButton = uiradiobutton(app.ButtonGroup);
            app.sensemapButton.Enable = 'off';
            app.sensemapButton.Tooltip = {'Sensitivity maps.'};
            app.sensemapButton.Text = 'sense-map';
            app.MovieCoilSpinner = uispinner(app.MovieTab);
            app.MovieCoilSpinner.Limits = [1 16];
            app.MovieCoilSpinner.ValueDisplayFormat = '%.0f';
            app.MovieCoilSpinner.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.MovieCoilSpinner.Enable = 'off';
            app.MovieCoilSpinner.Value = 1;
            app.sensemapSpinner1 = uispinner(app.MovieTab);
            app.sensemapSpinner1.Limits = [1 16];
            app.sensemapSpinner1.ValueDisplayFormat = '%.0f';
            app.sensemapSpinner1.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.sensemapSpinner1.Enable = 'off';
            app.sensemapSpinner1.Value = 1;
            app.sensemapSpinner2 = uispinner(app.MovieTab);
            app.sensemapSpinner2.Limits = [1 16];
            app.sensemapSpinner2.ValueDisplayFormat = '%.0f';
            app.sensemapSpinner2.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.sensemapSpinner2.Enable = 'off';
            app.sensemapSpinner2.Value = 1;
            app.MovieDynIncrButton = uibutton(app.MovieTab, 'push');
            app.MovieDynIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieDynIncrButtonPushed, true);
            app.MovieDynIncrButton.Enable = 'off';
            app.MovieDynIncrButton.Tooltip = {''};
            app.MovieDynIncrButton.Text = '+1';
            app.MovieDynLabel = uilabel(app.MovieTab);
            app.MovieDynLabel.Text = 'dynamic';
            app.MovieDynDecrButton = uibutton(app.MovieTab, 'push');
            app.MovieDynDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieDynDecrButtonPushed, true);
            app.MovieDynDecrButton.Enable = 'off';
            app.MovieDynDecrButton.Text = '-1';
            app.MovieCheckBox1 = uicheckbox(app.MovieTab);
            app.MovieCheckBox1.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox1.Enable = 'off';
            app.MovieCheckBox1.Text = '';
            app.MovieCheckBox1.Value = true;
            app.MovieCheckBox2 = uicheckbox(app.MovieTab);
            app.MovieCheckBox2.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox2.Enable = 'off';
            app.MovieCheckBox2.Text = '';
            app.MovieCheckBox3 = uicheckbox(app.MovieTab);
            app.MovieCheckBox3.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox3.Enable = 'off';
            app.MovieCheckBox3.Text = '';
            app.autoButton = uibutton(app.MovieTab, 'push');
            app.autoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.autoButton.Enable = 'off';
            app.autoButton.Text = 'auto';
            app.frameLabel = uilabel(app.MovieTab);
            app.frameLabel.Tooltip = {'Cardiac / respiratory movie'};
            app.frameLabel.Text = 'frame';
            app.MovieFrameEditField = uieditfield(app.MovieTab, 'numeric');
            app.MovieFrameEditField.Limits = [1 240];
            app.MovieFrameEditField.ValueDisplayFormat = '%.0f';
            app.MovieFrameEditField.ValueChangedFcn = createCallbackFcn(app, @MovieFrameEditFieldValueChanged, true);
            app.MovieFrameEditField.Enable = 'off';
            app.MovieFrameEditField.Tooltip = {''};
            app.MovieFrameEditField.Value = 1;
            app.heartbpmLabel_2 = uilabel(app.MovieTab);
            app.heartbpmLabel_2.Tooltip = {'Average heart rate.'};
            app.heartbpmLabel_2.Text = 'heart (bpm)';
            app.FinalHeartViewField = uieditfield(app.MovieTab, 'numeric');
            app.FinalHeartViewField.Editable = 'off';
            app.respbpmLabel_2 = uilabel(app.MovieTab);
            app.respbpmLabel_2.Tooltip = {'Average respiration rate.'};
            app.respbpmLabel_2.Text = 'resp. (bpm)';
            app.FinalRespirationViewField = uieditfield(app.MovieTab, 'numeric');
            app.FinalRespirationViewField.Editable = 'off';
            app.sliceLabel = uilabel(app.MovieTab);
            app.sliceLabel.Tooltip = {'Slices movie'};
            app.sliceLabel.Text = 'slice';
            app.MovieSliceEditField = uieditfield(app.MovieTab, 'numeric');
            app.MovieSliceEditField.Limits = [1 1024];
            app.MovieSliceEditField.ValueDisplayFormat = '%.0f';
            app.MovieSliceEditField.ValueChangedFcn = createCallbackFcn(app, @MovieSliceEditFieldValueChanged, true);
            app.MovieSliceEditField.Enable = 'off';
            app.MovieSliceEditField.Tooltip = {''};
            app.MovieSliceEditField.Value = 1;
            app.WLabel = uilabel(app.MovieTab);
            app.WLabel.Tooltip = {'Intensity scale adjustment.'};
            app.WLabel.Text = 'W';
            app.WindowScaleSlider = uislider(app.MovieTab);
            app.WindowScaleSlider.Limits = [-32767 32767];
            app.WindowScaleSlider.MajorTicks = [];
            app.WindowScaleSlider.ValueChangedFcn = createCallbackFcn(app, @WindowScaleSliderValueChanged, true);
            app.WindowScaleSlider.MinorTicks = [];
            app.WindowScaleSlider.Enable = 'off';
            app.WindowScaleSlider.Value = 1;
            app.LLabel = uilabel(app.MovieTab);
            app.LLabel.Tooltip = {'Intensity scale adjustment.'};
            app.LLabel.Text = 'L';
            app.LevelScaleSlider = uislider(app.MovieTab);
            app.LevelScaleSlider.Limits = [-32767 32767];
            app.LevelScaleSlider.MajorTicks = [];
            app.LevelScaleSlider.ValueChangedFcn = createCallbackFcn(app, @LevelScaleSliderValueChanged, true);
            app.LevelScaleSlider.MinorTicks = [];
            app.LevelScaleSlider.Enable = 'off';
            app.LevelScaleSlider.Value = 1;
            app.dynamicLabel = uilabel(app.MovieTab);
            app.dynamicLabel.Tooltip = {'Dynamics movie'};
            app.dynamicLabel.Text = 'dynamic';
            app.MovieDynEditField = uieditfield(app.MovieTab, 'numeric');
            app.MovieDynEditField.Limits = [1 1024];
            app.MovieDynEditField.ValueDisplayFormat = '%.0f';
            app.MovieDynEditField.ValueChangedFcn = createCallbackFcn(app, @MovieDynEditFieldValueChanged, true);
            app.MovieDynEditField.Enable = 'off';
            app.MovieDynEditField.Tooltip = {''};
            app.MovieDynEditField.Value = 1;
            app.LcurveTab = uitab(app.TabGroup4);
            app.LcurveTab.Title = 'L-curve';
            app.LcurveFig = uiaxes(app.LcurveTab);
            title(app.LcurveFig, 'L-curve')
            xlabel(app.LcurveFig, '|| F^{-1}x_{\lambda} - y ||_2')
            ylabel(app.LcurveFig, '|| TVcine x_{\lambda} ||_2')
            app.LcurveFig.XLim = [0 100];
            app.LcurveFig.YLim = [0 1000];
            app.LcurveModelErrorFig = uiaxes(app.LcurveTab);
            title(app.LcurveModelErrorFig, 'Model error')
            xlabel(app.LcurveModelErrorFig, '\lambda')
            ylabel(app.LcurveModelErrorFig, ' || F^{-1}x_{\lambda} - y ||_2')
            app.LcurveModelErrorFig.XLim = [0 100];
            app.LcurveModelErrorFig.YLim = [0 1000];
            app.LcurveStartButton = uibutton(app.LcurveTab, 'push');
            app.LcurveStartButton.ButtonPushedFcn = createCallbackFcn(app, @LcurveStartButtonPushed, true);
            app.LcurveStartButton.Enable = 'off';
            app.LcurveStartButton.Text = 'Start';
            app.lowerEditFieldLabel = uilabel(app.LcurveTab);
            app.lowerEditFieldLabel.Text = 'lower';
            app.Lambda1EditField = uieditfield(app.LcurveTab, 'numeric');
            app.Lambda1EditField.Limits = [0 0.1];
            app.Lambda1EditField.Value = 0.001;
            app.upperEditFieldLabel = uilabel(app.LcurveTab);
            app.upperEditFieldLabel.Text = 'upper';
            app.Lambda2EditField = uieditfield(app.LcurveTab, 'numeric');
            app.Lambda2EditField.Limits = [0.11 10];
            app.Lambda2EditField.Value = 1;
            app.stepsEditFieldLabel = uilabel(app.LcurveTab);
            app.stepsEditFieldLabel.Text = '#steps';
            app.LambdaStepsEditField = uieditfield(app.LcurveTab, 'numeric');
            app.LambdaStepsEditField.Limits = [6 30];
            app.LambdaStepsEditField.ValueDisplayFormat = '%.0f';
            app.LambdaStepsEditField.Value = 12;
            app.iterLabel = uilabel(app.LcurveTab);
            app.iterLabel.Text = '#iter';
            app.LambdaIterationsEditField = uieditfield(app.LcurveTab, 'numeric');
            app.LambdaIterationsEditField.Limits = [1 10];
            app.LambdaIterationsEditField.ValueDisplayFormat = '%.0f';
            app.LambdaIterationsEditField.Value = 1;
            app.LambdaLabel = uilabel(app.LcurveTab);
            app.LambdaLabel.Text = 'Lambda';
            app.LcurveStopButton = uibutton(app.LcurveTab, 'push');
            app.LcurveStopButton.ButtonPushedFcn = createCallbackFcn(app, @LcurveStopButtonPushed, true);
            app.LcurveStopButton.Enable = 'off';
            app.LcurveStopButton.Text = 'Stop';
            app.LcurveWVxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveWVxyzCheckBox.Text = '';
            app.LcurveTVcineCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVcineCheckBox.Text = '';
            app.LcurveTVcineCheckBox.Value = true;
            app.LcurveTVxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVxyzCheckBox.Text = '';
            app.LcurveTVdynCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVdynCheckBox.Text = '';
            app.WVxyzLabel = uilabel(app.LcurveTab);
            app.WVxyzLabel.Text = 'WVxyz';
            app.TVcineLabel = uilabel(app.LcurveTab);
            app.TVcineLabel.Text = 'TVcine';
            app.TVxyzLabel = uilabel(app.LcurveTab);
            app.TVxyzLabel.Text = 'TVxyz';
            app.TVdynLabel = uilabel(app.LcurveTab);
            app.TVdynLabel.Text = 'TVdyn';
            app.RegularizationLabel = uilabel(app.LcurveTab);
            app.RegularizationLabel.Text = 'Regularization';
            app.LRxyzLabel2 = uilabel(app.LcurveTab);
            app.LRxyzLabel2.Text = 'LRxyz';
            app.LcurveLRxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveLRxyzCheckBox.Text = '';
            app.RadialTab = uitab(app.TabGroup4);
            app.RadialTab.Title = 'Radial';
            app.TabGroup3 = uitabgroup(app.RetrospectiveUIFigure);
            app.AcquisitionparametersTab = uitab(app.TabGroup3);
            app.AcquisitionparametersTab.Title = 'Acquisition parameters';
            app.TrajectoryFig = uiaxes(app.AcquisitionparametersTab);
            app.TrajectoryFig.XTick = [];
            app.TrajectoryFig.YTick = [];
            app.DataFile = uitextarea(app.AcquisitionparametersTab);
            app.DataFile.ValueChangedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.DataFile.Editable = 'off';
            app.DataFile.Value = {' '};
            app.DatafileLabel = uilabel(app.AcquisitionparametersTab);
            app.DatafileLabel.Text = 'data file';
            app.phaseNyLabel = uilabel(app.AcquisitionparametersTab);
            app.phaseNyLabel.Text = 'phase  Ny';
            app.NyViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.NyViewField.Editable = 'off';
            app.freqNxLabel = uilabel(app.AcquisitionparametersTab);
            app.freqNxLabel.Text = 'freq  Nx';
            app.NxViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.NxViewField.Editable = 'off';
            app.trajectorysizeLabel = uilabel(app.AcquisitionparametersTab);
            app.trajectorysizeLabel.Text = 'trajectory size';
            app.KlinesRepetitionViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.KlinesRepetitionViewField.Editable = 'off';
            app.repetitionsLabel = uilabel(app.AcquisitionparametersTab);
            app.repetitionsLabel.Text = 'repetitions';
            app.RepetitionsViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.RepetitionsViewField.RoundFractionalValues = 'on';
            app.RepetitionsViewField.ValueDisplayFormat = '%.0f';
            app.RepetitionsViewField.Editable = 'off';
            app.klinesLabel = uilabel(app.AcquisitionparametersTab);
            app.klinesLabel.Text = ' # k-lines';
            app.NrKlinesViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.NrKlinesViewField.ValueDisplayFormat = '%.0f';
            app.NrKlinesViewField.Editable = 'off';
            app.averagesEditFieldLabel = uilabel(app.AcquisitionparametersTab);
            app.averagesEditFieldLabel.Text = 'averages';
            app.AveragesViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.AveragesViewField.Editable = 'off';
            app.fillingLabel = uilabel(app.AcquisitionparametersTab);
            app.fillingLabel.Text = 'filling (%)';
            app.FillingViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.FillingViewField.Limits = [0 100];
            app.FillingViewField.ValueDisplayFormat = '%.0f';
            app.FillingViewField.Editable = 'off';
            app.TRmsLabel = uilabel(app.AcquisitionparametersTab);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.TRViewField.Editable = 'off';
            app.trajectoryEditFieldLabel = uilabel(app.AcquisitionparametersTab);
            app.trajectoryEditFieldLabel.Text = 'trajectory';
            app.TrajectoryViewField = uieditfield(app.AcquisitionparametersTab, 'text');
            app.TrajectoryViewField.Editable = 'off';
            app.slicesNzLabel = uilabel(app.AcquisitionparametersTab);
            app.slicesNzLabel.Text = 'slices  Nz';
            app.NzViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.NzViewField.Editable = 'off';
            app.coilsNcLabel = uilabel(app.AcquisitionparametersTab);
            app.coilsNcLabel.Text = 'coils  Nc';
            app.NcViewField = uieditfield(app.AcquisitionparametersTab, 'numeric');
            app.NcViewField.Editable = 'off';
            app.scantimeEditFieldLabel = uilabel(app.AcquisitionparametersTab);
            app.scantimeEditFieldLabel.Text = 'scan time';
            app.ScanTimeViewField = uieditfield(app.AcquisitionparametersTab, 'text');
            app.timedynamicLabel = uilabel(app.AcquisitionparametersTab);
            app.timedynamicLabel.Text = 'time / dynamic';
            app.TimeDynViewField = uieditfield(app.AcquisitionparametersTab, 'text');
            app.PresetsTab = uitab(app.TabGroup3);
            app.PresetsTab.Title = 'Presets';
            app.SpeciesButton = uibuttongroup(app.PresetsTab);
            app.SpeciesButton.SelectionChangedFcn = createCallbackFcn(app, @SpeciesButtonSelectionChanged, true);
            app.SpeciesButton.Tooltip = {'Preset navigator filter values for mouse and rat'};
            app.SpeciesButton.ButtonDownFcn = createCallbackFcn(app, @SpeciesButtonButtonDown, true);
            app.MouseButton = uiradiobutton(app.SpeciesButton);
            app.MouseButton.Enable = 'off';
            app.MouseButton.Text = 'mouse';
            app.MouseButton.Value = true;
            app.RatButton = uiradiobutton(app.SpeciesButton);
            app.RatButton.Enable = 'off';
            app.RatButton.Text = 'rat';
            app.SpeciesLabel = uilabel(app.PresetsTab);
            app.SpeciesLabel.Text = 'Species';
            app.RecoPresetsButtonGroup = uibuttongroup(app.PresetsTab);
            app.RecoPresetsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RecoPresetsButtonGroupSelectionChanged, true);
            app.RecoPresetsButtonGroup.Tooltip = {'Preset reconstruction settings'};
            app.RecoPresetsButtonGroup.ButtonDownFcn = createCallbackFcn(app, @RecoPresetsButtonGroupButtonDown, true);
            app.ScoutButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.ScoutButton.Enable = 'off';
            app.ScoutButton.Text = 'scout';
            app.SystolicFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.SystolicFunctionButton.Enable = 'off';
            app.SystolicFunctionButton.Text = 'systolic function';
            app.SystolicFunctionButton.Value = true;
            app.DiastolicFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.DiastolicFunctionButton.Enable = 'off';
            app.DiastolicFunctionButton.Text = 'diastolic function';
            app.RespiratoryFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.RespiratoryFunctionButton.Enable = 'off';
            app.RespiratoryFunctionButton.Text = 'respiratory function';
            app.RealTimeFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.RealTimeFunctionButton.Enable = 'off';
            app.RealTimeFunctionButton.Text = 'realtime';
            app.ReconstructionpresetsLabel = uilabel(app.PresetsTab);
            app.ReconstructionpresetsLabel.Text = 'Reconstruction presets';
            app.TabGroup2 = uitabgroup(app.RetrospectiveUIFigure);
            app.NavigatoranalysisTab = uitab(app.TabGroup2);
            app.NavigatoranalysisTab.Title = 'Navigator analysis';
            app.CardiacNavFig = uiaxes(app.NavigatoranalysisTab);
            title(app.CardiacNavFig, 'Heart navigator')
            xlabel(app.CardiacNavFig, 'Samples')
            ylabel(app.CardiacNavFig, 'Signal')
            app.RespirationNavFig = uiaxes(app.NavigatoranalysisTab);
            title(app.RespirationNavFig, 'Respiration navigator')
            xlabel(app.RespirationNavFig, 'Samples')
            ylabel(app.RespirationNavFig, 'Signal')
            app.HeartRateTimeFig = uiaxes(app.NavigatoranalysisTab);
            title(app.HeartRateTimeFig, 'Heart rate')
            xlabel(app.HeartRateTimeFig, 'Time (s)')
            ylabel(app.HeartRateTimeFig, 'Rate (bpm)')
            app.HeartRateTimeFig.XLim = [0 100];
            app.HeartRateTimeFig.YLim = [0 1000];
            app.RespirationRateTimeFig = uiaxes(app.NavigatoranalysisTab);
            title(app.RespirationRateTimeFig, 'Respiration rate')
            xlabel(app.RespirationRateTimeFig, 'Time (s)')
            ylabel(app.RespirationRateTimeFig, 'Rate (bpm)')
            app.RespirationRateTimeFig.XLim = [0 100];
            app.RespirationRateTimeFig.YLim = [0 100];
            app.DisplayrangeLabel = uilabel(app.NavigatoranalysisTab);
            app.DisplayrangeLabel.Tooltip = {'Selects the starting point and range of the heart- and respiratory-navigator windows'};
            app.DisplayrangeLabel.Text = 'Display range';
            app.StartSlider = uislider(app.NavigatoranalysisTab);
            app.StartSlider.Limits = [1 6000];
            app.StartSlider.MajorTicks = [];
            app.StartSlider.MajorTickLabels = {'1', '6000'};
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.StartSlider.MinorTicks = [];
            app.StartSlider.Enable = 'off';
            app.StartSlider.Value = 1000;
            app.RangeSlider = uislider(app.NavigatoranalysisTab);
            app.RangeSlider.Limits = [1 2000];
            app.RangeSlider.MajorTicks = [];
            app.RangeSlider.MajorTickLabels = {'1', '6000'};
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.RangeSlider.MinorTicks = [];
            app.RangeSlider.Enable = 'off';
            app.RangeSlider.Value = 600;
            app.IncludeCheckBox = uicheckbox(app.NavigatoranalysisTab);
            app.IncludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeCheckBoxValueChanged, true);
            app.IncludeCheckBox.Enable = 'off';
            app.IncludeCheckBox.Tooltip = {'Switch include window on/off'};
            app.IncludeCheckBox.Text = '';
            app.IncludeStartSlider = uislider(app.NavigatoranalysisTab);
            app.IncludeStartSlider.Limits = [1 6000];
            app.IncludeStartSlider.MajorTicks = [];
            app.IncludeStartSlider.MajorTickLabels = {'1', '6000'};
            app.IncludeStartSlider.ValueChangedFcn = createCallbackFcn(app, @IncludeSliderValueChanged, true);
            app.IncludeStartSlider.MinorTicks = [];
            app.IncludeStartSlider.Enable = 'off';
            app.IncludeStartSlider.Value = 1000;
            app.IncludeEndSlider = uislider(app.NavigatoranalysisTab);
            app.IncludeEndSlider.Limits = [1 6000];
            app.IncludeEndSlider.MajorTicks = [];
            app.IncludeEndSlider.MajorTickLabels = {'1', '6000'};
            app.IncludeEndSlider.ValueChangedFcn = createCallbackFcn(app, @IncludeSliderValueChanged, true);
            app.IncludeEndSlider.MinorTicks = [];
            app.IncludeEndSlider.Enable = 'off';
            app.IncludeEndSlider.Value = 1000;
            app.IncludeLabel = uilabel(app.NavigatoranalysisTab);
            app.IncludeLabel.Tooltip = {'Windows the data to reconstruct only part of the acquisition. '; 'Only the green selection area will be included. '; 'Window can be adjusted with the sliders.'};
            app.IncludeLabel.Text = 'Include';
            app.ExcludeCheckBox = uicheckbox(app.NavigatoranalysisTab);
            app.ExcludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeCheckBoxValueChanged, true);
            app.ExcludeCheckBox.Enable = 'off';
            app.ExcludeCheckBox.Tooltip = {'Switch include window on/off'};
            app.ExcludeCheckBox.Text = '';
            app.ExcludeStartSlider = uislider(app.NavigatoranalysisTab);
            app.ExcludeStartSlider.Limits = [1 6000];
            app.ExcludeStartSlider.MajorTicks = [];
            app.ExcludeStartSlider.MajorTickLabels = {'1', '6000'};
            app.ExcludeStartSlider.ValueChangedFcn = createCallbackFcn(app, @ExcludeSliderValueChanged, true);
            app.ExcludeStartSlider.MinorTicks = [];
            app.ExcludeStartSlider.Enable = 'off';
            app.ExcludeStartSlider.Value = 1000;
            app.ExcludeEndSlider = uislider(app.NavigatoranalysisTab);
            app.ExcludeEndSlider.Limits = [1 6000];
            app.ExcludeEndSlider.MajorTicks = [];
            app.ExcludeEndSlider.MajorTickLabels = {'1', '6000'};
            app.ExcludeEndSlider.ValueChangedFcn = createCallbackFcn(app, @ExcludeSliderValueChanged, true);
            app.ExcludeEndSlider.MinorTicks = [];
            app.ExcludeEndSlider.Enable = 'off';
            app.ExcludeEndSlider.Value = 1000;
            app.ExcludeLabel = uilabel(app.NavigatoranalysisTab);
            app.ExcludeLabel.Tooltip = {'Windows the data to exclude part of the acquisition. '; 'The red selection area will be excluded. '; 'Window can be adjusted with the sliders.'};
            app.ExcludeLabel.Text = 'Exclude';
            app.NavStartButton = uibutton(app.NavigatoranalysisTab, 'push');
            app.NavStartButton.ButtonPushedFcn = createCallbackFcn(app, @NavStartButtonPushed, true);
            app.NavStartButton.Enable = 'off';
            app.NavStartButton.Tooltip = {'Start navigator movie'};
            app.NavStartButton.Text = ' ';
            app.NavStopButton = uibutton(app.NavigatoranalysisTab, 'push');
            app.NavStopButton.ButtonPushedFcn = createCallbackFcn(app, @NavStopButtonPushed, true);
            app.NavStopButton.Enable = 'off';
            app.NavStopButton.Tooltip = {'Stop navigator movie'};
            app.NavStopButton.Text = ' ';
            app.NavFastSlowSwitch = uiswitch(app.NavigatoranalysisTab, 'rocker');
            app.NavFastSlowSwitch.Items = {'slow', 'fast'};
            app.NavFastSlowSwitch.ValueChangedFcn = createCallbackFcn(app, @NavFastSlowSwitchValueChanged, true);
            app.NavFastSlowSwitch.Enable = 'off';
            app.NavFastSlowSwitch.Tooltip = {'Navigator movie fast or slow'};
            app.NavFastSlowSwitch.Value = 'slow';
            app.startEditFieldLabel = uilabel(app.NavigatoranalysisTab);
            app.startEditFieldLabel.Tooltip = {''};
            app.startEditFieldLabel.Text = 'start';
            app.StartEditField = uieditfield(app.NavigatoranalysisTab, 'numeric');
            app.StartEditField.Limits = [1 Inf];
            app.StartEditField.RoundFractionalValues = 'on';
            app.StartEditField.ValueDisplayFormat = '%.0f';
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.StartEditField.Enable = 'off';
            app.StartEditField.Tooltip = {'Start of plotted navigator window'};
            app.StartEditField.Value = 1000;
            app.rangeEditFieldLabel = uilabel(app.NavigatoranalysisTab);
            app.rangeEditFieldLabel.Tooltip = {''};
            app.rangeEditFieldLabel.Text = 'range';
            app.RangeEditField = uieditfield(app.NavigatoranalysisTab, 'numeric');
            app.RangeEditField.Limits = [1 2000];
            app.RangeEditField.RoundFractionalValues = 'on';
            app.RangeEditField.ValueDisplayFormat = '%.0f';
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.RangeEditField.Enable = 'off';
            app.RangeEditField.Tooltip = {'Range of plotted navigator window'};
            app.RangeEditField.Value = 600;
            app.TabGroup1 = uitabgroup(app.RetrospectiveUIFigure);
            app.NavigatorTab = uitab(app.TabGroup1);
            app.NavigatorTab.Title = 'Navigator';
            app.RawNavFig = uiaxes(app.NavigatorTab);
            title(app.RawNavFig, 'Raw navigator')
            xlabel(app.RawNavFig, 'Samples')
            ylabel(app.RawNavFig, 'Signal')
            app.SpectrumFig = uiaxes(app.NavigatorTab);
            title(app.SpectrumFig, 'Navigator Spectrum')
            xlabel(app.SpectrumFig, 'Frequency (bpm)')
            ylabel(app.SpectrumFig, 'Power')
            app.CoilSpinner = uispinner(app.NavigatorTab);
            app.CoilSpinner.Limits = [1 16];
            app.CoilSpinner.ValueDisplayFormat = '%.0f';
            app.CoilSpinner.ValueChangedFcn = createCallbackFcn(app, @CoilSpinnerValueChanged, true);
            app.CoilSpinner.Enable = 'off';
            app.CoilSpinner.Tooltip = {'RF receiver coil number'};
            app.CoilSpinner.Value = 1;
            app.CoilLabel = uilabel(app.NavigatorTab);
            app.CoilLabel.Tooltip = {'Toggles between RF-coils to inspect the raw navigator signals.'};
            app.CoilLabel.Text = 'coil';
            app.loglinButton = uibutton(app.NavigatorTab, 'push');
            app.loglinButton.ButtonPushedFcn = createCallbackFcn(app, @loglinButtonPushed, true);
            app.loglinButton.Enable = 'off';
            app.loglinButton.Text = 'lin';
            app.AdvancedsettingsTab = uitab(app.TabGroup1);
            app.AdvancedsettingsTab.Title = 'Advanced settings';
            app.PhysiofilteringLabel = uilabel(app.AdvancedsettingsTab);
            app.PhysiofilteringLabel.Text = 'Physio filtering';
            app.minresprateEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.minresprateEditFieldLabel.Text = 'min resp rate';
            app.MinresprateEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.MinresprateEditField.Limits = [1 1000];
            app.MinresprateEditField.ValueDisplayFormat = '%.0f';
            app.MinresprateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MinresprateEditField.Enable = 'off';
            app.MinresprateEditField.Tooltip = {'Minimum search range respiration rate'};
            app.MinresprateEditField.Value = 1;
            app.maxresprateEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.maxresprateEditFieldLabel.Text = 'max resp rate';
            app.MaxresprateEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.MaxresprateEditField.Limits = [1 1000];
            app.MaxresprateEditField.ValueDisplayFormat = '%.0f';
            app.MaxresprateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MaxresprateEditField.Enable = 'off';
            app.MaxresprateEditField.Tooltip = {'Maximum search range respiration rate'};
            app.MaxresprateEditField.Value = 1;
            app.minheartrateEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.minheartrateEditFieldLabel.Text = 'min heart rate';
            app.MinheartrateEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.MinheartrateEditField.Limits = [1 1000];
            app.MinheartrateEditField.ValueDisplayFormat = '%.0f';
            app.MinheartrateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MinheartrateEditField.Enable = 'off';
            app.MinheartrateEditField.Tooltip = {'Minimum of search range heart rate'};
            app.MinheartrateEditField.Value = 1;
            app.maxheartrateEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.maxheartrateEditFieldLabel.Text = 'max heart rate';
            app.MaxheartrateEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.MaxheartrateEditField.Limits = [1 1000];
            app.MaxheartrateEditField.ValueDisplayFormat = '%.0f';
            app.MaxheartrateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MaxheartrateEditField.Enable = 'off';
            app.MaxheartrateEditField.Tooltip = {'Maxiumum of search range heart rate'};
            app.MaxheartrateEditField.Value = 1;
            app.filterorderEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.filterorderEditFieldLabel.Text = 'filter order';
            app.FilterorderEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.FilterorderEditField.Limits = [1 6];
            app.FilterorderEditField.ValueDisplayFormat = '%.0f';
            app.FilterorderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.FilterorderEditField.Enable = 'off';
            app.FilterorderEditField.Tooltip = {'Butterworth filter order'};
            app.FilterorderEditField.Value = 4;
            app.DiscardEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.DiscardEditField.Limits = [0 1024];
            app.DiscardEditField.ValueDisplayFormat = '%.0f';
            app.DiscardEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.DiscardEditField.Enable = 'off';
            app.DiscardEditField.Tooltip = {'Number of frequency encoding points discarded after navigator. Increase if FID artifact is present in movie.'};
            app.DiscardEditField.Value = 35;
            app.freqNxdiscardLabel = uilabel(app.AdvancedsettingsTab);
            app.freqNxdiscardLabel.Text = 'freq Nx discard';
            app.NavigatorLabel = uilabel(app.AdvancedsettingsTab);
            app.NavigatorLabel.Text = 'Navigator';
            app.primaryLabel = uilabel(app.AdvancedsettingsTab);
            app.primaryLabel.Tooltip = {'Primary navigator point.'};
            app.primaryLabel.Text = 'primary';
            app.NavigatorEndEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.NavigatorEndEditField.Limits = [1 1024];
            app.NavigatorEndEditField.ValueDisplayFormat = '%.0f';
            app.NavigatorEndEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorEndEditField.Enable = 'off';
            app.NavigatorEndEditField.Tooltip = {'Primary navigator point analyzed'};
            app.NavigatorEndEditField.Value = 10;
            app.pointsLabel = uilabel(app.AdvancedsettingsTab);
            app.pointsLabel.Tooltip = {'Number of points that are considered for navigator analysis.'};
            app.pointsLabel.Text = '# points';
            app.NavigatorNRPointsEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.NavigatorNRPointsEditField.Limits = [1 24];
            app.NavigatorNRPointsEditField.ValueDisplayFormat = '%.0f';
            app.NavigatorNRPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorNRPointsEditField.Enable = 'off';
            app.NavigatorNRPointsEditField.Tooltip = {'Number of navigator points analyzed'};
            app.NavigatorNRPointsEditField.Value = 1;
            app.SpeciesButton2 = uibuttongroup(app.AdvancedsettingsTab);
            app.SpeciesButton2.SelectionChangedFcn = createCallbackFcn(app, @SpeciesButton2SelectionChanged, true);
            app.SpeciesButton2.Tooltip = {'Preset navigator filter values for mouse and rat'};
            app.SpeciesButton2.ButtonDownFcn = createCallbackFcn(app, @SpeciesButtonButtonDown, true);
            app.MouseButton2 = uiradiobutton(app.SpeciesButton2);
            app.MouseButton2.Enable = 'off';
            app.MouseButton2.Text = 'mouse';
            app.MouseButton2.Value = true;
            app.RatButton2 = uiradiobutton(app.SpeciesButton2);
            app.RatButton2.Enable = 'off';
            app.RatButton2.Text = 'rat';
            app.PresetsLabel_2 = uilabel(app.AdvancedsettingsTab);
            app.PresetsLabel_2.Text = 'Presets';
            app.FlipSwitchLabel = uilabel(app.AdvancedsettingsTab);
            app.FlipSwitchLabel.Tooltip = {'Flips the navigator signal.'; 'Respiratory navigator signals should display positive peaks.'};
            app.FlipSwitchLabel.Text = 'Flip';
            app.NavigatorFlipSwitch = uiswitch(app.AdvancedsettingsTab, 'toggle');
            app.NavigatorFlipSwitch.Items = {'Down', 'Up'};
            app.NavigatorFlipSwitch.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorFlipSwitch.Enable = 'off';
            app.NavigatorFlipSwitch.Tooltip = {'Flip navigator signal'};
            app.NavigatorFlipSwitch.Value = 'Up';
            app.sharingLabel = uilabel(app.AdvancedsettingsTab);
            app.sharingLabel.Text = 'sharing';
            app.FrameSharingEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.FrameSharingEditField.Limits = [1 9];
            app.FrameSharingEditField.ValueDisplayFormat = '%.0f';
            app.FrameSharingEditField.Enable = 'off';
            app.FrameSharingEditField.Value = 1;
            app.DynamicsLabel = uilabel(app.AdvancedsettingsTab);
            app.DynamicsLabel.Text = 'Dynamics';
            app.TabGroup0 = uitabgroup(app.RetrospectiveUIFigure);
            app.DashboardTab = uitab(app.TabGroup0);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDButton.Enable = 'off';
            app.LoadMRDButton.Tooltip = {'Select MRD file - '; 'for multicoil data select one of the MRD files'};
            app.LoadMRDButton.Text = ' Load MRD';
            app.DetectedratesBPMPanel = uipanel(app.DashboardTab);
            app.DetectedratesBPMPanel.Title = 'Heart / respiration filters';
            app.respbpmLabel_3 = uilabel(app.DetectedratesBPMPanel);
            app.respbpmLabel_3.Tooltip = {'Center frequency and width used as a starting point for filtering the navigator data for respiratory motion. '; 'Initially automatically determined but can be changed manually.'};
            app.respbpmLabel_3.Text = 'resp. (bpm)';
            app.RespirationEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.RespirationEditField.Limits = [25 200];
            app.RespirationEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationEditField.Enable = 'off';
            app.RespirationEditField.Value = 50;
            app.heartbpmLabel_3 = uilabel(app.DetectedratesBPMPanel);
            app.heartbpmLabel_3.Tooltip = {'Center frequency and width used as a starting point for filtering the navigator data for cardiac motion. '; 'Initially automatically determined but can be changed manually.'};
            app.heartbpmLabel_3.Text = 'heart (bpm)';
            app.HeartEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.HeartEditField.Limits = [100 2000];
            app.HeartEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartEditField.Enable = 'off';
            app.HeartEditField.Value = 500;
            app.windowLabel = uilabel(app.DetectedratesBPMPanel);
            app.windowLabel.Tooltip = {'Percentage of the data that is discarded during respiration.'};
            app.windowLabel.Text = 'window (%)';
            app.RespirationWindowEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.RespirationWindowEditField.Limits = [5 95];
            app.RespirationWindowEditField.ValueDisplayFormat = '%.0f';
            app.RespirationWindowEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationWindowEditField.Enable = 'off';
            app.RespirationWindowEditField.Value = 30;
            app.RespirationWidthEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.RespirationWidthEditField.Limits = [10 100];
            app.RespirationWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationWidthEditField.Enable = 'off';
            app.RespirationWidthEditField.Value = 40;
            app.HeartWidthEditField = uieditfield(app.DetectedratesBPMPanel, 'numeric');
            app.HeartWidthEditField.Limits = [20 300];
            app.HeartWidthEditField.ValueDisplayFormat = '%.0f';
            app.HeartWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartWidthEditField.Enable = 'off';
            app.HeartWidthEditField.Value = 100;
            app.centerLabel = uilabel(app.DetectedratesBPMPanel);
            app.centerLabel.Text = 'center';
            app.widthLabel = uilabel(app.DetectedratesBPMPanel);
            app.widthLabel.Text = 'width';
            app.ResetFilterValuesButton = uibutton(app.DetectedratesBPMPanel, 'push');
            app.ResetFilterValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFilterValuesButtonPushed, true);
            app.ResetFilterValuesButton.Enable = 'off';
            app.ResetFilterValuesButton.Text = 'reset';
            app.FilterButton = uibutton(app.DashboardTab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Enable = 'off';
            app.FilterButton.Tooltip = {'Filters the navigator signal to extract cardiac and respiratory triggering points'};
            app.FilterButton.Text = ' Filter';
            app.ReconstructButton = uibutton(app.DashboardTab, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Enable = 'off';
            app.ReconstructButton.Tooltip = {'Perform a compressed-sensing reconstruction of the data with the Bart-toolbox (if available) or Matlab'};
            app.ReconstructButton.Text = 'Reconstruct';
            app.CSrecoparametersPanel = uipanel(app.DashboardTab);
            app.CSrecoparametersPanel.Title = 'CS reco parameters';
            app.TVcineEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.TVcineEditFieldLabel.Tooltip = {'Total-variation regularization in the CINE dimension'};
            app.TVcineEditFieldLabel.Text = 'TVcine';
            app.TVcineEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.TVcineEditField.Limits = [0 20];
            app.TVcineEditField.ValueDisplayFormat = '%.3f';
            app.TVcineEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVcineEditField.Enable = 'off';
            app.TVcineEditField.Tooltip = {'Total Variation constraint in CINE dimension'};
            app.TVcineEditField.Value = 0.1;
            app.TVxyzEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.TVxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVxyzEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.TVxyzEditField.Limits = [0 20];
            app.TVxyzEditField.ValueDisplayFormat = '%.3f';
            app.TVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVxyzEditField.Enable = 'off';
            app.TVxyzEditField.Tooltip = {'Total Variation constraint in spatial domain'};
            app.ProgressGauge = uigauge(app.CSrecoparametersPanel, 'linear');
            app.ProgressGauge.MajorTicks = [0 25 50 75 100];
            app.WVxyzEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.WVxyzEditFieldLabel.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.WVxyzEditField.Limits = [0 20];
            app.WVxyzEditField.ValueDisplayFormat = '%.3f';
            app.WVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.WVxyzEditField.Enable = 'off';
            app.WVxyzEditField.Tooltip = {'Wavelet L1 regularization in spatial domain'};
            app.ESPIRiTCheckBox = uicheckbox(app.CSrecoparametersPanel);
            app.ESPIRiTCheckBox.Enable = 'off';
            app.ESPIRiTCheckBox.Text = '';
            app.ESPIRiTLabel = uilabel(app.CSrecoparametersPanel);
            app.ESPIRiTLabel.Tooltip = {'ESPIRiT reconstruction.'; '(Magn Reson Med. 2014 Mar'; ' 71(3): 990–1001.)'};
            app.ESPIRiTLabel.Text = 'ESPIRiT';
            app.CLEARCheckBox = uicheckbox(app.CSrecoparametersPanel);
            app.CLEARCheckBox.Enable = 'off';
            app.CLEARCheckBox.Text = '';
            app.CLEARLabel = uilabel(app.CSrecoparametersPanel);
            app.CLEARLabel.Tooltip = {'Intensity inhomogeneity correction using coil-sensitivity maps'};
            app.CLEARLabel.Text = 'CLEAR';
            app.TVdynEditFieldLabel = uilabel(app.CSrecoparametersPanel);
            app.TVdynEditFieldLabel.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.TVdynEditFieldLabel.Text = 'TVdyn';
            app.TVdynEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.TVdynEditField.Limits = [0 20];
            app.TVdynEditField.ValueDisplayFormat = '%.3f';
            app.TVdynEditField.Enable = 'off';
            app.TVdynEditField.Tooltip = {'Total Variation constraint in dynamic dimension'};
            app.LRxyzLabel = uilabel(app.CSrecoparametersPanel);
            app.LRxyzLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.LRxyzLabel.Text = 'LRxyz';
            app.LRxyzEditField = uieditfield(app.CSrecoparametersPanel, 'numeric');
            app.LRxyzEditField.Limits = [0 20];
            app.LRxyzEditField.ValueDisplayFormat = '%.3f';
            app.LRxyzEditField.Enable = 'off';
            app.LRxyzEditField.Tooltip = {'Locally Low-Rank in spatial domain'};
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export the movie to Dicom'};
            app.ExportDCMButton.Text = 'Export DCM';
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = '     Exit';
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export the movie to animated gif'};
            app.ExportGIFButton.Text = 'Export GIF';
            app.BusyImage4 = uiimage(app.DashboardTab);
            app.Lamp4 = uilamp(app.DashboardTab);
            app.BusyImage1 = uiimage(app.DashboardTab);
            app.Lamp1 = uilamp(app.DashboardTab);
            app.BusyImage2 = uiimage(app.DashboardTab);
            app.Lamp2 = uilamp(app.DashboardTab);
            app.BusyImage5 = uiimage(app.DashboardTab);
            app.Lamp5 = uilamp(app.DashboardTab);
            app.BusyImage6 = uiimage(app.DashboardTab);
            app.Lamp6 = uilamp(app.DashboardTab);
            app.ExportMRDButton = uibutton(app.DashboardTab, 'push');
            app.ExportMRDButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMRDButtonPushed, true);
            app.ExportMRDButton.Enable = 'off';
            app.ExportMRDButton.Tooltip = {'Export the movie to a new MRD file'};
            app.ExportMRDButton.Text = 'Export MRD';
            app.BusyImage7 = uiimage(app.DashboardTab);
            app.Lamp7 = uilamp(app.DashboardTab);
            app.SortKspaceButton = uibutton(app.DashboardTab, 'push');
            app.SortKspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SortKspaceButtonPushed, true);
            app.SortKspaceButton.Enable = 'off';
            app.SortKspaceButton.Tooltip = {'Sort the dat in the desired number of cardiac or respiratory frames'};
            app.SortKspaceButton.Text = 'Sort k-space';
            app.CinePanel = uipanel(app.DashboardTab);
            app.CinePanel.Title = 'Cine';
            app.CINEframesLabel = uilabel(app.CinePanel);
            app.CINEframesLabel.Tooltip = {'Number of desired CINE frames'};
            app.CINEframesLabel.Text = '# CINE frames';
            app.FramesEditField = uieditfield(app.CinePanel, 'numeric');
            app.FramesEditField.Limits = [1 240];
            app.FramesEditField.RoundFractionalValues = 'on';
            app.FramesEditField.ValueDisplayFormat = '%.0f';
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.FramesEditField.Enable = 'off';
            app.FramesEditField.Value = 12;
            app.movieDropDownLabel = uilabel(app.CinePanel);
            app.movieDropDownLabel.Text = 'movie';
            app.RecoTypeDropDown = uidropdown(app.CinePanel);
            app.RecoTypeDropDown.Items = {'cardiac', 'cardiac abs', 'respiratory', 'realtime'};
            app.RecoTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.RecoTypeDropDown.Enable = 'off';
            app.RecoTypeDropDown.Tooltip = {'Data sorting:'; '- cardiac phase binning'; '- cardiac absolute binning'; '- respiratory phase binning'; '- realtime, 1 dynamic per heart beat'};
            app.RecoTypeDropDown.Value = 'cardiac';
            app.dynamicsLabel = uilabel(app.CinePanel);
            app.dynamicsLabel.Tooltip = {'Number of desired dynamics'};
            app.dynamicsLabel.Text = '# dynamics';
            app.DynamicsEditField = uieditfield(app.CinePanel, 'numeric');
            app.DynamicsEditField.Limits = [1 Inf];
            app.DynamicsEditField.RoundFractionalValues = 'on';
            app.DynamicsEditField.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.DynamicsEditField.Enable = 'off';
            app.DynamicsEditField.Value = 1;
            app.BusyImage3 = uiimage(app.DashboardTab);
            app.Lamp3 = uilamp(app.DashboardTab);
            app.Hyperlink = uihyperlink(app.RetrospectiveUIFigure);
            app.Hyperlink.URL = 'https://www.jove.com/t/62595/quantification-mouse-heart-left-ventricular-function-myocardial';
            app.Hyperlink.Text = 'JOVE';
            app.RetrospectivecanbecreditedbycitingthispaperLabel = uilabel(app.RetrospectiveUIFigure);
            app.RetrospectivecanbecreditedbycitingthispaperLabel.Text = 'Retrospective can be credited by citing this paper:';
            % Show the figure after all components are created
            app.RetrospectiveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retrospective

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RetrospectiveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RetrospectiveUIFigure)
        end
    end
end